classdef browse_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        browse_data_fig    matlab.ui.Figure
        DataSeries         matlab.ui.control.Table
        SortOption         matlab.ui.control.ListBox
        Label_2            matlab.ui.control.Label
        Refresh            matlab.ui.control.Button
        ListOptions        matlab.ui.control.ListBox
        Label              matlab.ui.control.Label
        ReplicateCheckBox  matlab.ui.control.CheckBox
        text14             matlab.ui.control.Label
        text13             matlab.ui.control.Label
        TotalFolderNbre    matlab.ui.control.EditField
        DataSeries_old     matlab.ui.control.ListBox
        Down               matlab.ui.control.Button
        Up                 matlab.ui.control.Button
        text9              matlab.ui.control.Label
        OpenFile           matlab.ui.control.Button
        ListDevices        matlab.ui.control.ListBox
        CampaignDoc        matlab.ui.control.Button
        element_attrib     matlab.ui.control.Label
        ListExperiments    matlab.ui.control.ListBox
        SourceDir          matlab.ui.control.EditField
        text7              matlab.ui.control.Label
        element_key        matlab.ui.control.Label
    end


    methods (Access = private)
        %------------------------------------------------------------------
        % --- Executes when user attempts to close browse_data.TO CHECK
        function closefcn(app, gcbo, eventdata)
            %------------------------------------------------------------------
            hseries=findobj(allchild(0),'Tag','series');
            if ~isempty(hseries)
                hreplicate=findobj(hseries,'Tag','Replicate');
                set(hreplicate,'Value',0)
            end
            hcalib=findobj(allchild(0),'Tag','geometry_calib');
            if ~isempty(hcalib)
                hreplicate=findobj(hcalib,'Tag','Replicate');
                set(hreplicate,'Value',0)
            end
        end

        %------------------------------------------------------------------
        % --- Provide a list to display
        function [ListFiles, indices] = list_dir_1(app, SourceDir, ListSub)
            %------------------------------------------------------------------
            ListStruct=dir_uvmat(SourceDir); %list files and dirs, extende to OpenDAP case
            ListCells=struct2cell(ListStruct);% transform dir struct to a cell arrray
            ListFiles=ListCells(1,:);
            index_isdir=find(strcmp('isdir',fieldnames(ListStruct)));
            check_dir=cell2mat(ListCells(index_isdir,:));% =1 for directories, =0 for files
            ListFiles(check_dir)=regexprep(ListFiles(check_dir),'^.+','+/$0');% put '+/' in front of dir name display
            cell_remove=regexp(ListFiles,'^(-|\.|\+/\.)');% detect strings beginning by '-' ,'.' or '+/.'(dir beginning by . )
            check_keep=cellfun('isempty', cell_remove);
            ListFiles=sort((ListFiles(check_keep))');

            if ischar(ListSub)
                indices=find(strcmp(ListSub,ListFiles));
            else
                indices=[];
                for ilist=1:numel(ListSub)
                    index=find(strcmp(ListSub{ilist},ListFiles));
                    indices=[indices index];
                end
            end
            if isempty(indices), indices=1; end
        end

        %------------------------------------------------------------------
        % --- Provide a list to display
        function [ListFilesTot, indices] = list_dir_2(app, SourceDir, ListDir, ListSub, check_fix)
            %------------------------------------------------------------------
            ListFilesTot={};
            for ilist=1:numel(ListDir)
                if ~isempty(regexp(ListDir{ilist},'^\+/'))
                    ListDir{ilist}=regexprep(ListDir{ilist},'^\+/','');
                    ListStruct=dir_uvmat(fullfile(SourceDir,ListDir{ilist})); %list files and dirs, extende to OpenDAP case
                    ListCells=struct2cell(ListStruct);% transform dir struct to a cell arrray
                    ListFiles=ListCells(1,:);
                    index_isdir=find(strcmp('isdir',fieldnames(ListStruct)));
                    check_dir=cell2mat(ListCells(index_isdir,:));% =1 for directories, =0 for files
                    ListFiles(check_dir)=regexprep(ListFiles(check_dir),'^.+','+/$0');% put '+/' in front of dir name display
                    cell_remove=regexp(ListFiles,'^(-|\.|\+/\.)');% detect strings beginning by '-' ,'.' or '+/.'(dir beginning by . )
                    check_keep=cellfun('isempty', cell_remove);
                    ListFilesTot=[ListFilesTot (ListFiles(check_keep))];
                end
            end
            ListFilesTot=unique(ListFilesTot);
            if ischar(ListSub)
                indices=find(strcmp(ListSub,ListFilesTot));
            else
                indices=[];
                for ilist=1:numel(ListSub)
                    index=find(strcmp(ListSub{ilist},ListFilesTot));
                    indices=[indices index];
                end
                if check_fix
                    index_init=1:numel(ListFilesTot);
                    %indices=sort(indices)
                    index_init(indices)=[];
                    ListFilesTot=ListFilesTot([indices index_init]);
                    indices=1:numel(indices);
                end
            end
            if isempty(indices), indices=1; end
        end

        %------------------------------------------------------------------
        % --- Provide the list (ListFilesTot) to display in DataSeries with the selected indices
        function [ListFilesTot, indices] = list_dir_3(app, SourceDir, ListDir, ListSub, ListSubSub, check_fix)
            %------------------------------------------------------------------
            % INPUT:
            %SourceDir: path to the displayed directories
            %ListDir: list of file and folder names under SourceDir (column 'Experiments')
            %ListSub: list of file and folder in the second column 'Devices')
            %ListSubSub: previous list of file and folder in the third column 'DataSeries', used to mark the selected indices
            ListFilesTot={};
            for ilist=1:numel(ListDir)
                if ~isempty(regexp(ListDir{ilist},'^\+/'))% detect folders
                    ListDir{ilist}=regexprep(ListDir{ilist},'^\+/','');
                    for isub=1:numel(ListSub)
                        if ~isempty(regexp(ListSub{isub},'^\+/'))
                            ListSub{isub}=regexprep(ListSub{isub},'^\+/','');
                            ListStruct=dir_uvmat(fullfile(SourceDir,ListDir{ilist},ListSub{isub})); %list files and dirs, extende to OpenDAP case
                            ListCells=struct2cell(ListStruct);% transform dir struct to a cell arrray
                            ListFiles=ListCells(1,:);
                            check_xml=~cellfun('isempty',regexp(ListFiles,'\.xml$'));% detect  xml files
                            check_tild=~cellfun('isempty',regexp(ListFiles,'~$'));% detect  ~  files
                            index_isdir=find(strcmp('isdir',fieldnames(ListStruct)));
                            check_dir=cell2mat(ListCells(index_isdir,:));% =1 for directories, =0 for files
                            nbfiles=numel(find(~check_xml & ~check_dir & ~check_tild));% number of non xml files
                            check_dir=check_dir & cellfun('isempty', regexp(ListFiles,'^(-|\.|\+/\.)'));% detect strings beginning by '-' ,'.' or '+/.'(dir beginning by . )
                            ListFiles(check_dir)=regexprep(ListFiles(check_dir),'^.+','+/$0');% put '+/' in front of dir name display
                            %cell_remove=regexp(ListFiles,'^(-|\.|\+/\.)');% detect strings beginning by '-' ,'.' or '+/.'(dir beginning by . )
                            ListFiles=ListFiles(check_dir | check_xml);
                            ListFilesTot=[ListFilesTot ListFiles];
                            if nbfiles>0
                                ListFilesTot=[ListFilesTot {[ListSub{isub} '/' num2str(nbfiles) ' files']}];
                            end
                        end
                    end
                end
            end
            ListFilesTot=unique(ListFilesTot);
            if ischar(ListSubSub)
                indices=find(strcmp(ListSubSub,ListFilesTot));
            else
                indices=[];
                for ilist=1:numel(ListSubSub)
                    index=find(strcmp(ListSubSub{ilist},ListFilesTot));
                    if check_fix && isempty(index)
                        ListFilesTot=[ListFilesTot {['---' ListSubSub{ilist}]}];
                        index=numel(ListFilesTot);
                    end
                    indices=[indices index];
                end
                if check_fix
                    index_init=1:numel(ListFilesTot);
                    index_init(indices)=[];
                    ListFilesTot=ListFilesTot([indices index_init]);
                    indices=1:numel(indices);
                end
            end
            if isempty(indices), indices=1; end
        end

        %------------------------------------------------------------------
        % ---  List the experiments in a campaign, filling the menu ListExperiments
        function errormsg = scan_campaign(app, SourceDir, Experiment)
            %------------------------------------------------------------------
            errormsg='';
            if ~isempty(regexp(SourceDir,'^http'))|| exist(SourceDir,'dir')
                ListStruct=dir_uvmat(SourceDir); %list files and dirs, extende to OpenDAP case
                if numel(ListStruct)>1000% A campaign folder must contain maily a list of 'experiment' sub-folders
                    errormsg=[SourceDir ' contains too many items (>1000) to be a Project folder'];
                    return
                end
                [ListFiles,index]=list_dir_1(app, SourceDir,Experiment);
                app.ListExperiments.Items=ListFiles;
                app.ListExperiments.Value=ListFiles(index);% initialise the menu selection with the folder defined by the input
                ListExperiments_Callback(app,[])
            else
                msgbox_uvmat('ERROR',['The input ' SourceDir ' is not a folder'])
            end
        end

        %------------------------------------------------------------------------
        function update_experiments(app, ListExperiments, CampaignPath, MirrorPath)
            % --- Executes when the mirror is created or updated
            % TODO:  MOVE IN uvmat
            %------------------------------------------------------------------------
            DataSeries={};
            for iexp=1:numel(ListExperiments)
                if strcmp(ListExperiments{iexp}(1),'+')% if the item is a directory
                    ListExperiments{iexp}(1)=[];
                    ListStruct=dir(fullfile(CampaignPath,ListExperiments{iexp})); %list files and dir in the source experiment directory
                    ListCells=struct2cell(ListStruct);%transform dir struct to a cell arrray
                    ListFiles=ListCells(1,:);%list of dir and file  names
                    cell_remove=regexp(ListFiles,'^((-|\.|\+/\.))');% detect strings beginning by '-' ,'.' or '+/.'(dir beginning by . )
                    check_keep=cellfun('isempty', cell_remove);
                    index_isdir=find(strcmp('isdir',fieldnames(ListStruct)));
                    check_dir=cell2mat(ListCells(index_isdir,:));% =1 for directories, =0 for files
                    for ilist=1:numel(ListFiles)
                        if check_keep(ilist)% loop on eligible DataSeries folders
                            DataSeries=fullfile(CampaignPath,ListExperiments{iexp},ListFiles{ilist});%source folder
                            if ~isempty(MirrorPath)
                                mirror=fullfile(MirrorPath,ListExperiments{iexp},ListFiles{ilist});
                                if exist(mirror,'file')% if mirror already exists as a file or folder
                                    [tild,msg]=fileattrib(mirror);
                                    if strcmp(msg.Name,mirror)%if the mirror name already exists as a local file or dir
                                        if msg.directory% case of a folder
                                            answer=msgbox_uvmat('INPUT_Y-N',['replace local folder ' msg.Name ' by a link to the source dir']);
                                            if strcmp(answer,'Yes')
                                                [ss,msg]=rmdir(mirror);
                                                if ss==1
                                                    system(['ln -s ' DataSeries ' ' mirror]); % create the link to the source folder
                                                else
                                                    msgbox_uvmat('ERROR',['enable to delete local folder: ' msg]);
                                                end
                                            end
                                        else % case of an existing mirror file
                                            answer=msgbox_uvmat('INPUT_Y-N',['replace local file ' msg.Name ' by a link to the source file']);
                                            if strcmp(answer,'Yes')
                                                delete(mirror);
                                                system(['ln -s ' DataSeries ' ' mirror]); % create the link to the source folder
                                            end
                                        end
                                    end
                                else% create mirror to the data series if needed
                                    system(['ln -s ' DataSeries ' ' mirror]); % create the link to the source folder
                                end
                                if isempty(find(strcmp(ListFiles{ilist},DataSeries), 1))% if the item is not already in DataSeries
                                    if check_dir(ilist)
                                        ListFiles{ilist}=['+/' ListFiles{ilist}];%mark dir by '+' in the list
                                    end
                                    DataSeries=[DataSeries;ListFiles{ilist}]; %append the item to the list
                                end
                            end
                        end
                    end
                end
            end
            DataTab=cell2tab(DataSeries);
            app.DataSeries_old.Items=sort(DataTab);
        end

        %------------------------------------------------------------------
        % list the content of a directory
        function [ListFiles,NumFiles]=list_files(app,DirName,check_date,sort_option,filter_ext)
            %--------------------------------------------------------------
            ListFiles={};%default
            NumFiles=0;
            ListStruct=dir_uvmat(DirName);% get structure of the current directory
            if ~isstruct(ListStruct)
                return
            end

            date_index=find(strcmp('datenum',fieldnames(ListStruct)));% find the index of the date displayin the list of fields
            if numel(ListStruct)<1  % case of empty dir
                return
            end
            ListCells=struct2cell(ListStruct);% transform dir struct to a cell arrray
            ListFiles=ListCells(1,:);%list of file names
            index_dir=strcmp('isdir',fieldnames(ListStruct));
            check_dir=cell2mat(ListCells(index_dir,:));% =1 for directories, =0 for files
            ListFiles(check_dir)=regexprep(ListFiles(check_dir),'^.+','+/$0');% put '+/' in front of dir name display
            if exist('filter_ext','var') && ~strcmp(filter_ext,'*') &&~strcmp(filter_ext,'uigetdir')
                if strcmp(filter_ext,'image')
                    check_keep=cellfun(@isimage,ListFiles) ;
                elseif strcmp(filter_ext(1),'.')
                    ind_ext=regexp(ListFiles,[filter_ext '$']);%look for the input file extension
                    check_keep=~cellfun('isempty',ind_ext);
                end
                check_keep=check_keep|check_dir;
                ListFiles=ListFiles(check_keep);
                ListCells=ListCells(:,check_keep);
                check_dir=check_dir(check_keep);
            end
            check_emptydate=cellfun('isempty',ListCells(date_index,:));% = 1 if datenum undefined
            ListCells(date_index,check_emptydate)={0}; %set to 0 the empty dates
            ListDates=cell2mat(ListCells(date_index,:));%list of numerical dates
            if isnumeric(sort_option)
                check_old=ListDates<sort_option-1;% -1 is put to account for a 1 s delay in the record of starting time
                NumFiles=numel(find(~check_old&~check_dir));
            end
            if ~isempty(find(~check_dir, 1))
                ListDates(check_dir)=max(ListDates(~check_dir))+1000; % we set the dir in front
            end

            if isnumeric(sort_option)|| strcmp(sort_option,'date')
                [~,index_sort]=sort(ListDates,2,'descend');% sort files by chronological order, recent first, put the dir first in the list
            else
                [~,index_sort]=sort(check_dir,2,'descend');% put the dir first in the list
            end
            ListFiles=ListFiles(index_sort);% list of names sorted by alaphabetical order and dir and file
            cell_remove=regexp(ListFiles,'^(-|\.|\+/\.)');% detect strings beginning by '-' ,'.' or '+/.'(dir beginning by . )
            check_keep=cellfun('isempty', cell_remove);
            ListFiles= ListFiles(check_keep);
            if check_date
                ListDateString=ListCells(3,:);%list of file dates
                if isnumeric(sort_option)
                    ListDateString(check_old)={'--OLD--'};
                end
                ListDateString(check_dir)={''};
                ListDateString=ListDateString(index_sort);% sort the corresponding dates
                ListDateString=[{''} ListDateString(check_keep)];
                ListFiles=[ListFiles; ListDateString];
                ListFiles=cell2tab(ListFiles','...');
            end
        end

        %------------------------------------------------------------------------
        function create_mirror_NOT_USED(app,InputFileName,FilterExt)
            % NOT USED,  TODO: MOVE to GUI uvmat menu Tools
            %------------------------------------------------------------------------
            set(handles.SourceDir,'BackgroundColor',[1 1 0])% indicate action of button by yellow color
            drawnow
            SourcePath=get(handles.SourceDir,'String');
            if ~isempty(regexp(SourcePath,'^http'))
                SourcePath=pwd;
                SourcePath=regexprep(SourcePath,'^\/.fsnet','/fsnet');
            end


            ProjectList=get(handles.ListExperiments,'String');
            ProjectName=ProjectList{get(handles.ListExperiments,'Value')};
            ProjectName=regexprep(ProjectName,'^\+/','');
            if strcmp(get(handles.MirrorDir,'Visible'),'on')
                MirrorDir=get(handles.MirrorDir,'String');% name of the mirror folder
            else% create the mirror folder if it does not exist
                MirrorRoot=uigetfile_uvmat('select the folder which must contain the mirror directory:',SourcePath,'uigetdir');
                if isempty(MirrorRoot)
                    return
                elseif strcmp(MirrorRoot,SourcePath)
                    msgbox_uvmat('ERROR','The mirror folder must be different from the source')
                    return
                else
                    MirrorDir=fullfile(MirrorRoot,ProjectName);
                end
                if exist(MirrorDir,'dir')
                    msgbox_uvmat('ERROR',['The folder ' MirrorDir ' chosen as new mirror campaign already exists'])
                    return
                else
                    [s,errormsg]=mkdir(MirrorDir);% create the mirror dir
                    if s~=1
                        msgbox_uvmat('ERROR',['error in creating ' MirrorDir ': ' errormsg])
                        return
                    end
                end
                SourceDir=fullfile(SourcePath,ProjectName);
                MirrorDoc.SourceDir=SourceDir;
                t=struct2xml(MirrorDoc);
                set(t,1,'name','DataTree');
                save(t,fullfile(MirrorDir,[ProjectName '.xml']))% create an xml file in the mirror folder to indicate its source folder
                set(handles.MirrorDir,'String',MirrorDir)
                set(handles.MirrorDir,'Visible','on')
                set(handles.CreateMirror,'String','update_mirror')
            end
            ExpName={''};

            %% update the mirror from the source dir
            if exist(SourceDir,'dir')
                hdir=dir(SourceDir); %list files and dirs
                idir=0;
                for ilist=1:length(hdir)
                    if hdir(ilist).isdir% scan all subfolders
                        dirname=hdir(ilist).name;%
                        if ~isequal(dirname(1),'.')&&~isequal(dirname(1),'0')%skip subfolder beginning by '0'
                            idir=idir+1;
                            mirror=fullfile(MirrorDir,hdir(ilist).name);% corresponding name in the mirror
                            if ~exist(mirror,'dir')
                                mkdir(mirror)% create the mirror folder if it does not exist
                            end
                            ExpName{idir}=['+/' hdir(ilist).name];% insert '+/' in the list to show that it is a folder
                        end
                        % look for the list of 'devices'
                    else
                        %warning for isolated files
                    end
                end
                set(handles.ListExperiments,'String',[{'*'};ExpName'])
                set(handles.ListExperiments,'Value',1)
                update_experiments(app,[{'*'};ExpName'],SourceDir,MirrorDir)% list the content of the experiment
            else
                msgbox_uvmat('ERROR',['The input ' SourceDir ' is not a directory'])
            end
            set(handles.SourceDir,'BackgroundColor',[1 1 1])
        end  

end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function browse_data_OpeningFcn(app, InputDir, Replicate)

            % Ensure that the app appears on screen when run
            movegui(app.browse_data_fig, 'onscreen');

            %% Determine the position of the dialog - centered on the screen
            % FigPos=get(0,'DefaultFigurePosition');
            % OldUnits = get(hObject, 'Units');
            % set(hObject, 'Units', 'pixels');
            % OldPos = get(hObject,'Position');
            % FigWidth = OldPos(3);
            % FigHeight = OldPos(4);
            % ScreenUnits=get(0,'Units');
            % set(0,'Units','pixels');
            % ScreenSize=get(0,'ScreenSize');
            % set(0,'Units',ScreenUnits);
            % FigPos(1)=1/2*(ScreenSize(3)-FigWidth);
            % FigPos(2)=2/3*(ScreenSize(4)-FigHeight);
            % FigPos(3:4)=[FigWidth FigHeight];
            % set(hObject, 'Position', FigPos);
            % set(hObject, 'Units', OldUnits);
            if exist('Replicate','var') && isequal(Replicate,'Replicate')
                app.ReplicateCheckBox.Value=true;
            else
                app.ReplicateCheckBox.Value=false;
            end

            %% initialize the GUI
            if ~exist('InputDir','var')
                    InputDir=pwd;
            end
            if isempty(regexp(InputDir,'^http:', 'once'))&& ~(exist('InputDir','var') && ischar(InputDir) && exist(InputDir,'dir'))
                InputDir=pwd;% current dir is the starting data series by default
            end
            if ischar(InputDir),InputDir={InputDir};end
            for ilist=1:numel(InputDir)
                [ExpWithPath,DataSeries{ilist},Ext]=fileparts(InputDir{ilist});
                DataSeries{ilist}=['+/' DataSeries{ilist} Ext];
                [Experiment{ilist},Device{ilist},Ext]=fileparts(ExpWithPath);
                Device{ilist}=['+/' Device{ilist} Ext];
                [SourceDir{ilist},Experiment{ilist},Ext]=fileparts(Experiment{ilist});
                Experiment{ilist}=['+/' Experiment{ilist} Ext];
                if ~strcmp(SourceDir{ilist},SourceDir{1})||~strcmp(Experiment{ilist},Experiment{1})
                    msgbox_uvmat('ERROR','replicate cannot be used with multiple root folders')
                    return
                end
                if ~strcmp(DataSeries{ilist},DataSeries{1})
                    app.DataSeries_old.enable='off';
                end
                if ~strcmp(Device{ilist},Device{1})
                    app.ListDevices.enable='off';
                end
            end

            app.SourceDir.Value=SourceDir{1};
            DataCell=scan_folder(InputDir{1});
            app.DataSeries.Data=DataCell;
%             app.DataSeries_old.Items=DataSeries;
%             app.DataSeries_old.Value=DataSeries(1:numel(DataSeries));
            app.ListDevices.Items=Device;
            app.ListDevices.Value=Device(1:numel(Device));
            errormsg=scan_campaign(app,SourceDir{1},Experiment{1});
            if ~isempty(errormsg)
                msgbox_uvmat('ERROR',errormsg)
                return
            end
        end

        % Button pushed function: CampaignDoc
        function CampaignDoc_Callback(app, event)
            % NOT USED: display the structure of the data tree in the form of an xml file
            %------------------------------------------------------------------------
     
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            answer=msgbox_uvmat('INPUT_Y-N','This function will update the global xml representation of the data set and the Heading of each xml file');
            if ~isequal(answer{1},'OK')
                return
            end
            set(handles.ListExperiments,'Value',1)
            ListExperiments_Callback(hObject, eventdata, handles)%update the overview of the experiment directories
            DataviewData=get(handles.browse_data,'UserData');
            List=DataviewData.List;
            Currentpath=get(handles.SourceDir,'String');
            [Currentpath,Campaign,DirExt]=fileparts(Currentpath);
            Campaign=[Campaign DirExt];
            t=xmltree;
            t=set(t,1,'name','CampaignDoc');
            t = attributes(t,'add',1,'source','directory');
            SubCampaignTest=get(handles.SubCampaignTest,'Value');
            root_uid=1;
            if SubCampaignTest
                %TO DO open an exoiting xml doc
                [t,root_uid]=add(t,1,'element','SubCampaign');
                t =attributes(t,'add',root_uid,'DirName',Campaign);
            end
            for iexp=1:length(List.Experiment)
                set(handles.ListExperiments,'Value',iexp+1)
                drawnow
                test_mod=0;
                [t,uid_exp]=add(t,root_uid,'element','Experiment');
                t = attributes(t,'add',uid_exp,'i',num2str(iexp));
                ExpName=List.Experiment{iexp}.name;
                t = attributes(t,'add',uid_exp,'DirName',List.Experiment{iexp}.name);

                if isfield(List.Experiment{iexp},'Device')
                    for idevice=1:length(List.Experiment{iexp}.Device)
                        [t,uid_device]=add(t,uid_exp,'element','Device');
                        DeviceName=List.Experiment{iexp}.Device{idevice}.name;
                        t = attributes(t,'add',uid_device,'DirName',List.Experiment{iexp}.Device{idevice}.name);
                        if isfield(List.Experiment{iexp}.Device{idevice},'xmlfile')
                            for ixml=1:length(List.Experiment{iexp}.Device{idevice}.xmlfile)
                                FileName=List.Experiment{iexp}.Device{idevice}.xmlfile{ixml};
                                [Title,test]=check_heading(Currentpath,Campaign,ExpName,DeviceName,[],FileName,SubCampaignTest);
                                if test
                                    disp([List.Experiment{iexp}.Device{idevice}.xmlfile{ixml} ' , Heading updated'])
                                end
                                if isequal(Title,'ImaDoc')
                                    [t,uid_xml]=add(t,uid_device,'element','ImaDoc');
                                    t = attributes(t,'add',uid_xml,'source','file');
                                    [t]=add(t,uid_xml,'chardata',List.Experiment{iexp}.Device{idevice}.xmlfile{ixml});
                                end
                            end
                        elseif isfield(List.Experiment{iexp}.Device{idevice},'Record')
                            for irecord=1:length(List.Experiment{iexp}.Device{idevice}.Record)
                                RecordName=List.Experiment{iexp}.Device{idevice}.Record{irecord}.name;
                                [t,uid_record]=add(t,uid_device,'element','Record');
                                t = attributes(t,'add',uid_record,'DirName',RecordName);
                                if isfield(List.Experiment{iexp}.Device{idevice}.Record{irecord},'xmlfile')
                                    for ixml=1:length(List.Experiment{iexp}.Device{idevice}.Record{irecord}.xmlfile)
                                        FileName=List.Experiment{iexp}.Device{idevice}.Record{irecord}.xmlfile{ixml};
                                        [Title,test]=check_heading(Currentpath,Campaign,ExpName,DeviceName,RecordName,FileName,SubCampaignTest);
                                        if test
                                            disp([FileName ' , Heading updated'])
                                        end
                                        [t,uid_xml]=add(t,uid_record,'element','ImaDoc');
                                        t = attributes(t,'add',uid_xml,'source','file');
                                        [t]=add(t,uid_xml,'chardata',FileName);
                                    end
                                end
                            end
                        end
                    end
                end
            end
            set(handles.ListExperiments,'Value',1)
            outputdir=get(handles.SourceDir,'String');
            [path,dirname]=fileparts(outputdir);
            outputfile=fullfile(outputdir,[dirname '.xml']);
            save(t,outputfile)
        end

        % Button pushed function: Down
        function Down_Callback(app, event)

            ExpName=app.ListExperiments.Value{1};% select the first item in the list Experiments
            ExpName=regexprep(ExpName,'^\+/','');% remove '+/'
            app.SourceDir.Value=fullfile(app.SourceDir.Value,ExpName);  % update source path with the first selected experiment

            ListDevicesInit=app.ListDevices.Value;
            app.ListExperiments.Items=app.ListDevices.Value;%replace Experiments by the selected Devices
            app.ListDevices.Items=app.DataSeries_old.Items;
            app.ListDevices.Value=app.DataSeries_old.Value;
            [ListFiles,indices]=list_dir_3(app, app.SourceDir.Value,ListDevicesInit,app.DataSeries_old.Value,[],0);
            
            if ~isempty(ListFiles) && strcmp(app.ListOptions.Value,'pick a file ...')
              app.DataSeries_old.Items=ListFiles';
            DataSeriesList=ListFiles(indices);
            else
                app.DataSeries_old.Items=ListFiles;
                DataSeriesList=ListFiles(indices);
            end
            app.DataSeries_old.Value=DataSeriesList;% initialise the menu selection with the folder defined by the input
        end

        % Value changed function: ListDevices
        function ListDevices_Callback(app, event)

            check_fix=strcmp(app.DataSeries_old.Enable,'off');
            [ListFiles,indices]=list_dir_3(app,app.SourceDir.Value,app.ListExperiments.Value,app.ListDevices.Value,app.DataSeries_old.Value,check_fix);
            app.DataSeries_old.Items=ListFiles;
            app.DataSeries_old.Value=ListFiles(indices);% initialise the menu selection with the folder defined by the input
        end

        % Value changed function: ListExperiments
        function ListExperiments_Callback(app, event)

            check_fix=strcmp(app.ListDevices.Enable,'off');
            [ListFiles,indices]=list_dir_2(app, app.SourceDir.Value,app.ListExperiments.Value,app.ListDevices.Items,check_fix);
            app.ListDevices.Items=ListFiles;
            app.ListDevices.Value=ListFiles(indices);% initialise the menu selection with the folder defined by the input
            ListDevices_Callback(app,event)
        end

        % Value changed function: SourceDir
        function SourceDir_Callback(app, event)
          
            errormsg=scan_campaign(app,app.SourceDir.Value,app.ListExperiments.Value);% get the whole data tree from the updated root path
            if ~isempty(errormsg)
                msgbox_uvmat('ERROR',errormsg)
                return
            end
        end

        % Button pushed function: Up
        function Up_Callback(app, event)

            [app.SourceDir.Value,Exp,ExpExt]=fileparts(app.SourceDir.Value);%move up in the data tree, [Exp ExpExt] is the name of the experiment

            app.DataSeries_old.Items=app.ListDevices.Items;%transfer list of devices to DataSeries
            app.DataSeries_old.Value=app.ListDevices.Value;%transfer selected list of devices to DataSeries

            app.ListDevices.Items=app.ListExperiments.Items;%transfer list of experiments to devices
            app.ListDevices.Value=app.ListExperiments.Value;%transfer selected list of experiments to devices

            ListStruct=dir_uvmat(app.SourceDir.Value); %list files and dirs, extende to OpenDAP case
            ListCells=struct2cell(ListStruct);% transform dir struct to a cell arrray
            ListFiles=ListCells(1,:);
            cell_remove=regexp(ListFiles,'^(-|\.)');% detect strings beginning by '-' ,'.' (dir beginning by . )
            check_keep=cellfun('isempty', cell_remove);%remove detected cells
            ListFiles=ListFiles(check_keep);
            index_selected= strcmp([Exp ExpExt],ListFiles);% find the index of the selected experiment in the list
            index_isdir=find(strcmp('isdir',fieldnames(ListStruct)));
            check_dir=cell2mat(ListCells(index_isdir,check_keep));% =1 for directories, =0 for files
            ListFiles(check_dir)=regexprep(ListFiles(check_dir),'^.+','+/$0');% put '+/' in front of dir name display
            app.ListExperiments.Items=ListFiles';
            app.ListExperiments.Value=ListFiles(index_selected);
        end

        % Button pushed function: OpenFile
        function OpenFileCallback(app, event)
            %           set(hObject,'backgroundColor',[1 1 0])% indicate button activation
            % fig_struct=get(hObject,'parent');
            % if isstruct(fig_struct);%recent Matlab
            %     hfig=fig_struct.Number;
            % else
            %     hfig=fig_struct;
            % end
            % htitlebox=findobj(hfig,'tag','titlebox');  % display the current dir name
            % DirName=get(htitlebox,'String');
            % a file is expected as output, not a dir
            SelectName=app.DataSeries_old.Value{1};
            if ~isempty(regexp(SelectName,'^\+/', 'once'))
                return % quit if a dir has been opened
            end
            ind_dot=regexp(SelectName,'\s*\.\.\.');%remove what is beyond  '...'
            if ~isempty(ind_dot)
                SelectName=SelectName(1:ind_dot-1);
            end
            ExpName=regexprep(app.ListExperiments.Value{1},'+/','');
            DeviceName=regexprep(app.ListDevices.Value{1},'+/','');
            DirName=fullfile(app.SourceDir.Value,ExpName,DeviceName);
            if isempty(regexp(DirName,'^http://', 'once'))% if the input dir is not a web site (begins by http://)
                FullSelectName=fullfile(DirName,SelectName);
                check_exist=exist(FullSelectName,'file');
            else
                FullSelectName=[DirName '/' SelectName];
                check_exist=1;
            end
            if check_exist

                FileInfo=get_file_info(FullSelectName);
                if strcmp(FileInfo.FileType,'txt')
                    edit(FullSelectName)
                elseif strcmp(FileInfo.FileType,'xml')
                    editxml(FullSelectName)
                elseif strcmp(FileInfo.FileType,'figure')
                    open(FullSelectName)
                else
                    uvmat(FullSelectName);
                    delete(fig_struct)
                end
            end
        end

        % Value changed function: ListOptions
        function ListOptionsValueChanged(app, event)
            value = app.ListOptions.Value;
            switch value
                case 'pick a file...'
                case 'scan folder'
            end
        end

        % Callback function
        function DataSeriesCallback(app, event)
            item = event.InteractionInformation.Item;%:line number
            app.DataSeries_old.Value=app.DataSeries_old.Items(item);
            r=regexp(app.DataSeries_old.Value{1},'(?<DirName>\w+)/\d+\sfiles$','names')
            if isempty(r)
            Down_Callback(app, event)
            else
                app.ListDevices.Value={['+/' r.DirName]};
                RefreshButtonPushed(app, []);
            end
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
        %------------------------------------------------------------------------
 %-----------------------------------------------------------------

             FilterExt='*';%TODO: extend to other selections 'xml', 'png' depending on app.ListOptions.Value...
    
            % hfig=get(hObject,'parent');
            % hlist=findobj(hfig,'tag','list');% find the list object
            % set(hlist,'BackgroundColor',[1 1 0]p)
            app.DataSeries_old.BackgroundColor=[1 1 0]; % put the Data series in yellow to indicate activation
            ExpName=regexprep(app.ListExperiments.Value{1},'+/','');
            DeviceName=regexprep(app.ListDevices.Value{1},'+/','');
            DirName=fullfile(app.SourceDir.Value,ExpName,DeviceName);% current folder to scan for updated content

            sort_option=app.SortOption.Value;

            %% case of file browser opened by Status Display in the GUI series
            % if strcmp(get(hfig,'Tag'),'status_display') % use with GUI series
            %     hseries=findobj(allchild(0),'tag','series');
            %     hstatus=findobj(hseries,'tag','status');
            %     StatusData=get(hstatus,'UserData');
            %     TimeStart=0;
            %     if isfield(StatusData,'TimeStart')
            %         TimeStart=StatusData.TimeStart;
            %     end
            %     hlist=findobj(hfig,'tag','list');
            %     NbOutputFile=[];
            %     if isfield(StatusData,'NbOutputFile')
            %         NbOutputFile=StatusData.NbOutputFile;
            %     end
            %     [ListFiles,NumFiles]=list_files(DirName,1,TimeStart);% list the directory content
            % 
            %     % update the waitbar
            %     hwaitbar=findobj(hfig,'tag','waitbar');
            %     if ~isempty(NbOutputFile)
            %         BarPosition=get(hwaitbar,'Position');
            %         BarPosition(3)=0.9*max(0.01,NumFiles/NbOutputFile);% the bar width cannot be set to 0, set to 0.01 instead
            %         set(hwaitbar,'Position',BarPosition)
            %     end
            %     %% case of usual browser
            % else  %use as usual browser

                check_date=true;
                ListFiles=list_files(app,DirName,check_date,app.SortOption.Value,FilterExt);% list the directory content
            % end
            if ~isempty(DirName) && isempty(ListFiles)
                if isempty(regexp(DirName,'^http://', 'once'))%usual files
                    ListFiles='empty folder';
                else
                    ListFiles='address http:// not found';
                end
            end
            app.DataSeries_old.Items=ListFiles;
            % Value=[];
            % if ~isempty(InputFileName)
            %     Value=find(strcmp(InputFileName,ListFiles));
            % end
            % if isempty(Value)
            %     Value=1;
            % end
            % set(hlist,'Value',Value)
            app.DataSeries_old.BackgroundColor=[1 1 1];
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.browse_data_fig = uifigure('Visible', 'off');
            app.browse_data_fig.Name = 'browse_data';
            app.browse_data_fig.Tag = 'browse_data';
            app.element_key = uilabel(app.browse_data_fig);
            app.element_key.Tag = 'element_key';
            app.element_key.Text = 'Devices';
            app.text7 = uilabel(app.browse_data_fig);
            app.text7.Tag = 'text7';
            app.text7.Text = 'DataSeries';
            app.SourceDir = uieditfield(app.browse_data_fig, 'text');
            app.SourceDir.ValueChangedFcn = createCallbackFcn(app, @SourceDir_Callback, true);
            app.SourceDir.Tag = 'SourceDir';
            app.SourceDir.Tooltip = '''SourceDir'': name and path of the root source directory ';
            app.ListExperiments = uilistbox(app.browse_data_fig);
            app.ListExperiments.Items = {'ListExperiments'};
            app.ListExperiments.Multiselect = 'on';
            app.ListExperiments.ValueChangedFcn = createCallbackFcn(app, @ListExperiments_Callback, true);
            app.ListExperiments.Tag = 'ListExperiments';
            app.ListExperiments.Tooltip = '''ListExperiments''';
            app.ListExperiments.Value = {'ListExperiments'};
            app.element_attrib = uilabel(app.browse_data_fig);
            app.element_attrib.Tag = 'element_attrib';
            app.element_attrib.Text = 'attributes';
            app.CampaignDoc = uibutton(app.browse_data_fig, 'push');
            app.CampaignDoc.ButtonPushedFcn = createCallbackFcn(app, @CampaignDoc_Callback, true);
            app.CampaignDoc.Tag = 'CampaignDoc';
            app.CampaignDoc.Visible = 'off';
            app.CampaignDoc.Tooltip = '''CampaignDoc'': refresh the headings of all xml files and the global campaign xml file';
            app.CampaignDoc.Text = 'CampaignDoc xml';
            app.ListDevices = uilistbox(app.browse_data_fig);
            app.ListDevices.Items = {'ListDevices'};
            app.ListDevices.Multiselect = 'on';
            app.ListDevices.ValueChangedFcn = createCallbackFcn(app, @ListDevices_Callback, true);
            app.ListDevices.Tag = 'ListDevices';
            app.ListDevices.Tooltip = '''ListDevices''';
            app.ListDevices.Value = {'ListDevices'};
            app.OpenFile = uibutton(app.browse_data_fig, 'push');
            app.OpenFile.ButtonPushedFcn = createCallbackFcn(app, @OpenFileCallback, true);
            app.OpenFile.Tag = 'open_SubCampaign';
            app.OpenFile.Text = 'Open';
            app.text9 = uilabel(app.browse_data_fig);
            app.text9.Tag = 'text9';
            app.text9.Text = 'source path';
            app.Up = uibutton(app.browse_data_fig, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @Up_Callback, true);
            app.Up.Tag = 'Up';
            app.Up.Text = 'Up...';
            app.Down = uibutton(app.browse_data_fig, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @Down_Callback, true);
            app.Down.Tag = 'Down';
            app.Down.Text = 'Down...';
            app.DataSeries_old = uilistbox(app.browse_data_fig);
            app.DataSeries_old.Items = {'DataSeries'};
            app.DataSeries_old.Multiselect = 'on';
            app.DataSeries_old.Tag = 'DataSeries';
            app.DataSeries_old.Visible = 'off';
            app.DataSeries_old.Tooltip = '''DataSeries''';
            app.DataSeries_old.Value = {'DataSeries'};
            app.TotalFolderNbre = uieditfield(app.browse_data_fig, 'text');
            app.TotalFolderNbre.Tag = 'TotalFolderNbre';
            app.TotalFolderNbre.Visible = 'off';
            app.TotalFolderNbre.Tooltip = '''Mir'': name and path of the root mirror directory''';
            app.text13 = uilabel(app.browse_data_fig);
            app.text13.Tag = 'text13';
            app.text13.Visible = 'off';
            app.text13.Text = 'folders in total';
            app.text14 = uilabel(app.browse_data_fig);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Experiments';
            app.ReplicateCheckBox = uicheckbox(app.browse_data_fig);
            app.ReplicateCheckBox.Enable = 'off';
            app.ReplicateCheckBox.Text = 'Replicate';
            app.Label = uilabel(app.browse_data_fig);
            app.Label.Text = '';
            app.ListOptions = uilistbox(app.browse_data_fig);
            app.ListOptions.Items = {'pick a file ...', 'scan folders'};
            app.ListOptions.ValueChangedFcn = createCallbackFcn(app, @ListOptionsValueChanged, true);
            app.ListOptions.Value = 'pick a file ...';
            app.Refresh = uibutton(app.browse_data_fig, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Tag = 'Down';
            app.Refresh.Text = 'REFRESH';
            app.Label_2 = uilabel(app.browse_data_fig);
            app.Label_2.Text = {'sort'; 'option'};
            app.SortOption = uilistbox(app.browse_data_fig);
            app.SortOption.Items = {'name', 'date'};
            app.SortOption.Value = 'name';
            app.DataSeries = uitable(app.browse_data_fig);
            app.DataSeries.ColumnName = {'+'; 'name'; 'date'; 'size(Mo)'; 'dir'; 'datenum'; ''};
            app.DataSeries.RowName = {};
            app.DataSeries.ColumnSortable = [true false true];
            app.DataSeries.SelectionType = 'row';
            % Show the figure after all components are created
            app.browse_data_fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = browse_data(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.browse_data_fig)

                % Execute the startup function
                runStartupFcn(app, @(app)browse_data_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.browse_data_fig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.browse_data_fig)
        end
    end
end