classdef civ_input_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        civ_input               matlab.ui.Figure
        MenuOpen                matlab.ui.container.Menu
        MenuBrowse              matlab.ui.container.Menu
        MenuFile_1              matlab.ui.container.Menu
        MenuFile_2              matlab.ui.container.Menu
        MenuFile_3              matlab.ui.container.Menu
        MenuFile_4              matlab.ui.container.Menu
        MenuFile_5              matlab.ui.container.Menu
        MenuHelpHead            matlab.ui.container.Menu
        MenuHelp                matlab.ui.container.Menu
        source_config_title     matlab.ui.control.Label
        SetDefaultParam         matlab.ui.control.Button
        ImportParam             matlab.ui.control.Button
        ConfigSource            matlab.ui.control.EditField
        ListCompareMode         matlab.ui.control.DropDown
        CheckMask               matlab.ui.control.CheckBox
        Mask                    matlab.ui.control.EditField
        num_NbSlice             matlab.ui.control.EditField
        CheckBackground         matlab.ui.control.CheckBox
        Background              matlab.ui.control.EditField
        num_BkgndPeriod         matlab.ui.control.EditField
        CheckThreshold          matlab.ui.control.CheckBox
        num_MinIma              matlab.ui.control.EditField
        Threshold_title         matlab.ui.control.Label
        num_MaxIma              matlab.ui.control.EditField
        CheckRescale            matlab.ui.control.CheckBox
        Maxtanh_title           matlab.ui.control.Label
        num_Maxtanh             matlab.ui.control.EditField
        PairIndices             matlab.ui.container.Panel
        RefFile                 matlab.ui.control.EditField
        CheckRefFile            matlab.ui.control.Button
        OriginIndex_title       matlab.ui.control.Label
        num_OriginIndex         matlab.ui.control.EditField
        TimeUnit                matlab.ui.control.EditField
        text91                  matlab.ui.control.Label
        ref_j                   matlab.ui.control.EditField
        ref_i                   matlab.ui.control.EditField
        nb_field_title          matlab.ui.control.Label
        MaxIndex_j              matlab.ui.control.EditField
        MaxIndex_i              matlab.ui.control.EditField
        jtext                   matlab.ui.control.Label
        itext                   matlab.ui.control.Label
        first_title             matlab.ui.control.Label
        MinIndex_j              matlab.ui.control.EditField
        MinIndex_i              matlab.ui.control.EditField
        ListPairMode            matlab.ui.control.DropDown
        text78                  matlab.ui.control.Label
        TimeSource              matlab.ui.control.EditField
        dt_unit                 matlab.ui.control.EditField
        ListPairCiv2            matlab.ui.control.ListBox
        ListPairCiv1            matlab.ui.control.ListBox
        TitlePairCiv1           matlab.ui.control.Label
        TitlePairCiv2           matlab.ui.control.Label
        CheckCiv1               matlab.ui.control.CheckBox
        CheckFix1               matlab.ui.control.CheckBox
        CheckPatch1             matlab.ui.control.CheckBox
        CheckCiv2               matlab.ui.control.CheckBox
        CheckFix2               matlab.ui.control.CheckBox
        CheckPatch2             matlab.ui.control.CheckBox
        Civ1                    matlab.ui.container.Panel
        num_Dz                  matlab.ui.control.EditField
        num_SearchBoxShift_3    matlab.ui.control.EditField
        num_SearchRange_3       matlab.ui.control.EditField
        num_CorrBoxSize_3       matlab.ui.control.EditField
        TestCiv1                matlab.ui.control.StateButton
        title_CorrSmooth        matlab.ui.control.Label
        title_shift             matlab.ui.control.Label
        title_range             matlab.ui.control.Label
        title_CorrBox           matlab.ui.control.Label
        Grid                    matlab.ui.control.EditField
        CheckGrid               matlab.ui.control.CheckBox
        num_Dy                  matlab.ui.control.EditField
        num_Dx                  matlab.ui.control.EditField
        num_CorrSmooth          matlab.ui.control.DropDown
        num_SearchBoxShift_1    matlab.ui.control.EditField
        num_SearchBoxShift_2    matlab.ui.control.EditField
        num_SearchRange_1       matlab.ui.control.EditField
        num_SearchRange_2       matlab.ui.control.EditField
        num_CorrBoxSize_1       matlab.ui.control.EditField
        num_CorrBoxSize_2       matlab.ui.control.EditField
        title_Dy                matlab.ui.control.Label
        title_Dx                matlab.ui.control.Label
        Fix1                    matlab.ui.container.Panel
        field_ref1              matlab.ui.control.DropDown
        title_MaxVel_1          matlab.ui.control.Label
        num_MinCorr_1           matlab.ui.control.EditField
        thresh_vecC_title       matlab.ui.control.Label
        num_MinVel_1            matlab.ui.control.EditField
        num_MaxVel_1            matlab.ui.control.EditField
        ref_fix1                matlab.ui.control.EditField
        get_ref_fix1            matlab.ui.control.CheckBox
        TitleMinVel             matlab.ui.control.Label
        Patch1                  matlab.ui.container.Panel
        FieldSmooth_title_1     matlab.ui.control.Label
        num_FieldSmooth_1       matlab.ui.control.EditField
        MaxDiff_title_1         matlab.ui.control.Label
        num_MaxDiff_1           matlab.ui.control.EditField
        SubDomainSize_title_1   matlab.ui.control.Label
        num_SubDomainSize_1     matlab.ui.control.EditField
        Civ2                    matlab.ui.container.Panel
        range_civ2_title        matlab.ui.control.Label
        num_SearchRange_civ2_2  matlab.ui.control.EditField
        num_SearchRange_civ2_1  matlab.ui.control.EditField
        Grid_civ2               matlab.ui.control.EditField
        CheckGrid_civ2          matlab.ui.control.CheckBox
        num_Dy_civ2             matlab.ui.control.EditField
        num_Dx_civ2             matlab.ui.control.EditField
        title_Dy_civ2           matlab.ui.control.Label
        title_Dx_civ2           matlab.ui.control.Label
        title_rho_civ2          matlab.ui.control.Label
        CheckDeformation        matlab.ui.control.CheckBox
        title_CorrBox_civ2      matlab.ui.control.Label
        num_CorrSmooth_civ2     matlab.ui.control.DropDown
        num_CorrBoxSize_civ2_2  matlab.ui.control.EditField
        num_CorrBoxSize_civ2_1  matlab.ui.control.EditField
        Fix2                    matlab.ui.container.Panel
        field_ref2              matlab.ui.control.DropDown
        title_MaxVel            matlab.ui.control.Label
        num_MinCorr             matlab.ui.control.EditField
        thresh_vec2C_text       matlab.ui.control.Label
        num_MinVel              matlab.ui.control.EditField
        num_MaxVel              matlab.ui.control.EditField
        ref_fix2                matlab.ui.control.EditField
        get_ref_fix2            matlab.ui.control.CheckBox
        thresh_vel2_text        matlab.ui.control.Label
        Patch2                  matlab.ui.container.Panel
        FieldSmooth_title_2     matlab.ui.control.Label
        num_FieldSmooth_2       matlab.ui.control.EditField
        title_MaxDiff_2         matlab.ui.control.Label
        num_MaxDiff_2           matlab.ui.control.EditField
        SubDomainSize_title_2   matlab.ui.control.Label
        num_SubDomainSize_2     matlab.ui.control.EditField
        OK                      matlab.ui.control.Button
    end

    
    methods (Access = private)

        function closeview_field(app, gcbo, eventdata)
            %------------------------------------------------------------------------
            %----function introduced for the correlation window figure, activated by deleting this window
            
            %------------------------------------------------------------------------
            hview_field=findobj(allchild(0),'tag','view_field');% look for view_field
            if ~isempty(hview_field)
                delete(hview_field)
            end
        end
        
        function enable_i(app, state)
            %------------------------------------------------------------------------
            app.itext.Visible=state;
            app.MaxIndex_i.Visible=state;
            app.ref_i.Visible=state;
        end
        
        function enable_j(app, state)
            %------------------------------------------------------------------------
            app.jtext.Visible=state;
            app.MinIndex_j.Visible=state;
            app.MaxIndex_j.Visible=state;
            app.ref_j.Visible=state;
        end
        
        function fill_panel(app, Data, panel)
            %------------------------------------------------------------------------
            % --- fill a panel of civ_input with the parameters retrieved from an input netcdf  Civ file
            % Data= strtucture resulting from the reading of netcdf file, 
            % panel= name of the panel (=[] for the root data, 'civ1', 'fix1'...)
            %------------------------------------------------------------------------
            if isempty(panel)% fill the input data outside the panels
                children=get(app.civ_input,'children');
            else
                children=app.(panel).Children;%handles of the children of the input GUI with handle 'GUI_handle'
                app.(panel).Visible='on';
            end
            for ichild=1:numel(children)% scan the boxes in the considered panel
                box_tag=get(children(ichild),'tag');
                if ~isempty(box_tag)
                    check_num=~isempty(regexp(box_tag,'^num_', 'once'));%detect box with numerical input
                    DataTag=regexprep(box_tag,'^num_','');% suppress the prefix
                    DataTagRoot=regexprep(DataTag,'_\d$','');% suppress the numerical appendix
                    if isempty(panel)
                        DataFieldName=DataTagRoot;
                    else
                    DataFieldName=[panel '_' DataTagRoot];
                    end
                    if isfield(Data,DataFieldName)
                        if check_num
                            if isscalar(Data.(DataFieldName))
                                Handle = findall(app.(panel), 'Tag', box_tag);
                                set(Handle,'Value',num2str(Data.(DataFieldName)));
                            else
                                for index=1:numel(Data.(DataFieldName))
                                     Handle = findall(app.(panel), 'Tag', ['num_' DataTagRoot '_' num2str(index)]);
                                    set(Handle,'Value',num2str(Data.(DataFieldName)(index)));
                                end
                            end
                        else
                             Handle = findall(app.(panel), 'Tag',box_tag);
                            set(Handle,'Value',Data.(DataFieldName));
                        end
                    end
                end
            end
        end
        
        function errormsg = find_netcpair_civ(app, index)
            %------------------------------------------------------------------------
            %--- determine menus and selected values according to frame pairing mode set by ListPairMode.Value
            %------------------------------------------------------------------------
            set(gcf,'Pointer','watch')% set the mouse pointer to 'watch' (clock)
            
            %% initialisation
            errormsg='';
            CivInputData=app.civ_input.UserData;
            compare=app.ListCompareMode.Value;
            mode_selected='displacement';
            if ~strcmp(compare,'displacement')
                if isempty(app.ListPairMode.Value)
                    return
                else
                mode_selected=app.ListPairMode.Value;%='pair j1-j2','series(Di)','series(Dj)'
                end
            end
            NomTypeIma=CivInputData.NomTypeIma;
            PairCiv1Init=app.ListPairCiv1.Value;%previous choice of pair
            PairCiv2Init=app.ListPairCiv2.Value;
           
            %% reads .nc subdirectoy and image numbers from the interface
            ref_i=str2double(app.ref_i.Value);
            ref_j=[];
            if isequal(mode_selected,'pair j1-j2')%|isequal(mode,'st_pair j1-j2')
                 ref_j=0;
                MinIndex_j=str2double(app.MinIndex_j.Value);
                    MaxIndex_j=str2double(app.MaxIndex_j.Value);
                    if MaxIndex_j-MinIndex_j>10
                        mode_selected= 'series(Dj)';
                       ref_j= str2double(app.ref_j.Value);
                    end
            elseif strcmp(app.ref_j.Visible','on')
                ref_j=str2double(app.ref_j.Value);
            end
            if isempty(ref_j)
                ref_j=1;
            end
            TimeUnit=app.TimeUnit.Value;
            Time=CivInputData.Time;
            CheckTimeUnit=~strcmp(TimeUnit,'frame');%=true if a time calibration has been introduced (otherwise time is expressed in frames)
            
            %% case with no Civ1 operation, netcdf files need to exist for reading
            displ_pair={''};
            nbpair=200;%default
            select=ones(size(1:nbpair));%flag for displayed pairs =1 for display
            
            %% determine the menu display in .ListPairCiv1
            switch mode_selected
                case 'series(Di)'
                    displ_pair_dt=cell(nbpair,1);
                    for ipair=1:nbpair
                        if select(ipair)
                            displ_pair{ipair}=['Di= ' num2str(-floor(ipair/2)) '|' num2str(ceil(ipair/2))];
                            displ_pair_dt{ipair}=displ_pair{ipair};
                            if CheckTimeUnit
                                if size(Time,2)>=ref_i+1+ceil(ipair/2) && size(Time,1)>=ref_j+1&& ref_i-floor(ipair/2)>=0 && ref_j>=0
                                    dt=Time(ref_j+1,ref_i+1+ceil(ipair/2))-Time(ref_j+1,ref_i+1-floor(ipair/2));%Time interval dtref_j+1
                                    displ_pair_dt{ipair}=[displ_pair_dt{ipair} ' :dt= ' num2str(dt*1000) ' m' TimeUnit];
                                end
                            else
                                displ_pair_dt{ipair}=[displ_pair_dt{ipair} ' :dt= ' num2str(ipair)];
                            end
                        else
                            displ_pair{ipair}='...';
                            displ_pair_dt{ipair}='...'; %pair not displayed in the menu
                        end
                    end
                case 'series(Dj)'
                    displ_pair_dt=cell(nbpair,1);
                    for ipair=1:nbpair
                        if select(ipair)
                            displ_pair{ipair}=['Dj= ' num2str(-floor(ipair/2)) '|' num2str(ceil(ipair/2))];
                            displ_pair_dt{ipair}=displ_pair{ipair};
                            if CheckTimeUnit
                                if size(Time,1)>=ref_j+1+ceil(ipair/2) && size(Time,2)>=ref_i+1 && ref_j-floor(ipair/2)>=0 && ref_i>=0
                                    dt=Time(ref_j+1+ceil(ipair/2),ref_i+1)-Time(ref_j+1-floor(ipair/2),ref_i+1);%Time interval dtref_j+1
                                    displ_pair_dt{ipair}=[displ_pair_dt{ipair} ' :dt= ' num2str(dt*1000) ' m' TimeUnit];
                                end
                            else
                                dt=ipair/1000;
                                displ_pair_dt{ipair}=[displ_pair_dt{ipair} ' :dt= ' num2str(dt*1000)];
                            end
                        else
                            displ_pair{ipair}='...'; %pair not displayed in the menu 
                            displ_pair_dt{ipair}='...'; %pair not displayed in the menu
                        end
                    end
                case 'pair j1-j2'%case of pairs
                    index_pair=0;
                    %get all the Time intervals in bursts
                    displ_pair_dt='';
                    for numod_a=MinIndex_j:MaxIndex_j-1 %nbfield2 always >=2 for 'pair j1-j2' mode
                        for numod_b=(numod_a+1):MaxIndex_j
                            index_pair=index_pair+1;
                            displ_pair{index_pair}=['j= ' num2stra(app, numod_a,NomTypeIma) '-' num2stra(app, numod_b,NomTypeIma)];
                            displ_pair_dt{index_pair}=displ_pair{index_pair};
                            dt(index_pair)=numod_b-numod_a;%default dt
                            if size(Time,2)>ref_i && size(Time,1)>numod_b  % && ~CheckTimeUnit
                                dt(index_pair)=Time(numod_b+1,ref_i+1)-Time(numod_a+1,ref_i+1);% Time interval dt
                                displ_pair_dt{index_pair}=[displ_pair_dt{index_pair} ' :dt= ' num2str(dt(index_pair)*1000) ' m' TimeUnit];
                            end
                        end
            
                    end
                    if index_pair ~=0
                    [~,indsort]=sort(dt);
                    displ_pair=displ_pair(indsort);
                    displ_pair_dt=displ_pair_dt(indsort);
                    end
                case 'displacement'
                    app.PairIndices.Visible='off';
            end
            if index==1 && ~strcmp(mode_selected,'displacement')
                app.ListPairCiv1.Items=displ_pair_dt';
            end
            
            %% determine the default selection in the pair menu for Civ1
            end_pair=regexp(PairCiv1Init,' :dt=');
            if ~isempty(end_pair)
                PairCiv1Init=PairCiv1Init(1:end_pair-1);%removes the info on dt value from the list
            end
            ichoice=find(strcmp(PairCiv1Init,displ_pair'),1);
             if ~isempty(ichoice)
                app.ListPairCiv1.Value=PairCiv1Init;% first valid pair proposed by default in the menu
             end
            
            %% determine the default selection in the pair menu for Civ2
            if ~strcmp(mode_selected,'displacement')
                app.ListPairCiv2.Items=displ_pair_dt';
                if strcmp(app.ListPairCiv2.Visible,'on')
                    end_pair=regexp(PairCiv2Init,' :dt=');
                    if ~isempty(end_pair)
                        PairCiv2Init=PairCiv2Init(1:end_pair-1);
                    end
                    ichoice=find(strcmp(PairCiv2Init,displ_pair'),1);
                    if ~isempty(ichoice)
                        app.ListPairCiv2.Value=PairCiv2Init;% first valid pair proposed by default in the menu
                    end
                else
                    app.ListPairCiv2.Value=app.ListPairCiv1.Value;% reproduce the civ1 pair by default
                end         
            end
            set(gcf,'Pointer','arrow')% Indicate that the process is finished
        end

%------------------------------------------------------------------------
      % determine the list of index pairs of processing file
        function [ind1, ind2, mode_selected] = find_pair_indices(app, str_civ, i_series, j_series, MinIndex_i, MaxIndex_i, MinIndex_j, MaxIndex_j)
            ind1='';
            ind2='';
            r=regexp(str_civ,'^\D(?<ind>[i|j])=( -| )(?<num1>\d+)\|(?<num2>\d+)','names');
            if ~isempty(r)
                mode_selected=['D' r.ind];
                ind1=stra2num(r.num1);
                ind2=stra2num(r.num2);
            else
                mode_selected='burst';
                r=regexp(str_civ,'^j= (?<num1>[a-z])-(?<num2>[a-z])','names');
                if ~isempty(r)
                    NomTypeNc='_1ab';
                else
                    r=regexp(str_civ,'^j= (?<num1>[A-Z])-(?<num2>[A-Z])','names');
                    if ~isempty(r)
                        NomTypeNc='_1AB';
                    else
                        r=regexp(str_civ,'^j= (?<num1>\d+)-(?<num2>\d+)','names');
                        if ~isempty(r)
                            NomTypeNc='_1_1-2';
                        end
                    end
                end
                if isempty(r)
                    display('wrong pair mode input option')
                else
                    ind1=stra2num(r.num1);
                    ind2=stra2num(r.num2);
                end
            end
        end
        
%------------------------------------------------------------------------
      % transform numbers to letters
        function str = num2stra(app, num, nom_type)

            if isempty(nom_type)
                str='';
            elseif strcmp(nom_type(end),'a')
                str=char(96+num);
            elseif strcmp(nom_type(end),'A')
                str=char(96+num);
            elseif isempty(nom_type(2:end))%a single index
                str='';
            else
                str=num2str(num);
            end
        end
        
%------------------------------------------------------------------------        
      % clean the list of checked options and control the visibility of the corresponding input frames
        function update_frame(app, CivStage)

            options={'Civ1','Fix1','Patch1','Civ2','Fix2','Patch2'};
            check_on=app.(['Check' options{CivStage}]).Value;

            if check_on % activate frames before CivStage if the current box is selected
                status=false;
                for index=1:CivStage
                    if strcmp(app.(['Check' options{index}]).Enable,'on')
                    app.(options{index}).Visible='on';%make the frame content  visible
                    app.(options{index}).Enable='on';
                    app.(['Check' options{index}]).Value=true;
                    end
                end
            else % desactivate frames at CivStage and beyond if the current box is not selected
                for index=CivStage:numel(options)
                    app.(options{index}).Visible='off';% hide the frame content 
                    app.(['Check' options{index}]).Value=0;
                end
            end
            if CivStage>=4 && check_on% case of civ2 pair choice needed
                app.TitlePairCiv2.Visible='on';
                app.ListPairCiv2.Visible='on';               
            else        
                app.TitlePairCiv2.Visible='off';
                app.ListPairCiv2.Visible='off';
            end
        end
%---------------------------------------------------------------------
        % Translate the input param from old versions of uvmat
        function ParamInput=update_input(app,OldInput)

            ParamInput=OldInput;%default
            if isfield(OldInput,'Civ1')&& isfield(OldInput.Civ1,'SearchBoxSize')%transform from SearchBoxSize to SearchRange (old to new convention)
                ParamInput.Civ1.SearchRange=round((OldInput.Civ1.SearchBoxSize-OldInput.Civ1.CorrBoxSize)/2);
            end
            if isfield(OldInput,'Civ2')&& isfield(OldInput.Civ2,'SearchBoxSize')
                ParamInput.Civ2.SearchRange=round((OldInput.Civ2.SearchBoxSize-OldInput.Civ2.CorrBoxSize)/2);
            end
            if isfield(OldInput,'Civ2')
                OldInputGene=OldInput.Civ2;
            else
                OldInputGene=OldInput.Civ1;
            end
            if isfield(OldInputGene,'Mask')
                ParamInput.Mask=OldInputGene.Mask;
            end
            if isfield(OldInputGene,'NbSlice')
                ParamInput.NbSlice=OldInputGene.NbSlice;
            end
            if isfield(OldInputGene,'Background')
                ParamInput.Background=OldInputGene.Background;
            end
            if isfield(OldInputGene,'BkgndPeriod')
                ParamInput.Background=OldInputGene.BkgndPeriod;
            end
            if isfield(OldInputGene,'MinIma')
                ParamInput.MinIma=OldInputGene.MinIma;
            end
            if isfield(OldInputGene,'MaxIma')
                ParamInput.MaxIma=OldInputGene.MaxIma;
            end
            if isfield(OldInputGene,'Maxtanh')
                ParamInput.Maxtanh=OldInputGene.Maxtanh;
            end
        end

%------------------------------------------------------------------------
        % Introduce input parameters from structure ActionInput
        function fill_civ_input(app,ActionInput)

      fill_app(ActionInput,app)% fill the elements of the current GUI civ_input with the input parameters

        options={'Civ1','Fix1','Patch1','Civ2','Fix2','Patch2'};
        for ilist=1:6
            if app.(['Check' options{ilist}]).Value==true
                app.(options{ilist}).Visible='on';
            else
                app.(options{ilist}).Visible='off';
            end
        end
        event.Value=app.CheckGrid.Value;
        CheckGrid_Callback(app, event)  
        CheckCiv2_Callback(app, event)
        end
        %%%%%%%%%% END ANCILLARY FCTS %%%%%%%%
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function civ_input_OpeningFcn(app, varargin)
            %------------------------------------------------------------------------
            % --- Executes just before civ_input is made visible.

            % Add runtime required configuration - Added by Migration Tool
            %addRuntimeConfigurations(app);
            SetDefaultParam_Callback(app,[])
            % Ensure that the app appears on screen when run
            movegui(app.civ_input, 'onscreen');

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app);

            %------------------------------------------------------------------------
            % This function has no output args, see OutputFcn.

            %% General settings
            if ~isempty(varargin)
                Param= varargin{1};
            end
            hseries=findobj(allchild(0),'Tag','series');% find the parent GUI 'series'
            hhseries=guidata(hseries); %handles of the elements in 'series'
            SeriesData=get(hseries,'UserData');% info stored in the GUI series

            %% introduce the stored Civ parameters  if available (from previous input or ImportConfig in series)
            if isfield(Param,'ActionInput')&& strcmp(Param.ActionInput.Program,Param.Action.ActionName)% the program fits with the stored data
               fill_civ_input(app,Param.ActionInput); % fill the GUI with input parameters   
            end

            %% set visibility options depending on the calling function (Param.Action.ActionName):
            z_visible='off';
            switch Param.Action.ActionName
                case 'civ_series'
                    app.ListCompareMode.Visible='on';
                    app.PairIndices.Visible='on';
                    app.title_CorrBox.Text='CorrBox x,y';
                    app.title_range.Text='Range x,y';
                    app.title_shift.Text='Shift x,y';
                case 'civ_3D'
                    app.ListCompareMode.Visible='on';
                    app.PairIndices.Visible='on';
                    z_visible='on';
                    app.title_CorrBox.Text='CorrBox x,y,z';
                    app.title_range.Text='Range x,y,z';
                    app.title_shift.Text='Shift x,y,z';
            end
            app.num_CorrBoxSize_3.Visible=z_visible;
            app.num_SearchRange_3.Visible=z_visible;
            app.num_SearchBoxShift_3.Visible=z_visible;
            app.num_Dz.Visible=z_visible;

            %% input file info
            iview_image=[];
            iview_nc=[];
            FileInfo=[];
            if isfield(SeriesData,'FileInfo')&& ~isempty(SeriesData.FileInfo)
                for iview=1:numel(SeriesData.FileInfo)
                    FieldType{iview}=SeriesData.FileInfo{iview}.FieldType;
                end
                iview_image=find(strcmp('image',FieldType));
                iview_nc=find(strcmp('civdata',FieldType));
                if numel(iview_image)>1
                    msgbox_uvmat('ERROR','only one input image series must be used as input')
                    return
                end
                if numel(iview_nc)>1
                    msgbox_uvmat('ERROR','at most one civdata file must be used as input')
                    return
                end
            else
                set(hhseries.REFRESH,'BackgroundColor',[1 0 1])% indicate that the file input in series needs to be refreshed
            end

            %% case of netcdf file as input, read the processing stage and look for corresponding images
            ind_opening=0;%default
            NomTypeNc='';
            NomTypeInput=Param.InputTable{1,4};
           NomTypeImaA=NomTypeInput;
            if ~isempty(iview_nc)
                ind_opening=SeriesData.FileInfo{iview_nc}.CivStage;
                NomTypeInput=Param.InputTable{iview_nc,4};
                if isempty(regexp(NomTypeInput,'[ab|AB|-]', 'once'))
                    app.ListCompareMode.Value='displacement'; %mode displacement advised if the nomenclature does not involve index pairs
                else
                    app.ListCompareMode.Value='PIV';
                end
                [Data,~,~,errormsg]=nc2struct(SeriesData.FileInfo{iview_nc}.FileName,[]);
                if ~isempty(errormsg)
                    msgbox_uvmat('ERROR',['error in netcdf input file: ' errormsg])
                    return
                end

                if size(Param.InputTable,1)==1
                    if isfield(Data,'Civ2_ImageA')
                        ImageName=Data.Civ2_ImageA;
                    elseif isfield(Data,'Civ1_ImageA')
                        ImageName=Data.Civ1_ImageA;
                    else
                        msgbox_uvmat('ERROR','no original image defined in netcdf input file ')
                        return
                    end
                    series('display_file_name',hhseries,ImageName,'append');%append the image series to the input list
                    [~,~,~,~,~,~,~,~,NomTypeImaA]=fileparts_uvmat(ImageName);
                end
            end


            %% display the min and max indices for the whole file series
            if isempty(Param.IndexRange.MaxIndex_i)|| isempty(Param.IndexRange.MinIndex_i)
                msgbox_uvmat('ERROR','REFRESH the input files in the GUI series')
                return
            end

            MaxIndex_i=Param.IndexRange.MaxIndex_i(1);
            MinIndex_i=Param.IndexRange.MinIndex_i(1);
            MaxIndex_j=1;%default
            MinIndex_j=1;
            if isfield(Param.IndexRange,'MaxIndex_j') && isfield(Param.IndexRange,'MinIndex_j')...
                    && numel(Param.IndexRange.MaxIndex_j)>=iview_image && numel(Param.IndexRange.MinIndex_j)>=iview_image
                MaxIndex_j=Param.IndexRange.MaxIndex_j(iview_image);
                MinIndex_j=Param.IndexRange.MinIndex_j(iview_image);
            end

            %% det the reference indices for pair display
            ref_i=floor((MinIndex_i+MaxIndex_i)/2);
            app.ref_i.Value=num2str(ref_i);%take the middle of the available range
            if isfield(Param.IndexRange,'first_j')
                ref_j=floor((MinIndex_j+MaxIndex_j)/2);
                app.ref_j.Value=num2str(ref_j);%take the middle of the available range
            end
            app.ConfigSource.Value='\default';

            %%  transfer the time from the GUI series, or use file index by default
            time=[];
            TimeUnit='frame'; %default
            % CoordUnit='';%default
            % pxcm_search=1;
            if isfield(SeriesData,'Time') && numel(SeriesData.Time)>=iview_image && ~isempty(SeriesData.Time{iview_image})
                time=SeriesData.Time{iview_image};
            end
            if isfield(Param.IndexRange,'TimeUnit')&&~isempty(Param.IndexRange.TimeUnit)
                TimeUnit=Param.IndexRange.TimeUnit;
            end

            %% timing display
            %show the reference image edit box if relevant (not needed for movies or in the absence of time information
            if numel(time)>=2 % if there are at least two time values to define dt
                if size(time,2)<MaxIndex_i
                    msgbox_uvmat('WARNING','maximum i index restricted by the timing of the xml file');
                elseif size(time,1)<MaxIndex_j
                    msgbox_uvmat('WARNING','maximum j index restricted by the timing of the xml file');
                end
                MaxIndex_i=min(size(time,2),MaxIndex_i);%possibly adjust the max index according to time data
                app.TimeSource.Value=Param.IndexRange.TimeSource;
            else
                msgbox_uvmat('WARNING','timing not defined (check xml file), default values used')
                app.TimeSource.Value=''; %xml file not used for timing
                TimeUnit='frame';
                time=ones(MaxIndex_j-MinIndex_j+1,1)*(MinIndex_i:MaxIndex_i);
                time=time+0.001*(MinIndex_j:MaxIndex_j)'*ones(1,MaxIndex_i-MinIndex_i+1);
            end
            CivInputData.Time=time;
            if ~isempty(regexp(NomTypeImaA,'(a|A)$', 'once'))
                CivInputData.NomTypeIma=NomTypeImaA;
            else
                CivInputData.NomTypeIma='_1';%used only to display pairs in ListPairCiv1 and 2
            end
            app.civ_input.UserData=CivInputData;
            app.dt_unit.Value=['dt in m' TimeUnit];%display dt in unit 10-3 of the time (e.g ms)
            app.TimeUnit.Value=TimeUnit;


            %% set the civ_input options, depending on the input file content if a nc file has been opened
            options={'Civ1','Fix1','Patch1','Civ2','Fix2','Patch2'};

            checkrefresh=0;
            if ind_opening==0  %case of image opening, start with Civ1

                for ilist=1:6
                    if app.(['Check' options{ilist}]).Value==true
                        app.(options{ilist}).Visible='on';
                    else
                        app.(options{ilist}).Visible='off';
                    end
                end
            else  %case of netcdf file opening, start with the stage read in the file if the input file is being refreshed
                fill_panel(app, Data,[]);
                for ilist = 1:min(ind_opening,3)
                    app.(['Check' options{ilist}]).Value=false;
                    app.(['Check' options{ilist}]).Enable=false;
                    app.(options{ilist}).Enable='off';
                    fill_panel(app, Data,options{ilist});
                end
                app.(['Check' options{min(ind_opening+1,4)}]).Value=true;
                app.(options{min(ind_opening+1,4)}).Enable='on';% allow the frame next to the current civ stage
                app.(options{min(ind_opening+1,4)}).Visible='on';
                app.(['Check' options{min(ind_opening+1,4)}]).Enable='on';
                if isempty(FileInfo)
                    FileInfo.FileName='';
                end
               app.ConfigSource.Value=FileInfo.FileName;
                checkrefresh=1;
            end

            if isfield(Param,'ActionInput') && isfield(Param.ActionInput,'ListCompareMode')&&...
                    strcmp(Param.ActionInput.ListCompareMode,'displacement')
                set(handles.PairIndices,'Visible','off')
                set(handles.CheckRefFile,'Visible','on')
            else
                set(handles.CheckRefFile,'Visible','off')
            end

            %% reinitialise pair menus
            set(handles.ListPairMode,'Value',1)
            set(handles.ListPairMode,'String',{''})
            set(handles.ListPairCiv1,'Value',1)
            set(handles.ListPairCiv1,'String',{''})
            set(handles.ListPairCiv2,'Value',1)
            set(handles.ListPairCiv2,'String',{''})

            %% set the menu and default choice of civ pairs
            MinIndex_j_table=get(hhseries.MinIndex_j,'Data');
            MaxIndex_j_table=get(hhseries.MaxIndex_j,'Data');
            MinIndex_j=MinIndex_j_table(iview_image);
            MaxIndex_j=MaxIndex_j_table(iview_image);
            if isnan(MinIndex_j)||isequal(MaxIndex_j,MinIndex_j)|| strcmp(Param.Action.ActionName,'civ_3D')% no possibility of j pairs
                PairMenu={'series(Di)'};
            elseif MaxIndex_j-MinIndex_j==1
                PairMenu={'pair j1-j2';'series(Di)'};
            elseif  MaxIndex_i==MinIndex_i && MaxIndex_j-MinIndex_j>2% simple series in j
                PairMenu={'pair j1-j2';'series(Dj)'};
            else
                PairMenu={'pair j1-j2';'series(Dj)';'series(Di)'};%multiple choice
            end
            app.ListPairMode.Items=PairMenu;

            %% set default choice of pair mode
            PairIndex=[];
            if isfield(Param,'ActionInput') && isfield(Param.ActionInput,'PairIndices') && isfield(Param.ActionInput.PairIndices,'ListPairMode')
                PairIndex=find(strcmp(Param.ActionInput.PairIndices.ListPairMode,PairMenu));%retrieve the previous option
            end
            if strcmp(Param.Action.ActionName,'civ_3D')
                PairIndex=1;
            else
                if isempty(PairIndex)
                    if ~isfield(Param.IndexRange,'first_j')||isequal(MaxIndex_j,MinIndex_j)% no possibility of j pairs
                        PairIndex=1;
                    elseif  MaxIndex_i==1 && MaxIndex_j>1% simple series in j
                        if  MaxIndex_j <= 10
                            PairIndex=1;% advice 'pair j1-j2' except in MaxIndex_j is large
                        end
                    else
                        if strcmp(NomTypeNc,'_1-2_1')
                            PairIndex=3;% advise 'series(Di)'
                        elseif  MaxIndex_j <= 10
                            PairIndex=1;% advice 'pair j1-j2' except in MaxIndex_j is large
                        else
                            PairIndex=2;% advice 'Dj'
                        end
                    end
                end
            end
            app.ListPairMode.Value=PairMenu{PairIndex};

            %% indicate the min and max indices i and j on the GUI
            MinIndex_i_table=get(hhseries.MinIndex_i,'Data');
            MaxIndex_i_table=get(hhseries.MaxIndex_i,'Data');
            app.MinIndex_i.Value=num2str(MinIndex_i_table(iview_image));
            app.MaxIndex_i.Value=num2str(MaxIndex_i_table(iview_image));
            app.MinIndex_j.Value=num2str(MinIndex_j);
            app.MaxIndex_j.Value=num2str(MaxIndex_j);

            %% list the possible index pairs, depending on the option set in ListPairMode
            ListPairMode_Callback(app, [])
            if isfield(Param,'ActionInput')&& isfield(Param.ActionInput,'PairIndices')
                if isfield(Param.ActionInput.PairIndices,'ListPairCiv1')
                    PairChoiceCiv1=find(strcmp(Param.ActionInput.PairIndices.ListPairCiv1,get(handles.ListPairCiv1,'String')));
                    if ~isempty(PairChoiceCiv1)
                        set(handles.ListPairCiv1,'Value',PairChoiceCiv1)
                    end
                end
                if isfield(Param.ActionInput.PairIndices,'ListPairCiv2')
                    PairChoiceCiv2=find(strcmp(Param.ActionInput.PairIndices.ListPairCiv2,get(handles.ListPairCiv2,'String')));
                    if ~isempty(PairChoiceCiv2)
                        set(handles.ListPairCiv2,'Value',PairChoiceCiv2)
                    end
                end
            end

            %% set the GUI to modal: wait for OK to close
            drawnow
            uiwait(app.civ_input);
        end

        % Value changed function: CheckBackground
        function CheckBackground_Callback(app, event)

            testbackground=0;
            if app.CheckBackground.Value% if the checkbox is activated
                hseries=findobj(allchild(0),'Tag','series');
                hhseries=guidata(hseries);
                InputTable=get(hhseries.InputTable,'Data');
                if strcmp(InputTable{1,5},'.nc')
                    ind_A=2;%case of nc file as input (for civ3), image in second line
                else
                    ind_A=1;% line index of the (first) image series
                end
                % browse for a background
                filebackground= uigetfile_uvmat('pick a background image file:',InputTable{ind_A,1},'.png');
                if ~isempty(filebackground)
                    [FilePath,FileName,FileExt]=fileparts(filebackground);
                    [RootPath,SubDir,RootFile,i1_series,~,j1_series,~,NomType]=find_file_series(FilePath,[FileName FileExt]);
                    Min_i=find( i1_series(1,2,:), 1 );
                    Check_zero=0;
                    r=regexp(NomType,'^_(?<i1>\d+)1$','names');
                    if ~isempty(r)
                        nb_zero=numel(r.i1);% nbre of zeros
                        if nb_zero>0 && Min_i>10^nb_zero
                            NomType='_1';
                        end
                    end

                    if strcmp(NomType,'_1')|| strcmp(NomType,'_1_1')
                        [~,ref_i]=find(squeeze(i1_series(1,:,:)));% the index i stands for the i index of the image to process
                        if isscalar(ref_i)
                            msgbox_uvmat('ERROR','do not put any index  _1,_2,... for a single background image name');
                        else
                            diff_ref_i=diff(ref_i,1);
                            if isequal (diff_ref_i,diff_ref_i(1)*ones(size(diff_ref_i)))
                                app.num_BkgndPeriod.Value=num2str(diff_ref_i(1));
                                app.num_BkgndPeriod.Visible='on';
                            end
                        end
                        if strcmp(NomType,'_1_1')% period background at different levels (multilevel of volume scan)
                            NbSlice=j1_series(1,2,end);
                            app.num_NbSlice.Value=num2str(NbSlice);% slice deptermination detected
                            app.num_NbSlice.Visible='on';
                        end
                    elseif ~strcmp(NomType,'*')% other kind of file indexing
                        msgbox_uvmat('ERROR','multilevel background images must be labeled with a single index as _1,_2,...');
                        return
                    end
                    app.CheckBackground.UserData=filebackground;%store for future use
                    testbackground=1;
                end
            end
            if testbackground
                app.Background.Visible='on';
                app.Background.Value=fullfile(RootPath,SubDir,RootFile);
                app.CheckBackground.Value=true;
                if strcmp(NomType,'_1')
                    app.num_BkgndPeriod.Visible='on';
                end
            else
                app.CheckBackground.Value=0;
                app.handle_txtbox.Visible='off';
                app.num_BkgndPeriod.Visible='off';
            end
app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
        end

        % Value changed function: CheckCiv1
        function CheckCiv1_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in CheckCiv1.
            update_frame(app,1)
        end

        % Value changed function: CheckCiv2
        function CheckCiv2_Callback(app, event)

            update_frame(app,4)
        end

        % Value changed function: CheckDeformation
        function CheckDeformation_Callback(app, event)
            % --- Executes on button press in CheckDeformation.
            app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
            if app.CheckDeformation.Value
                app.CoorSmooth_civ2.Visible='off';
            else
                app.CoorSmooth_civ2.Visible='on';
            end
        end

        % Value changed function: CheckFix1
        function CheckFix1_Callback(app, event)

            update_frame(app,2)
        end

        % Value changed function: CheckFix2
        function CheckFix2_Callback(app, event)

            update_frame(app, 5)
        end

        % Value changed function: CheckGrid
        function CheckGrid_Callback(app, event)

            visible_corr='on';
            visible_grid='on';
            visible_gridfile='off';
          
            if event.Value% if the checkbox is activated
                filegrid=app.Grid.Value;
                [Data,~,~,errormsg]=nc2struct(filegrid);% try to open the displayed input file
                if ~isempty(errormsg) % browse for a grid file
                    hseries=findobj(allchild(0),'Tag','series');
                    hhseries=guidata(hseries);
                    InputTable=get(hhseries.InputTable,'Data');
                    filegrid= uigetfile_uvmat('pick a netcdf grid file :',InputTable{1,1},'.nc');
                    if ~isempty(filegrid)
                    [Data,~,~,errormsg]=nc2struct(filegrid);
                    end
                end
                if isempty(errormsg)
                    if isfield(Data,'Grid')
                        visible_grid='off';
                        visible_gridfile='on';
                        app.Grid.Value=filegrid;
                    else
                        msgbox_uvmat('ERROR', 'invalid grid file')
                        return
                    end
                    if isfield(Data,'CorrBoxSize')
                        visible_corr='off';
                    end
                end
            end
            app.Grid.Visible=visible_gridfile;
            app.num_Dx.Visible=visible_grid;
            app.num_Dy.Visible=visible_grid;
            app.title_Dx.Visible=visible_grid;
            app.title_Dy.Visible=visible_grid;
            app.num_CorrBoxSize_1.Visible=visible_corr;
            app.num_CorrBoxSize_2.Visible=visible_corr;
            app.title_CorrBox.Visible=visible_corr;
        end

        % Value changed function: CheckMask
        function CheckMask_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in CheckMask: common to all panels (civ1, Civ2..)

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            %------------------------------------------------------------------------
            hparent=get(hObject,'parent');
            hchildren=get(hparent,'children');
            handle_txtbox=findobj(hchildren,'tag','Mask');% look for the mask name box in the same panel
            testmask=0;
            if get(hObject,'Value')% if the checkbox is activated
                hseries=findobj(allchild(0),'Tag','series');
                hhseries=guidata(hseries);
                InputTable=get(hhseries.InputTable,'Data');
                if strcmp(InputTable{1,5},'.nc')
                    ind_A=2;%case of nc file as input (for civ3), image in second line
                else
                    ind_A=1;% line index of the (first) image series
                end
                % browse for a mask
                MaskFile= uigetfile_uvmat('pick a mask image file:',InputTable{ind_A,1},'.png');
                if ~isempty(MaskFile)
                    [FilePath,FileName,FileExt]=fileparts(MaskFile);
                    [RootPath,SubDir,RootFile,i1_series,i2,j1,j2,NomType]=find_file_series(FilePath,[FileName FileExt]);
                    if strcmp(NomType,'_1')
                        NbSlice=i1_series(1,2,end);
                        set(handles.num_NbSlice,'String',num2str(NbSlice))
                    elseif ~strcmp(NomType,'*')
                        msgbox_uvmat('ERROR','multilevel masks must be labeled with a single index as _1,_2,...');
                        return
                    end
                    set(hObject,'UserData',MaskFile);%store for future use
                    testmask=1;% check that a mask has been introduced
                end
            end
            if testmask
                set(handles.Mask,'Visible','on')
                set(handles.Mask,'String',fullfile(RootPath,SubDir,RootFile))
                set(handles.CheckMask,'Value',1)
                if strcmp(NomType,'_1')
                    set(handles.num_NbSlice,'Visible','on')
                end
            else
                set(hObject,'Value',0);
                set(handle_txtbox,'Visible','off')
                set(handles.num_NbSlice,'Visible','off')
            end
app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
        end

        % Value changed function: CheckPatch1
        function CheckPatch1_Callback(app, event)
   
            update_frame(app, 3)
        end

        % Value changed function: CheckPatch2
        function CheckPatch2_Callback(app, event)

            update_frame(app,6)
        end

        % Button pushed function: CheckRefFile
        function CheckRefFile_Callback(app, event)
        
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            hseries=findobj(allchild(0),'Tag','series');
            hhseries=guidata(hseries);
            InputTable=get(hhseries.InputTable,'Data');
            i1=str2num(get(hhseries.num_first_i,'String'));
            j1=str2num(get(hhseries.num_first_j,'String'));
            InputFile=fullfile_uvmat(InputTable{1,1},InputTable{1,2},InputTable{1,3},InputTable{1,5},InputTable{1,4},i1,[],j1);
            % browse for a reference file for displacement
            fileref= uigetfile_uvmat('pick a reference image file:',InputFile);
            if ~isempty(fileref)
                FileInfo=get_file_info(fileref);
                CheckImage=strcmp(FileInfo.FieldType,'image');% =1 for images
                if ~CheckImage
                    msgbox_uvmat('ERROR',['invalid file type input for reference image: ' FileInfo.FileType ' not an image'])
                else
                    if isfield (FileInfo,'NumberOfFrames')&& FileInfo.NumberOfFrames>1
                        set(handles.num_OriginIndex,'Visible','on')
                        set(handles.OriginIndex_title,'Visible','on')
                    else
                        set(handles.num_OriginIndex,'Visible','off')
                        set(handles.OriginIndex_title,'Visible','off')
                    end
                    set(handles.RefFile,'String',fileref)
app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
                end
            end
        end

        % Value changed function: CheckRescale
        function CheckRescale_Callback(app, event)
  
            if app.CheckRescale.Value
                visible_str='on';
            else
                visible_str='off';
            end
            app.num_Maxtanh.Visible=visible_str;
            app.Maxtanh_title.Visible=visible_str;
            app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
        end

        % Value changed function: CheckThreshold
        function CheckThreshold_Callback(app, event)

            if app.CheckThreshold.Value
                visible_str='on';
            else
                visible_str='off';
            end
            app.num_MinIma.Visible=visible_str;
            app.num_MaxIma.Visible=visible_str;
            app.Threshold_title.Visible=visible_str;
            app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
        end

        % Button pushed function: ImportParam
        function ImportParam_Callback(app, event)
            %------------------------------------------------------------------------
            hseries=findobj(allchild(0),'Tag','series');
            hhseries=guidata(hseries);
            InputTable=get(hhseries.InputTable,'Data');% read the input file(s) table in the GUI series as a starting path to search the input xml file
            oldfile=InputTable{1,1};
            if isempty(oldfile)
                % use a file name stored in prefdir
                dir_perso=prefdir;
                profil_perso=fullfile(dir_perso,'uvmat_perso.mat');
                if exist(profil_perso,'file')
                    h=load (profil_perso);
                    if isfield(h,'RootPath') && ischar(h.RootPath)
                        oldfile=h.RootPath;
                    end
                end
            end
            filexml=uigetfile_uvmat('pick a xml parameter file for civ',oldfile,'.xml');% get the xml file containing processing parameters
            %proceed only if a file has been introduced by the browser
            if ~isempty(filexml)
                Param=xml2struct(filexml);% read the input xml file as a Matlab structure
                if ~isfield(Param,'InputTable')||~isfield(Param,'IndexRange')
                    msgbox_uvmat('ERROR','invalid config file: open a file in a folder ''/0_XML''')
                    return
                end
                check_input=false;% check the validity of the input xml file as civ parameters
                if isfield(Param,'ActionInput')
                    if isfield(Param.ActionInput,'Program')&& ismember(Param.ActionInput.Program,{'civ_series','civ_3D'})
                        ActionInput=update_input(app,Param.ActionInput);% translate from old conventions if needed
                        fill_civ_input(app,ActionInput); % fill the GUI with input parameters  
                        app.ConfigSource.Value=filexml;% indicate the source file in box app.ConfigSource
                        check_input=true;%
                    end
                end
                if ~check_input
                    msgbox_uvmat('ERROR','invalid config file (not for civ_series')
                    return
                end
            end
        end

        % Value changed function: ListCompareMode
        function ListCompareMode_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in ListCompareMode.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            ListCompareMode=get(handles.ListCompareMode,'String');
            option=ListCompareMode{get(handles.ListCompareMode,'Value')};
           
            OriginIndex='off';
            PairIndices='off';
            switch option
                case 'PIV'
                    PairIndices='on';% needs to define index pairs for PIV
                case 'displacement'
                    OriginIndex='on';%define a frame origin for displacement
            end
            set(handles.num_OriginIndex,'Visible',OriginIndex)
            set(handles.OriginIndex_title,'Visible',OriginIndex)
            set(handles.CheckRefFile,'Visible',OriginIndex)
            set(handles.RefFile,'Visible',OriginIndex)
            set(handles.PairIndices,'Visible',PairIndices)
            ListPairMode_Callback(app,event)
            if strcmp(OriginIndex,'on')
                set(handles.CheckRefFile,'Value',1)
                CheckRefFile_Callback(app,event)
            end
        end

        % Value changed function: ListPairCiv1
        function ListPairCiv1_Callback(app, event)

            %------------------------------------------------------------------------
            %reproduce by default the chosen pair in the checkciv2 menu
            app.ListPairCiv2.Value=app.ListPairCiv1.Value;%civ2 selection the same as civ1 by default
        end

        % Value changed function: ListPairMode
        function ListPairMode_Callback(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Callbacks in the uipanel Pair Indices
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------------------------------------------
            % --- Executes on button press in ListPairMode.
            
            % Create GUIDE-style callback args - Added by Migration Tool
           % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            
            % compare_list=get(handles.ListCompareMode,'String');
            % val=get(handles.ListCompareMode,'Value');
            % compare=compare_list{val};
            compare=app.ListCompareMode.Value;
            if strcmp(compare,'displacement')||strcmp(compare,'shift')
                mode_selected='displacement';
            else
                % mode_list=get(handles.ListPairMode,'String');
                % if ischar(mode_list)
                %     mode_list={mode_list};
                % end
                % mode_value=get(handles.ListPairMode,'Value');
                % if isempty(mode_value)|| mode_value>numel(mode_list)
                %     mode_value=1;
                % end
                % mode_list=app.ListPairMode.items;
                 mode_selected=app.ListPairMode.Value;
                % mode_selected=mode_list{mode_value};
            end
            ref_i=str2double(app.ref_i.Value);
            CivInputData=app.civ_input.UserData;
           TimeUnit=app.TimeUnit.Value;
            CheckTimeUnit=~strcmp(TimeUnit,'frame');
            time=CivInputData.Time;
            siztime=size(CivInputData.Time);
            nbfield=siztime(1)-1;
            nbfield2=siztime(2)-1;
            %indchosen=1;  %%first pair selected by default
            % in mode 'pair j1-j2', j1 and j2 are the file indices, else the indices
            % are relative to the reference indices ref_i and ref_j respectively.
            if isequal(mode_selected,'pair j1-j2')
                dt=1;
                index=0;
                numlist_a=[];
                %get all the time intervals in bursts
                displ_dt=1;%default
                nbfield2=min(nbfield2,10);%limitate the number of pairs to 10x10
                for numod_a=1:nbfield2-1 %nbfield2 always >=2 for 'pair j1-j2' mode
                    for numod_b=(numod_a+1):nbfield2
                        index=index+1;
                        numlist_a(index)=numod_a;
                        numlist_b(index)=numod_b;
                        if size(time,1)>1 && CheckTimeUnit && size(CivInputData.Time,2)>ref_i && size(CivInputData.Time,1)>numod_b
                            dt(numod_a,numod_b)=CivInputData.Time(numod_b+1,ref_i+1)-CivInputData.Time(numod_a+1,ref_i+1);%first time interval dt
                            displ_dt(index)=dt(numod_a,numod_b);
                        else
                            displ_dt(index)=1;
                        end
                    end
                end
                enable_j(app,'off')
            elseif isequal(mode_selected,'series(Dj)') %| isequal(mode,'st_series(Dj)')
                enable_j(app, 'on')
            elseif isequal(mode_selected,'series(Di)') %| isequal(mode,'st_series(Di)')
                enable_i(app,  'on')
                if nbfield2 > 1
                    enable_j(app,  'on')
                else
                    enable_j(app, 'off')
                end
            elseif isequal(mode_selected,'displacement')%the pairs have the same indices
                if nbfield > 1 || nbfield==0
                    enable_i(app,  'on')
                else
                    enable_j(app,  'off')
                end
                if nbfield2 > 1
                    enable_j(app,  'on')
                else
                    enable_j(app, 'off')
                end
            end
            errormsg=find_netcpair_civ(app,1);
            if ~isempty(errormsg)
                msgbox_uvmat('ERROR',errormsg)
            end
        end

        % Menu selected function: MenuHelp
        function MenuHelp_Callback(app, event)

            web('https://legi.grenoble.gricad-pages.univ-grenoble-alpes.fr/soft/uvmat-doc/help/#PIV')
        end

        % Button pushed function: OK
        function OK_Callback(app, event)

            uiresume(app.civ_input)
        end

        % Button pushed function: SetDefaultParam
        function SetDefaultParam_Callback(app, event)
                      
            Param.ConfigSource='\default';
            Param.CheckMask=false;
            Param.Mask='';
            Param.CheckBackground=false;
            Param.CheckThreshold=false;
            Param.CheckRescale=false;
            Param.Maxtanh=[];
            
            %% Civ1 parameters
            %Param.CheckCiv1=1;
            Param.Civ1.CorrBoxSize=[31 31 1];
            Param.Civ1.SearchRange=[15 15];
            Param.Civ1.SearchBoxShift=[0 0];
            Param.Civ1.CorrSmooth=1;
            Param.Civ1.Dx=20;
            Param.Civ1.Dy=20;
            Param.Civ1.CheckGrid=0;
            Param.Civ1.TestCiv1=false;
                         
            %% Fix1 parameters
            Param.Fix1.MinCorr=0.2000;
            
            %% Patch1 parameters
            %Param.CheckPatch1=1;
            Param.Patch1.FieldSmooth=200;
            Param.Patch1.MaxDiff=1.5;
            Param.Patch1.SubDomainSize=125;
            
            %% Civ2 parameters
            %Param.CheckCiv2=1;
            Param.Civ2.CorrBoxSize=[21 21];
            Param.Civ2.SearchRange=[3 3];
            Param.Civ2.CorrSmooth=1;
            Param.Civ2.Dx=10;
            Param.Civ2.Dy=10;
            Param.Civ2.CheckGrid=0;
            
            %% Fix2 parameters
            Param.Fix2.MinCorr=0.2000;
            
            %% Patch2 parameters
            Param.Patch2.FieldSmooth=20;
            Param.Patch2.MaxDiff=1;
            Param.Patch2.SubDomainSize=250;
            
            fill_app(Param,app)% fill the elements of the GUI series with the input parameters
            %update_CivOptions(app, 0)
        end

        % Value changed function: TestCiv1
        function TestCiv1_Callback(app, event)
        % prepare the image correlation function
        %------------------------------------------------------------------------
            if app.TestCiv1.Value
                app.TestCiv1.BackgroundColor=[1 1 0];% paint TestCiv1 button to yellow to confirm civ launch
                update_frame(app,1);
                hseries=findobj(allchild(0),'Tag','series');   % get info from the GUI 'series'
                Param=read_GUI(hseries);
                Param.Action.RUN=1;
                Param.ActionInput=read_app(app);
                i1=str2double(app.ref_i.Value);  %references indices
                i2=i1;
                j1=1;
                if strcmp(app.ref_j.Visible,'on')
                    j1=str2double(app.ref_j.Value);
                end
                j2=j1;
                if strcmp(app.PairIndices.Visible,'on')
                    str_civ=app.ListPairCiv1.Value;
                    r=regexp(str_civ,'^\D(?<ind>[i|j])=( -| )(?<num1>\d+)\|(?<num2>\d+)','names');
                    if ~isempty(r)
                        if strcmp(r.ind,'i')
                            i1=i1-str2num(r.num1);
                            i2=i2 +str2num(r.num2);
                        elseif strcmp(r.ind,'j')
                            j1=j1-str2num(r.num1);
                            j2=j2 +str2num(r.num2);
                        end
                    else % mode='j1-j2';
                        r=regexp(str_civ,'^j= (?<num1>[a-z])-(?<num2>[a-z])','names');
                        if isempty(r)
                            r=regexp(str_civ,'^j= (?<num1>[A-Z])-(?<num2>[A-Z])','names');
                            if isempty(r)
                                r=regexp(str_civ,'^j= (?<num1>\d+)-(?<num2>\d+)','names');
                            end
                        end
                        if isempty(r)
                            disp('wrong pair mode input option')
                        else
                            j1=stra2num(r.num1);
                            j2=stra2num(r.num2);
                        end
                    end
                end
                par_civ1=Param.ActionInput.Civ1;
                RootPath_A=Param.InputTable{1,1};
                SubDir_A=Param.InputTable{1,2};
                RootFile_A=Param.InputTable{1,3};
                NomType_A=Param.InputTable{1,4};
                FileExt_A=Param.InputTable{1,5};
                if strcmp(Param.ActionInput.ListCompareMode,'displacement')
                    ImageName_A=Param.ActionInput.RefFile;
                else
                    ImageName_A=fullfile_uvmat(RootPath_A,SubDir_A,RootFile_A,FileExt_A,NomType_A,i1,[],j1);
                end
                ImageName_B=fullfile_uvmat(RootPath_A,SubDir_A,RootFile_A,FileExt_A,NomType_A,i2,[],j2);
                par_civ1.ImageA = read_image(ImageName_A);
                par_civ1.ImageB = read_image(ImageName_B);
                par_civ1.CorrSmooth=0;% will give only the grid of data points expected for PIV, computations will be activated by the fct mouse_motion.m
                [Data.Civ1_X,Data.Civ1_Y,Data.Civ1_U,Data.Civ1_V,Data.Civ1_C,Data.Civ1_FF, ~, errormsg]=civ(par_civ1);
                if ~isempty(errormsg)
                    disp(errormsg)
                    return
                end % rmq: error msg displayed in civ_series
            
                %% create image data ImageData for display
                ImageData.ListVarName={'ny','nx','A'};
                ImageData.VarDimName= {'ny','nx',{'ny','nx'}};
                ImageData.VarAttribute{1}.Role='coord_y';
                ImageData.VarAttribute{2}.Role='coord_x';
                ImageData.VarAttribute{3}.Role='scalar';
                ImageData.A=par_civ1.ImageA; % get the first image
                if ndims(ImageData.A)==3 %case of color image
                    ImageData.VarDimName= {'ny','nx',{'ny','nx','rgb'}};
                end
                ImageData.ny=[size(ImageData.A,1) 1];
                ImageData.nx=[1 size(ImageData.A,2)];
                ImageData.CoordUnit='pixel';% used to set equal scaling for x and y in image dispa=ly
            
                %% create the figure view_field for image visualization
                hview_field=view_field(ImageData); %view the image in the GUI view_field
                set(0,'CurrentFigure',hview_field)
                hhview_field=guihandles(hview_field);
                set(hview_field,'CurrentAxes',hhview_field.PlotAxes)
                ViewData=get(hview_field,'UserData');
                ViewData.CivHandle=app.civ_input;% indicate the handle of the civ GUI in view_field
                ViewData.PlotAxes.X=Data.Civ1_X';
                ViewData.PlotAxes.Y=Data.Civ1_Y';
                ViewData.PlotAxes.B=par_civ1.ImageB;%store the second image in the UserData of the GUI view_field
                set(hview_field,'UserData',ViewData)% store the info in the UserData of image view_field, to be used by mouse_motion.m
            
                %% look for a current figure for image correlation display
                corrfig=findobj(allchild(0),'tag','corrfig');
                if isempty(corrfig)
                    corrfig=figure;
                    set(corrfig,'tag','corrfig')
                    set(corrfig,'name','image correlation')
                    set(corrfig,'DeleteFcn',{@app.closeview_field})%
                    app.TestCiv1.BackgroundColor=[1 0 0];
                else
                    app.TestCiv1.BackgroundColor=[1 0 0];% paint button to red
                    corrfig=findobj(allchild(0),'tag','corrfig');% look for a current figure for image correlation display
                    if ~isempty(corrfig)
                        delete(corrfig)
                    end
                    hview_field=findobj(allchild(0),'tag','view_field');% look for view_field
                    if ~isempty(hview_field)
                        delete(hview_field)
                    end
                end
            else
                hview_field=findobj(allchild(0),'Tag','view_field'); %view the image in the GUI view_field
                if ~isempty(hview_field)
                    delete(hview_field)
                end
            end
        end

        % Callback function
        function TestPatch1_Callback(app, event)
    
            msgbox_uvmat('WARNING','open the civ file and run "series/test_filter_tps" ')
        end

        % Value changed function: get_ref_fix1
        function get_ref_fix1_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in get_ref_fix1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            filebase=get(handles.RootPath,'String');
            [FileName, PathName, filterindex] = uigetfile( ...
                {'*.nc', ' (*.nc)';
                '*.nc',  'netcdf files '; ...
                '*.*', 'All Files (*.*)'}, ...
                'Pick a file',filebase);
            
            fileinput=[PathName FileName];
            sizf=size(fileinput);
            if (~ischar(fileinput)||~isequal(sizf(1),1)),return;end %stop if fileinput not a character string
            %[Path,File,field_count,str2,str_a,str_b,ref.ext,ref.nom_type,ref.subdir]=name2display(fileinput);
            [Path,ref.subdir,File,ref.num1,ref.num2,ref.num_a,ref.num_b,ref.ext,ref.nom_type]=fileparts_uvmat(fileinput);
            ref.filebase=fullfile(Path,File);
            
            %browse=[];%initialisation
            if ~isequal(ref.ext,'.nc')
                msgbox_uvmat('ERROR','the reference file must be in netcdf format (*.nc)')
                return
            end
            set(handles.ref_fix1,'String',[fullfile(ref.subdir,File) '....nc']);
            set(handles.ref_fix1,'UserData',ref)
            menu_field{1}='civ1';
            Data=nc2struct(fileinput,[]);
            if isfield(Data,'patch') && isequal(Data.patch,1)
                menu_field{2}='filter1';
            end
            if isfield(Data,'civ2') && isequal(Data.civ2,1)
                menu_field{3}='civ2';
            end
            if isfield(Data,'patch2') && isequal(Data.patch2,1)
                menu_field{4}='filter2';
            end
            set(handles.field_ref1,'String',menu_field);
            set(handles.field_ref1,'Value',length(menu_field));
            set(handles.num_MinVel,'Value',2);
            set(handles.num_MinVel,'String','1');%default threshold
            set(handles.ref_fix1,'Enable','on')
        end

        % Value changed function: get_ref_fix2
        function get_ref_fix2_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in get_ref_fix2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            if isequal(get(handles.get_ref_fix2,'Value'),1)
                filebase=get(handles.RootPath,'String');
                [FileName, PathName, filterindex] = uigetfile( ...
                    {'*.nc', ' (*.nc)';
                    '*.nc',  'netcdf files '; ...
                    '*.*', 'All Files (*.*)'}, ...
                    'Pick a file',filebase);
                fileinput=[PathName FileName];
                sizf=size(fileinput);
                if (~ischar(fileinput)||~isequal(sizf(1),1)),return;end %stop if fileinput not a character string
                %[Path,File,field_count,str2,str_a,str_b,ref.ext,ref.nom_type,ref.subdir]=name2display(fileinput);
                [Path,ref.subdir,File,ref.num1,ref.num2,ref.num_a,ref.num_b,ref.ext,ref.nom_type]=fileparts_uvmat(fileinput);
                ref.filebase=fullfile(Path,File);
                %     ref.num_a=stra2num(str_a);
                %     ref.num_b=stra2num(str_b);
                %     ref.num1=str2num(field_count);
                %     ref.num2=str2num(str2);
                browse=[];%initialisation
                if ~isequal(ref.ext,'.nc')
                    msgbox_uvmat('ERROR','the reference file must be in netcdf format (*.nc)')
                    return
                end
                set(handles.ref_fix2,'String',[fullfile(ref.subdir,File) '....nc']);
                set(handles.ref_fix2,'UserData',ref)
                menu_field{1}='civ1';
                Data=nc2struct(fileinput,[]);
                if isfield(Data,'patch') && isequal(Data.patch,1)
                    menu_field{2}='filter1';
                end
                if isfield(Data,'civ2') && isequal(Data.civ2,1)
                    menu_field{3}='civ2';
                end
                if isfield(Data,'patch2') && isequal(Data.patch2,1)
                    menu_field{4}='filter2';
                end
                set(handles.field_ref2,'String',menu_field);
                set(handles.field_ref2,'Value',length(menu_field));
                set(handles.num_MinVel,'Value',2);
                set(handles.num_MinVel,'String','1');%default threshold
                set(handles.ref_fix2,'Enable','on')
                set(handles.ref_fix2,'Visible','on')
                set(handles.field_ref2,'Visible','on')
            else
                set(handles.ref_fix2,'Visible','off')
                set(handles.field_ref2,'Visible','off')
            end
        end

        % Value changed function: num_CorrSmooth, num_CorrSmooth_civ2
        function num_CorrSmooth_Callback(app, event)
           
            app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
        end

        % Value changed function: num_MaxIma
        function num_MaxIma_Callback(app, event)
            %------------------------------------------------------------------------
            % --- synchronise the image threshold for civ1 and civ2
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            MaxIma_string=get(hObject,'String');
            set(handles.num_MaxIma,'String',MaxIma_string);
            set(handles.num_MaxIma,'Visible','on');
            set(handles.title_Threshold,'Visible','on');
            set(handles.CheckThreshold,'Value',true);
            
app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
        end

        % Value changed function: num_Maxtanh
        function num_Maxtanh_Callback(app, event)
            %------------------------------------------------------------------------
            % --- synchronise the image rescaling threshold for civ1 and civ2
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Maxtanh_string=get(hObject,'String');
            set(handles.num_Maxtanh,'String',Maxtanh_string);
            set(handles.num_Maxtanh,'Visible','on');
            set(handles.title_Maxtanh,'Visible','on');
            set(handles.CheckRescale,'Value',true);
            
      app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
        end

        % Value changed function: ref_fix1
        function ref_fix1_Callback(app, event)
            %------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            set(handles.num_MinVel,'Value',1);
            set(handles.field_ref1,'Value',1)
            set(handles.field_ref1,'String',{' '})
            set(handles.ref_fix1,'UserData',[]);
            set(handles.ref_fix1,'String','');
            set(handles.thresh_vel1,'String','0');
        end

        % Value changed function: ref_i, ref_j
        function ref_i_Callback(app, event)
            %------------------------------------------------------------------------

            mode_selected=app.ListPairMode.Value;
            errormsg=find_netcpair_civ(app,1);% update the menu of pairs depending on the available netcdf files
            if isequal(mode_selected,'series(Di)') || ...% we do patch2 only
                    (app.CheckCiv2.Value==0 && app.CheckCiv1.Value==0 && app.CheckFix1.Value==0 && app.CheckPatch1.Value==0)
                errormsg=find_netcpair_civ(app, 2);
            end
            if isempty(errormsg)
                app.ref_i.BackgroundColor=[1 1 1];
                app.ref_j.BackgroundColor=[1 1 1];
            else
                msgbox_uvmat('ERROR',errormsg)
            end
        end

        % Callback function
        function civ_inputWindowButtonDown(app, event)
            
        end

        % Callback function
        function civ_inputWindowButtonDown2(app, event)
            
        end

        % Callback function: civ_input, civ_input
        function civ_inputKeyPress(app, event)
            ListExclude={'CheckCiv1','CheckFix1','CheckPatch1','CheckCiv2','CheckFix2','CheckPatch2','ref_i'};
            if isempty(find(strcmp(get(gco,'Tag'),ListExclude),1))% if the selected uicontrol is not in the Exclude list
                app.ConfigSource.Value='NEW';% indicate that the configuration is new
                app.OK.BackgroundColor=[1 0 1];%
                drawnow
            end
            key = event.Key;           
        end

        % Value changed function: CheckGrid_civ2
        function CheckGrid_civ2ValueChanged(app, event)

            visible_corr='on';
            visible_grid='on';
            visible_gridfile='off';
          
            if app.CheckGrid_civ2.Value% if the checkbox is activated
                filegrid=app.Grid_civ2.Value;
                [Data,~,~,errormsg]=nc2struct(filegrid);% try to open the displayed input file
                if ~isempty(errormsg) % browse for a grid file
                    hseries=findobj(allchild(0),'Tag','series');
                    hhseries=guidata(hseries);
                    InputTable=get(hhseries.InputTable,'Data');
                    filegrid= uigetfile_uvmat('pick a netcdf grid file :',InputTable{1,1},'.nc');
                    if ~isempty(filegrid)
                    [Data,~,~,errormsg]=nc2struct(filegrid);
                    end
                end
                if isempty(errormsg)
                    if isfield(Data,'Grid')
                        visible_grid='off';
                        visible_gridfile='on';
                        app.Grid_civ2.Value=filegrid;
                    else
                        msgbox_uvmat('ERROR', 'invalid grid file')
                        return
                    end
                    if isfield(Data,'CorrBoxSize')
                        visible_corr='off';
                    end
                end
            end
            app.Grid_civ2.Visible=visible_gridfile;
            app.num_Dx_civ2.Visible=visible_grid;
            app.num_Dy_civ2.Visible=visible_grid;
            app.title_Dx_civ2.Visible=visible_grid;
            app.title_Dy_civ2.Visible=visible_grid;
            app.num_CorrBoxSize_civ2_1.Visible=visible_corr;
            app.num_CorrBoxSize_civ2_2.Visible=visible_corr;
            app.title_CorrBox_civ2.Visible=visible_corr;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.civ_input = uifigure('Visible', 'off');
            app.civ_input.Name = 'civ_input';
            app.civ_input.WindowKeyPressFcn = createCallbackFcn(app, @civ_inputKeyPress, true);
            app.civ_input.KeyPressFcn = createCallbackFcn(app, @civ_inputKeyPress, true);
            app.civ_input.Tag = 'civ_input';
            app.MenuOpen = uimenu(app.civ_input);
            app.MenuOpen.Text = 'Open';
            app.MenuOpen.Tag = 'MenuOpen';
            app.MenuBrowse = uimenu(app.MenuOpen);
            app.MenuBrowse.Text = 'Browse';
            app.MenuBrowse.Tag = 'MenuBrowse';
            app.MenuFile_1 = uimenu(app.MenuOpen);
            app.MenuFile_1.Separator = 'on';
            app.MenuFile_1.Tag = 'MenuFile_1';
            app.MenuFile_2 = uimenu(app.MenuOpen);
            app.MenuFile_2.Tag = 'MenuFile_2';
            app.MenuFile_3 = uimenu(app.MenuOpen);
            app.MenuFile_3.Tag = 'MenuFile_3';
            app.MenuFile_4 = uimenu(app.MenuOpen);
            app.MenuFile_4.Tag = 'MenuFile_4';
            app.MenuFile_5 = uimenu(app.MenuOpen);
            app.MenuFile_5.Tag = 'MenuFile_5';
            app.MenuHelpHead = uimenu(app.civ_input);
            app.MenuHelpHead.Text = 'Help';
            app.MenuHelpHead.Tag = 'MenuHelpHead';
            app.MenuHelp = uimenu(app.MenuHelpHead);
            app.MenuHelp.MenuSelectedFcn = createCallbackFcn(app, @MenuHelp_Callback, true);
            app.MenuHelp.Text = 'Help';
            app.MenuHelp.Tag = 'MenuHelp';
            app.OK = uibutton(app.civ_input, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OK_Callback, true);
            app.OK.Tag = 'OK';
            app.OK.Tooltip = '''OK'': send parameters to the GUI series';
            app.OK.Text = 'OK';
            app.Patch2 = uipanel(app.civ_input);
            app.Patch2.Visible = 'off';
            app.Patch2.Tag = 'Patch2';
            app.num_SubDomainSize_2 = uieditfield(app.Patch2, 'text');
            app.num_SubDomainSize_2.Tag = 'num_SubDomainSize';
            app.num_SubDomainSize_2.Tooltip = '''num_SubDomainSize'': size of the subdomains used for interpolation (nb of vectors)';
            app.num_SubDomainSize_2.Value = '250';
            app.SubDomainSize_title_2 = uilabel(app.Patch2);
            app.SubDomainSize_title_2.Tag = 'subdomain_text2';
            app.SubDomainSize_title_2.Text = 'subdomain size';
            app.num_MaxDiff_2 = uieditfield(app.Patch2, 'text');
            app.num_MaxDiff_2.Tag = 'num_MaxDiff';
            app.num_MaxDiff_2.Tooltip = '''num_MaxDiff'': vectors are flaged as false if the difference with filtered value exceeds this value (in px)';
            app.num_MaxDiff_2.Value = '1';
            app.title_MaxDiff_2 = uilabel(app.Patch2);
            app.title_MaxDiff_2.Tag = 'title_MaxDiff';
            app.title_MaxDiff_2.Text = 'MaxDiff';
            app.num_FieldSmooth_2 = uieditfield(app.Patch2, 'text');
            app.num_FieldSmooth_2.Tag = 'num_FieldSmooth';
            app.num_FieldSmooth_2.Tooltip = '''num_FieldSmooth'': smoothing parameter (large values increase smoothing)';
            app.num_FieldSmooth_2.Value = '20';
            app.FieldSmooth_title_2 = uilabel(app.Patch2);
            app.FieldSmooth_title_2.Tag = 'FieldSmooth_txt';
            app.FieldSmooth_title_2.Text = 'FieldSmooth';
            app.Fix2 = uipanel(app.civ_input);
            app.Fix2.Visible = 'off';
            app.Fix2.Tag = 'Fix2';
            app.thresh_vel2_text = uilabel(app.Fix2);
            app.thresh_vel2_text.Tag = 'thresh_vel2_text';
            app.thresh_vel2_text.Text = '| vel | <';
            app.get_ref_fix2 = uicheckbox(app.Fix2);
            app.get_ref_fix2.ValueChangedFcn = createCallbackFcn(app, @get_ref_fix2_Callback, true);
            app.get_ref_fix2.Tag = 'get_ref_fix2';
            app.get_ref_fix2.Visible = 'off';
            app.get_ref_fix2.Tooltip = '''get_ref_fix2'': compare the velocity field to a reference field';
            app.get_ref_fix2.Text = 'GET_REF';
            app.ref_fix2 = uieditfield(app.Fix2, 'text');
            app.ref_fix2.Tag = 'ref_fix2';
            app.ref_fix2.Visible = 'off';
            app.ref_fix2.Tooltip = '''ref_fix2'': display the reference file name for fix';
            app.num_MaxVel = uieditfield(app.Fix2, 'text');
            app.num_MaxVel.Tag = 'num_MaxVel';
            app.num_MaxVel.Tooltip = '''num_MaxVel'': remove vel vectors  larger  than threshold';
            app.num_MinVel = uieditfield(app.Fix2, 'text');
            app.num_MinVel.Tag = 'num_MinVel';
            app.num_MinVel.Tooltip = '''num_MinVel'': removes vectors whose modulus is smaller than the threshold (in picxel displacement)';
            app.thresh_vec2C_text = uilabel(app.Fix2);
            app.thresh_vec2C_text.Tag = 'thresh_vec2C_text';
            app.thresh_vec2C_text.Text = 'vec_C <';
            app.num_MinCorr = uieditfield(app.Fix2, 'text');
            app.num_MinCorr.Tag = 'num_MinCorr';
            app.num_MinCorr.Tooltip = '''num_MinCorr'': removes vectors with image correlation value smaller than the threshold';
            app.num_MinCorr.Value = '0.2';
            app.title_MaxVel = uilabel(app.Fix2);
            app.title_MaxVel.Tag = 'title_MaxVel';
            app.title_MaxVel.Text = '| vel |>';
            app.field_ref2 = uidropdown(app.Fix2);
            app.field_ref2.Items = {};
            app.field_ref2.Tag = 'field_ref2';
            app.field_ref2.Visible = 'off';
            app.field_ref2.Tooltip = '''field_ref2'':field used for reference (civ1,filter1...)';
            app.field_ref2.Value = {};
            app.Civ2 = uipanel(app.civ_input);
            app.Civ2.Visible = 'off';
            app.Civ2.Tag = 'Civ2';
            app.num_CorrBoxSize_civ2_1 = uieditfield(app.Civ2, 'text');
            app.num_CorrBoxSize_civ2_1.Tag = 'num_CorrBoxSize_1';
            app.num_CorrBoxSize_civ2_1.Tooltip = '''num_CorrBoxSize_1'':size of the correlation box (in pixels) along the x direction';
            app.num_CorrBoxSize_civ2_1.Value = '21';
            app.num_CorrBoxSize_civ2_2 = uieditfield(app.Civ2, 'text');
            app.num_CorrBoxSize_civ2_2.Tag = 'num_CorrBoxSize_2';
            app.num_CorrBoxSize_civ2_2.Tooltip = '''num_CorrBoxSize_2'':size of the correlation box (in pixels) along the y direction';
            app.num_CorrBoxSize_civ2_2.Value = '21';
            app.num_CorrSmooth_civ2 = uidropdown(app.Civ2);
            app.num_CorrSmooth_civ2.Items = {'1', '2'};
            app.num_CorrSmooth_civ2.ValueChangedFcn = createCallbackFcn(app, @num_CorrSmooth_Callback, true);
            app.num_CorrSmooth_civ2.Tag = 'num_CorrSmooth';
            app.num_CorrSmooth_civ2.Tooltip = '''num_CorrSmooth'': smoothing parameter for image correlations ';
            app.num_CorrSmooth_civ2.Value = '1';
            app.title_CorrBox_civ2 = uilabel(app.Civ2);
            app.title_CorrBox_civ2.Tag = 'ibx_civ2_text';
            app.title_CorrBox_civ2.Text = 'CorrBox x, y';
            app.CheckDeformation = uicheckbox(app.Civ2);
            app.CheckDeformation.ValueChangedFcn = createCallbackFcn(app, @CheckDeformation_Callback, true);
            app.CheckDeformation.Tag = 'CheckDeformation';
            app.CheckDeformation.Tooltip = '''CheckDeformation'': introduces local image deformation by shear and rotation  in the correlation search';
            app.CheckDeformation.Text = 'deformation';
            app.title_rho_civ2 = uilabel(app.Civ2);
            app.title_rho_civ2.Tag = 'rho_civ2_title';
            app.title_rho_civ2.Tooltip = '''rho_civ2_title'': smoothing parameter for image correlations ';
            app.title_rho_civ2.Text = 'CorrSmooth';
            app.title_Dx_civ2 = uilabel(app.Civ2);
            app.title_Dx_civ2.Tag = 'title_Dx';
            app.title_Dx_civ2.Tooltip = '''title_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.title_Dx_civ2.Text = 'auto-grid Dx';
            app.title_Dy_civ2 = uilabel(app.Civ2);
            app.title_Dy_civ2.Tag = 'title_Dy';
            app.title_Dy_civ2.Tooltip = '''title_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.title_Dy_civ2.Text = 'Dy';
            app.num_Dx_civ2 = uieditfield(app.Civ2, 'text');
            app.num_Dx_civ2.Tag = 'num_Dx';
            app.num_Dx_civ2.Tooltip = '''num_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.num_Dx_civ2.Value = '10';
            app.num_Dy_civ2 = uieditfield(app.Civ2, 'text');
            app.num_Dy_civ2.Tag = 'num_Dy';
            app.num_Dy_civ2.Tooltip = 'num_Dy'': mesh along y (in pixel) for the grid of measureme';
            app.num_Dy_civ2.Value = '10';
            app.CheckGrid_civ2 = uicheckbox(app.Civ2);
            app.CheckGrid_civ2.ValueChangedFcn = createCallbackFcn(app, @CheckGrid_civ2ValueChanged, true);
            app.CheckGrid_civ2.Tag = 'CheckGrid';
            app.CheckGrid_civ2.Tooltip = '''CheckGrid'':''CheckGrid'': get custom grid file for civ2  (replaces Dx and Dy)';
            app.CheckGrid_civ2.Text = 'Grid File';
            app.Grid_civ2 = uieditfield(app.Civ2, 'text');
            app.Grid_civ2.Tag = 'Grid';
            app.Grid_civ2.Visible = 'off';
            app.Grid_civ2.Tooltip = '"Grid'': grid name for civ2';
            app.num_SearchRange_civ2_1 = uieditfield(app.Civ2, 'text');
            app.num_SearchRange_civ2_1.Tag = 'num_SearchRange_1';
            app.num_SearchRange_civ2_1.Tooltip = '''num_SearchRange_1'': search range around civ1 data (in pixels) along the x direction';
            app.num_SearchRange_civ2_1.Value = '3';
            app.num_SearchRange_civ2_2 = uieditfield(app.Civ2, 'text');
            app.num_SearchRange_civ2_2.Tag = 'num_SearchRange_2';
            app.num_SearchRange_civ2_2.Tooltip = '''num_SearchRange_2'': search range around civ1 data (in pixels) along the y direction';
            app.num_SearchRange_civ2_2.Value = '3';
            app.range_civ2_title = uilabel(app.Civ2);
            app.range_civ2_title.Tag = 'text94';
            app.range_civ2_title.Tooltip = '''is_title'':size of the search range (in pixels) along each direction';
            app.range_civ2_title.Text = 'Range x, y';
            app.Patch1 = uipanel(app.civ_input);
            app.Patch1.Visible = 'off';
            app.Patch1.Tag = 'Patch1';
            app.num_SubDomainSize_1 = uieditfield(app.Patch1, 'text');
            app.num_SubDomainSize_1.Tag = 'num_SubDomainSize_1';
            app.num_SubDomainSize_1.Tooltip = '''num_SubDomainSize'': size of the subdomains used for interpolation (nb of vectors)';
            app.num_SubDomainSize_1.Value = '125';
            app.SubDomainSize_title_1 = uilabel(app.Patch1);
            app.SubDomainSize_title_1.Tag = 'text72';
            app.SubDomainSize_title_1.Text = 'subdomain size';
            app.num_MaxDiff_1 = uieditfield(app.Patch1, 'text');
            app.num_MaxDiff_1.Tag = 'num_MaxDiff';
            app.num_MaxDiff_1.Tooltip = '''num_MaxDiff'': vectors are flaged as false if the difference with filtered value exceeds this value (in px)';
            app.num_MaxDiff_1.Value = '1.5';
            app.MaxDiff_title_1 = uilabel(app.Patch1);
            app.MaxDiff_title_1.Tag = 'title_MaxDiff_1';
            app.MaxDiff_title_1.Text = 'MaxDiff';
            app.num_FieldSmooth_1 = uieditfield(app.Patch1, 'text');
            app.num_FieldSmooth_1.Tag = 'num_FieldSmooth';
            app.num_FieldSmooth_1.Tooltip = '''num_FieldSmooth'': smoothing parameter (large values increase smoothing)';
            app.num_FieldSmooth_1.Value = '200';
            app.FieldSmooth_title_1 = uilabel(app.Patch1);
            app.FieldSmooth_title_1.Tag = 'text75';
            app.FieldSmooth_title_1.Text = 'FieldSmooth';
            app.Fix1 = uipanel(app.civ_input);
            app.Fix1.Visible = 'off';
            app.Fix1.Tag = 'Fix1';
            app.TitleMinVel = uilabel(app.Fix1);
            app.TitleMinVel.Tag = 'TitleMinVel';
            app.TitleMinVel.Text = {'| vel | <'; ''};
            app.get_ref_fix1 = uicheckbox(app.Fix1);
            app.get_ref_fix1.ValueChangedFcn = createCallbackFcn(app, @get_ref_fix1_Callback, true);
            app.get_ref_fix1.Tag = 'get_ref_fix1';
            app.get_ref_fix1.Visible = 'off';
            app.get_ref_fix1.Tooltip = '''get_ref_fix1'': compares the velocity field to a reference';
            app.get_ref_fix1.Text = 'GET_REF';
            app.ref_fix1 = uieditfield(app.Fix1, 'text');
            app.ref_fix1.ValueChangedFcn = createCallbackFcn(app, @ref_fix1_Callback, true);
            app.ref_fix1.Tag = 'ref_fix1';
            app.ref_fix1.Visible = 'off';
            app.ref_fix1.Tooltip = '''ref_fix1'': display the reference file name for fix';
            app.num_MaxVel_1 = uieditfield(app.Fix1, 'text');
            app.num_MaxVel_1.Tag = 'num_MaxVel_1';
            app.num_MaxVel_1.Tooltip = '''num_MaxVel'': remove vel vectors  larger  than this  threshold (in pixel displacement)';
            app.num_MinVel_1 = uieditfield(app.Fix1, 'text');
            app.num_MinVel_1.Tag = 'num_MinVel_1';
            app.num_MinVel_1.Tooltip = '''num_MinVel'': removes vectors whose modulus is smaller than the threshold (in picxel displacement)';
            app.thresh_vecC_title = uilabel(app.Fix1);
            app.thresh_vecC_title.Tag = 'thresh_vecC_title';
            app.thresh_vecC_title.Tooltip = '''thresh_vecC_title'': removes vectors with image correlation value smaller than the threshold';
            app.thresh_vecC_title.Text = 'vec_C <';
            app.num_MinCorr_1 = uieditfield(app.Fix1, 'text');
            app.num_MinCorr_1.Tag = 'num_MinCorr_1';
            app.num_MinCorr_1.Tooltip = '''num_MinCorr'': removes vectors with image correlation value smaller than the threshold';
            app.num_MinCorr_1.Value = '0.2';
            app.title_MaxVel_1 = uilabel(app.Fix1);
            app.title_MaxVel_1.Tag = 'title_MaxVel_1';
            app.title_MaxVel_1.Text = '| vel | >';
            app.field_ref1 = uidropdown(app.Fix1);
            app.field_ref1.Items = {};
            app.field_ref1.Tag = 'field_ref1';
            app.field_ref1.Visible = 'off';
            app.field_ref1.Tooltip = '''field_ref1'':field used for reference (civ1,filter1...)''';
            app.field_ref1.Value = {};
            app.Civ1 = uipanel(app.civ_input);
            app.Civ1.Visible = 'off';
            app.Civ1.Tag = 'Civ1';
            app.title_Dx = uilabel(app.Civ1);
            app.title_Dx.Tag = 'title_Dx_1';
            app.title_Dx.Tooltip = '''title_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.title_Dx.Text = 'auto-grid Dx';
            app.title_Dy = uilabel(app.Civ1);
            app.title_Dy.Tag = 'title_Dy_1';
            app.title_Dy.Tooltip = '''title_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.title_Dy.Text = 'Dy';
            app.num_CorrBoxSize_2 = uieditfield(app.Civ1, 'text');
            app.num_CorrBoxSize_2.Tag = 'num_CorrBoxSize_2';
            app.num_CorrBoxSize_2.Tooltip = '''num_CorrBoxSize_2'':size of the correlation box (in pixels) along the y direction';
            app.num_CorrBoxSize_2.Value = '31';
            app.num_CorrBoxSize_1 = uieditfield(app.Civ1, 'text');
            app.num_CorrBoxSize_1.Tag = 'num_CorrBoxSize_1';
            app.num_CorrBoxSize_1.Tooltip = '''num_CorrBoxSize_1'':size of the correlation box (in pixels) along the x direction''';
            app.num_CorrBoxSize_1.Value = '31';
            app.num_SearchRange_2 = uieditfield(app.Civ1, 'text');
            app.num_SearchRange_2.Tag = 'num_SearchRange_2';
            app.num_SearchRange_2.Tooltip = '''num_SearchRange_2'':  search range (in pixels) along the y direction';
            app.num_SearchRange_2.Value = '15';
            app.num_SearchRange_1 = uieditfield(app.Civ1, 'text');
            app.num_SearchRange_1.Tag = 'num_SearchRange_1';
            app.num_SearchRange_1.Tooltip = '''num_SearchRange_1'': search range (in pixels) along the x direction''';
            app.num_SearchRange_1.Value = '15';
            app.num_SearchBoxShift_2 = uieditfield(app.Civ1, 'text');
            app.num_SearchBoxShift_2.Tag = 'num_SearchBoxShift_2';
            app.num_SearchBoxShift_2.Tooltip = '''num_SearchBoxShift_2'': systematic shift (in pixels) of the search box (take into account a mean flow)';
            app.num_SearchBoxShift_2.Value = '0';
            app.num_SearchBoxShift_1 = uieditfield(app.Civ1, 'text');
            app.num_SearchBoxShift_1.Tag = 'num_SearchBoxShift_1';
            app.num_SearchBoxShift_1.Tooltip = '''num_SearchBoxShift_1'': systematic shift (in pixels) of the search box (take into account a mean flow)';
            app.num_SearchBoxShift_1.Value = '0';
            app.num_CorrSmooth = uidropdown(app.Civ1);
            app.num_CorrSmooth.Items = {'1', '2'};
            app.num_CorrSmooth.ValueChangedFcn = createCallbackFcn(app, @num_CorrSmooth_Callback, true);
            app.num_CorrSmooth.Tag = 'num_CorrSmooth_1';
            app.num_CorrSmooth.Tooltip = '''num_CorrSmooth'': smoothing parameter for image correlations ';
            app.num_CorrSmooth.Value = '1';
            app.num_Dx = uieditfield(app.Civ1, 'text');
            app.num_Dx.Tag = 'num_Dx';
            app.num_Dx.Tooltip = '''Num_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.num_Dx.Value = '20';
            app.num_Dy = uieditfield(app.Civ1, 'text');
            app.num_Dy.Tag = 'num_Dy';
            app.num_Dy.Tooltip = '''num_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.num_Dy.Value = '20';
            app.CheckGrid = uicheckbox(app.Civ1);
            app.CheckGrid.ValueChangedFcn = createCallbackFcn(app, @CheckGrid_Callback, true);
            app.CheckGrid.Tag = 'CheckGrid';
            app.CheckGrid.Tooltip = '''CheckGrid'': get custom grid file for civ1  (replaces Dx and Dy)';
            app.CheckGrid.Text = 'Grid File';
            app.Grid = uieditfield(app.Civ1, 'text');
            app.Grid.Tag = 'Grid';
            app.Grid.Visible = 'off';
            app.Grid.Tooltip = '''Grid'': custom grid file for civ1  (replaces dx and dy)''';
            app.title_CorrBox = uilabel(app.Civ1);
            app.title_CorrBox.Tag = 'ib_title';
            app.title_CorrBox.Tooltip = '''ib_title'':size of the correlation box (in pixels) along each direction';
            app.title_CorrBox.Text = 'CorrBox x, y';
            app.title_range = uilabel(app.Civ1);
            app.title_range.Tag = 'is_title';
            app.title_range.Tooltip = '''is_title'':size of the search range (in pixels) along each direction';
            app.title_range.Text = 'Range x, y';
            app.title_shift = uilabel(app.Civ1);
            app.title_shift.Tag = 'shift_title';
            app.title_shift.Tooltip = '''shift_title'': systematic shift (in pixels) for search box (take into account a mean flow)';
            app.title_shift.Text = 'Shift x, y';
            app.title_CorrSmooth = uilabel(app.Civ1);
            app.title_CorrSmooth.Tag = 'rho_title';
            app.title_CorrSmooth.Tooltip = '''rho_title'': smoothing parameter for image correlations ';
            app.title_CorrSmooth.Text = 'CorrSmooth';
            app.TestCiv1 = uibutton(app.Civ1, 'state');
            app.TestCiv1.ValueChangedFcn = createCallbackFcn(app, @TestCiv1_Callback, true);
            app.TestCiv1.Tag = 'TestCiv1';
            app.TestCiv1.Tooltip = '''TestCiv1'' show the image correlation';
            app.TestCiv1.Text = 'TEST';
            app.num_CorrBoxSize_3 = uieditfield(app.Civ1, 'text');
            app.num_CorrBoxSize_3.Tag = 'num_CorrBoxSize_3';
            app.num_CorrBoxSize_3.Enable = 'off';
            app.num_CorrBoxSize_3.Visible = 'off';
            app.num_CorrBoxSize_3.Tooltip = '''num_CorrBoxSize_3'':size of the correlation box (in pixels) along the y direction';
            app.num_CorrBoxSize_3.Value = '1';
            app.num_SearchRange_3 = uieditfield(app.Civ1, 'text');
            app.num_SearchRange_3.Tag = 'num_SearchRange_3';
            app.num_SearchRange_3.Visible = 'off';
            app.num_SearchRange_3.Tooltip = '''num_SearchRange_3'': size of the search box (in pixels) along the y direction';
            app.num_SearchRange_3.Value = '2';
            app.num_SearchBoxShift_3 = uieditfield(app.Civ1, 'text');
            app.num_SearchBoxShift_3.Tag = 'num_SearchBoxShift_3';
            app.num_SearchBoxShift_3.Visible = 'off';
            app.num_SearchBoxShift_3.Tooltip = '''num_SearchBoxShift_2'': systematic shift (in pixels) of the search box (take into account a mean flow)';
            app.num_SearchBoxShift_3.Value = '0';
            app.num_Dz = uieditfield(app.Civ1, 'text');
            app.num_Dz.Tag = 'num_Dz';
            app.num_Dz.Visible = 'off';
            app.num_Dz.Tooltip = '''num_Dz'': mesh along z (in frame) for the grid of measurement points';
            app.num_Dz.Value = '2';
            app.CheckPatch2 = uicheckbox(app.civ_input);
            app.CheckPatch2.ValueChangedFcn = createCallbackFcn(app, @CheckPatch2_Callback, true);
            app.CheckPatch2.Tag = 'CheckPatch2';
            app.CheckPatch2.Tooltip = '''CheckPatch2'': interpolates and smooths the civ2 velocity field';
            app.CheckPatch2.Text = 'PATCH2';
            app.CheckFix2 = uicheckbox(app.civ_input);
            app.CheckFix2.ValueChangedFcn = createCallbackFcn(app, @CheckFix2_Callback, true);
            app.CheckFix2.Tag = 'CheckFix2';
            app.CheckFix2.Tooltip = '''CheckFix2'': removes (flag)  false civ2 vectors with selected criteria';
            app.CheckFix2.Text = 'FIX2';
            app.CheckCiv2 = uicheckbox(app.civ_input);
            app.CheckCiv2.ValueChangedFcn = createCallbackFcn(app, @CheckCiv2_Callback, true);
            app.CheckCiv2.Tag = 'CheckCiv2';
            app.CheckCiv2.Tooltip = '''CheckCiv2'': select first PIV processing';
            app.CheckCiv2.Text = 'CIV2';
            app.CheckPatch1 = uicheckbox(app.civ_input);
            app.CheckPatch1.ValueChangedFcn = createCallbackFcn(app, @CheckPatch1_Callback, true);
            app.CheckPatch1.Tag = 'CheckPatch1';
            app.CheckPatch1.Tooltip = '''CheckPatch1'': interpolates and smooths the velocity field';
            app.CheckPatch1.Text = 'PATCH1';
            app.CheckFix1 = uicheckbox(app.civ_input);
            app.CheckFix1.ValueChangedFcn = createCallbackFcn(app, @CheckFix1_Callback, true);
            app.CheckFix1.Tag = 'CheckFix1';
            app.CheckFix1.Tooltip = '''CheckFix1'': flags false vectors with selected criteria';
            app.CheckFix1.Text = 'FIX1';
            app.CheckCiv1 = uicheckbox(app.civ_input);
            app.CheckCiv1.ValueChangedFcn = createCallbackFcn(app, @CheckCiv1_Callback, true);
            app.CheckCiv1.Tag = 'CheckCiv1';
            app.CheckCiv1.Tooltip = '''CheckCiv1'': select first PIV processing';
            app.CheckCiv1.Text = 'CIV1';
            app.PairIndices = uipanel(app.civ_input);
            app.PairIndices.Title = 'Pair Indices';
            app.PairIndices.Visible = 'off';
            app.PairIndices.Tag = 'PairIndices';
            app.TitlePairCiv2 = uilabel(app.PairIndices);
            app.TitlePairCiv2.Tag = 'TitlePairCiv2';
            app.TitlePairCiv2.Visible = 'off';
            app.TitlePairCiv2.Text = 'Civ2';
            app.TitlePairCiv1 = uilabel(app.PairIndices);
            app.TitlePairCiv1.Tag = 'TitlePairCiv1';
            app.TitlePairCiv1.Text = 'Civ1';
            app.ListPairCiv1 = uilistbox(app.PairIndices);
            app.ListPairCiv1.Items = {};
            app.ListPairCiv1.ValueChangedFcn = createCallbackFcn(app, @ListPairCiv1_Callback, true);
            app.ListPairCiv1.Tag = 'ListPairCiv1';
            app.ListPairCiv1.Tooltip = '''ListPairCiv1'': menu for selecting the image pair';
            app.ListPairCiv1.Value = {};
            app.ListPairCiv2 = uilistbox(app.PairIndices);
            app.ListPairCiv2.Items = {};
            app.ListPairCiv2.Tag = 'ListPairCiv2';
            app.ListPairCiv2.Visible = 'off';
            app.ListPairCiv2.Tooltip = '''ListPairCiv2'': menu for selecting the image pair for civ2';
            app.ListPairCiv2.Value = {};
            app.dt_unit = uieditfield(app.PairIndices, 'text');
            app.dt_unit.Tag = 'dt_unit';
            app.dt_unit.Tooltip = '''dt_unit'': unit for dt display';
            app.TimeSource = uieditfield(app.PairIndices, 'text');
            app.TimeSource.Tag = 'TimeSource';
            app.TimeSource.Enable = 'off';
            app.TimeSource.Tooltip = '''TimeSource:'' source of time info ';
            app.TimeSource.Value = 'ImaDoc';
            app.text78 = uilabel(app.PairIndices);
            app.text78.Tag = 'text78';
            app.text78.Text = 'dt ';
            app.ListPairMode = uidropdown(app.PairIndices);
            app.ListPairMode.Items = {};
            app.ListPairMode.ValueChangedFcn = createCallbackFcn(app, @ListPairMode_Callback, true);
            app.ListPairMode.Tag = 'ListPairMode';
            app.ListPairMode.Tooltip = '''ListPairMode'': choice of the index pair organisation; burst, pairs on i or j index.';
            app.ListPairMode.Value = {};
            app.MinIndex_i = uieditfield(app.PairIndices, 'text');
            app.MinIndex_i.Tag = 'MinIndex_i';
            app.MinIndex_i.Enable = 'off';
            app.MinIndex_i.Tooltip = '''MinIndex_i'': first image index i of the series detected in the directory';
            app.MinIndex_i.Value = '1';
            app.MinIndex_j = uieditfield(app.PairIndices, 'text');
            app.MinIndex_j.Tag = 'MinIndex_j';
            app.MinIndex_j.Enable = 'off';
            app.MinIndex_j.Tooltip = '''MinIndex_j'': first reference index j of the series detected in the directory';
            app.MinIndex_j.Value = '1';
            app.first_title = uilabel(app.PairIndices);
            app.first_title.Tag = 'first_title';
            app.first_title.Tooltip = '''first_title'': first index of the file series selected for processing';
            app.first_title.Text = 'min';
            app.itext = uilabel(app.PairIndices);
            app.itext.Tag = 'itext';
            app.itext.Text = '# i';
            app.jtext = uilabel(app.PairIndices);
            app.jtext.Tag = 'jtext';
            app.jtext.Text = '# j';
            app.MaxIndex_i = uieditfield(app.PairIndices, 'text');
            app.MaxIndex_i.Tag = 'MaxIndex_i';
            app.MaxIndex_i.Enable = 'off';
            app.MaxIndex_i.Tooltip = '''MaxIndex_i'': last ref image index i detected in the directory ';
            app.MaxIndex_i.Value = '1';
            app.MaxIndex_j = uieditfield(app.PairIndices, 'text');
            app.MaxIndex_j.Tag = 'MaxIndex_j';
            app.MaxIndex_j.Enable = 'off';
            app.MaxIndex_j.Tooltip = '''MaxIndex_j'': last image index j detected in the directory ';
            app.MaxIndex_j.Value = '1';
            app.nb_field_title = uilabel(app.PairIndices);
            app.nb_field_title.Tag = 'nb_field_title';
            app.nb_field_title.Tooltip = '''nb_field_title'': last image index detected in the directory (or indicated by the .xml file)';
            app.nb_field_title.Text = 'max';
            app.ref_i = uieditfield(app.PairIndices, 'text');
            app.ref_i.ValueChangedFcn = createCallbackFcn(app, @ref_i_Callback, true);
            app.ref_i.Tag = 'ref_i';
            app.ref_i.Tooltip = '''ref_i'': reference field index i for dt display';
            app.ref_i.Value = '1';
            app.ref_j = uieditfield(app.PairIndices, 'text');
            app.ref_j.ValueChangedFcn = createCallbackFcn(app, @ref_i_Callback, true);
            app.ref_j.Tag = 'ref_j';
            app.ref_j.Visible = 'off';
            app.ref_j.Tooltip = '''ref_j'': reference field index j for dt display';
            app.ref_j.Value = '1';
            app.text91 = uilabel(app.PairIndices);
            app.text91.Tag = 'text91';
            app.text91.Text = 'ref index';
            app.TimeUnit = uieditfield(app.PairIndices, 'text');
            app.TimeUnit.Tag = 'TimeUnit';
            app.TimeUnit.Enable = 'off';
            app.TimeUnit.Tooltip = '''TimeUnit'': ';
            app.TimeUnit.Value = '1';
            app.num_OriginIndex = uieditfield(app.PairIndices, 'text');
            app.num_OriginIndex.Tag = 'num_OriginIndex';
            app.num_OriginIndex.Visible = 'off';
            app.num_OriginIndex.Tooltip = '''num_OriginIndex'': (i) file index for the displacement origin';
            app.num_OriginIndex.Value = '1';
            app.OriginIndex_title = uilabel(app.PairIndices);
            app.OriginIndex_title.Tag = 'OriginIndex_title';
            app.OriginIndex_title.Visible = 'off';
            app.OriginIndex_title.Text = 'index';
            app.CheckRefFile = uibutton(app.PairIndices, 'push');
            app.CheckRefFile.ButtonPushedFcn = createCallbackFcn(app, @CheckRefFile_Callback, true);
            app.CheckRefFile.Tag = 'CheckRefFile';
            app.CheckRefFile.Tooltip = '''CheckRefFile'': use ref file for displacement';
            app.CheckRefFile.Text = 'Ref. file';
            app.RefFile = uieditfield(app.PairIndices, 'text');
            app.RefFile.Tag = 'RefFile';
            app.RefFile.Enable = 'off';
            app.RefFile.Visible = 'off';
            app.RefFile.Tooltip = '''RefFile'': file used as reference for the displacement';
            app.num_Maxtanh = uieditfield(app.civ_input, 'text');
            app.num_Maxtanh.ValueChangedFcn = createCallbackFcn(app, @num_Maxtanh_Callback, true);
            app.num_Maxtanh.Tag = 'num_Maxtanh';
            app.num_Maxtanh.Visible = 'off';
            app.num_Maxtanh.Tooltip = '''num_Maxtanh'': threshold for the tanh image intensity rescaling';
            app.Maxtanh_title = uilabel(app.civ_input);
            app.Maxtanh_title.Tag = 'title_Maxtanh';
            app.Maxtanh_title.Visible = 'off';
            app.Maxtanh_title.Text = 'threshold';
            app.CheckRescale = uicheckbox(app.civ_input);
            app.CheckRescale.ValueChangedFcn = createCallbackFcn(app, @CheckRescale_Callback, true);
            app.CheckRescale.Tag = 'CheckRescale';
            app.CheckRescale.Tooltip = '''CheckRescale'': rescale the image luminosity I by threshold*tanh(I/threshold)';
            app.CheckRescale.Text = 'Rescale tanh';
            app.num_MaxIma = uieditfield(app.civ_input, 'text');
            app.num_MaxIma.ValueChangedFcn = createCallbackFcn(app, @num_MaxIma_Callback, true);
            app.num_MaxIma.Tag = 'num_MaxIma';
            app.num_MaxIma.Visible = 'off';
            app.num_MaxIma.Tooltip = '''num_MaxIma'': higher luminosity threshold for PIV processing';
            app.Threshold_title = uilabel(app.civ_input);
            app.Threshold_title.Tag = 'title_Threshold_1';
            app.Threshold_title.Visible = 'off';
            app.Threshold_title.Text = '< image <';
            app.num_MinIma = uieditfield(app.civ_input, 'text');
            app.num_MinIma.Tag = 'num_MinIma';
            app.num_MinIma.Visible = 'off';
            app.num_MinIma.Tooltip = '''num_MinIma'': lower luminosity threshold for PIV processing';
            app.num_MinIma.Value = '0';
            app.CheckThreshold = uicheckbox(app.civ_input);
            app.CheckThreshold.ValueChangedFcn = createCallbackFcn(app, @CheckThreshold_Callback, true);
            app.CheckThreshold.Tag = 'CheckThreshold';
            app.CheckThreshold.Tooltip = '''CheckThreshold'': set thresholds on image intensity''';
            app.CheckThreshold.Text = 'Threshold';
            app.num_BkgndPeriod = uieditfield(app.civ_input, 'text');
            app.num_BkgndPeriod.Tag = 'num_BkgndPeriod';
            app.num_BkgndPeriod.Visible = 'off';
            app.num_BkgndPeriod.Tooltip = '''num_BkgndPeriod'': index i period for background determination';
            app.Background = uieditfield(app.civ_input, 'text');
            app.Background.Tag = 'Background';
            app.Background.Visible = 'off';
            app.Background.Tooltip = '''Background'': display background image filename';
            app.CheckBackground = uicheckbox(app.civ_input);
            app.CheckBackground.ValueChangedFcn = createCallbackFcn(app, @CheckBackground_Callback, true);
            app.CheckBackground.Tag = 'CheckBackground';
            app.CheckBackground.Tooltip = '''CheckBackground'': subtract a background image before PIV''';
            app.CheckBackground.Text = 'Background';
            app.num_NbSlice = uieditfield(app.civ_input, 'text');
            app.num_NbSlice.Tag = 'num_NbSlice';
            app.num_NbSlice.Visible = 'off';
            app.num_NbSlice.Tooltip = '''num_NbSlice'': number of slices for the mask';
            app.Mask = uieditfield(app.civ_input, 'text');
            app.Mask.Tag = 'Mask';
            app.Mask.Visible = 'off';
            app.Mask.Tooltip = '''Mask'': display a selected mask series';
            app.CheckMask = uicheckbox(app.civ_input);
            app.CheckMask.ValueChangedFcn = createCallbackFcn(app, @CheckMask_Callback, true);
            app.CheckMask.Tag = 'CheckMask';
            app.CheckMask.Tooltip = '''CheckMask'': use mask file for civ1, or creates it if it does not exist';
            app.CheckMask.Text = 'Mask';
            app.ListCompareMode = uidropdown(app.civ_input);
            app.ListCompareMode.Items = {'PIV', 'displacement', ''};
            app.ListCompareMode.ValueChangedFcn = createCallbackFcn(app, @ListCompareMode_Callback, true);
            app.ListCompareMode.Tag = 'ListCompareMode';
            app.ListCompareMode.Visible = 'off';
            app.ListCompareMode.Tooltip = '''ListCompareMode'' introduce a second image series for stereo view or stereo 3C PIV';
            app.ListCompareMode.Value = 'PIV';
            app.ConfigSource = uieditfield(app.civ_input, 'text');
            app.ConfigSource.Tag = 'ConfigSource';
            app.ConfigSource.Enable = 'off';
            app.ConfigSource.Tooltip = '''ConfigSource'': source of the current parameters';
            app.ImportParam = uibutton(app.civ_input, 'push');
            app.ImportParam.ButtonPushedFcn = createCallbackFcn(app, @ImportParam_Callback, true);
            app.ImportParam.Tag = 'ImportParam';
            app.ImportParam.Tooltip = '''ImportParam'' browse   xml file (in a folder 0_XML) to import civ parameters ';
            app.ImportParam.Text = 'IMPORT';
            app.SetDefaultParam = uibutton(app.civ_input, 'push');
            app.SetDefaultParam.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultParam_Callback, true);
            app.SetDefaultParam.Tag = 'SetDefaultParam';
            app.SetDefaultParam.Tooltip = '''SetDefaultParam'': press to revert to default civ parameters';
            app.SetDefaultParam.Text = 'DEFAULT';
            app.source_config_title = uilabel(app.civ_input);
            app.source_config_title.Tag = 'text93';
            app.source_config_title.Text = 'source config:';
            % Show the figure after all components are created
            app.civ_input.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = civ_input_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.civ_input)

                % Execute the startup function
                runStartupFcn(app, @(app)civ_input_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.civ_input)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.civ_input)
        end
    end
end