classdef stereo_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        stereo_input_fig          matlab.ui.Figure
        source_config_title       matlab.ui.control.Label
        SetDefaultParam           matlab.ui.control.Button
        ImportParam               matlab.ui.control.Button
        ConfigSource              matlab.ui.control.EditField
        CheckBackground           matlab.ui.control.CheckBox
        Threshold_title           matlab.ui.control.Label
        num_MinIma                matlab.ui.control.EditField
        CheckThreshold            matlab.ui.control.CheckBox
        num_MaxIma                matlab.ui.control.EditField
        CheckRescale              matlab.ui.control.CheckBox
        threshold_title           matlab.ui.control.Label
        num_Maxtanh               matlab.ui.control.EditField
        num_BkgndPeriod           matlab.ui.control.EditField
        Background_B              matlab.ui.control.EditField
        Background_A              matlab.ui.control.EditField
        num_NbSlice               matlab.ui.control.EditField
        Mask_B                    matlab.ui.control.EditField
        Mask_A                    matlab.ui.control.EditField
        CheckMask                 matlab.ui.control.CheckBox
        CheckTest                 matlab.ui.control.CheckBox
        CheckZField               matlab.ui.control.CheckBox
        resolution_factor_title   matlab.ui.control.Label
        num_resolution            matlab.ui.control.EditField
        CheckCiv1                 matlab.ui.control.CheckBox
        CheckFix1                 matlab.ui.control.CheckBox
        CheckPatch1               matlab.ui.control.CheckBox
        CheckCiv2                 matlab.ui.control.CheckBox
        CheckFix2                 matlab.ui.control.CheckBox
        CheckPatch2               matlab.ui.control.CheckBox
        CheckCiv3                 matlab.ui.control.CheckBox
        CheckFix3                 matlab.ui.control.CheckBox
        CheckPatch3               matlab.ui.control.CheckBox
        OK                        matlab.ui.control.Button
        Civ1                      matlab.ui.container.Panel
        rho_title                 matlab.ui.control.Label
        shift_title               matlab.ui.control.Label
        is_title                  matlab.ui.control.Label
        ib_title                  matlab.ui.control.Label
        parameters                matlab.ui.control.Label
        num_CorrBoxSize_civ1_1    matlab.ui.control.EditField
        num_CorrBoxSize_civ1_2    matlab.ui.control.EditField
        num_SearchRange_civ1_1    matlab.ui.control.EditField
        num_SearchRange_civ1_2    matlab.ui.control.EditField
        num_SearchBoxShift_1      matlab.ui.control.EditField
        num_SearchBoxShift_2      matlab.ui.control.EditField
        num_CorrSmooth_civ1       matlab.ui.control.EditField
        title_Dx_civ1             matlab.ui.control.Label
        num_Dx_civ1               matlab.ui.control.EditField
        title_Dy_civ1             matlab.ui.control.Label
        num_Dy_civ1               matlab.ui.control.EditField
        TestCiv1                  matlab.ui.control.StateButton
        Fix1                      matlab.ui.container.Panel
        field_ref1                matlab.ui.control.DropDown
        title_MaxVel_fix1         matlab.ui.control.Label
        num_MinCorr_fix1          matlab.ui.control.EditField
        thresh_vecC_title         matlab.ui.control.Label
        num_MinVel_fix1           matlab.ui.control.EditField
        num_MaxVel_fix1           matlab.ui.control.EditField
        ref_fix1                  matlab.ui.control.EditField
        get_ref_fix1              matlab.ui.control.CheckBox
        TitleMinVel               matlab.ui.control.Label
        Patch1                    matlab.ui.container.Panel
        title_MaxDiff_patch1      matlab.ui.control.Label
        text75                    matlab.ui.control.Label
        text72                    matlab.ui.control.Label
        num_SubDomainSize_patch1  matlab.ui.control.EditField
        num_MaxDiff_patch1        matlab.ui.control.EditField
        num_FieldSmooth_patch1    matlab.ui.control.EditField
        Civ2                      matlab.ui.container.Panel
        text94                    matlab.ui.control.Label
        title_Dy                  matlab.ui.control.Label
        title_Dx                  matlab.ui.control.Label
        rho_civ2_title            matlab.ui.control.Label
        CheckDeformation          matlab.ui.control.CheckBox
        ibx_civ2_text             matlab.ui.control.Label
        num_CorrBoxSize_civ2_1    matlab.ui.control.EditField
        num_CorrBoxSize_civ2_2    matlab.ui.control.EditField
        num_SearchRange_civ2_1    matlab.ui.control.EditField
        num_SearchRange_civ2_2    matlab.ui.control.EditField
        num_CorrSmooth_civ2       matlab.ui.control.EditField
        num_Dx_civ2               matlab.ui.control.EditField
        num_Dy_civ2               matlab.ui.control.EditField
        parameters_text           matlab.ui.control.Label
        Fix2                      matlab.ui.container.Panel
        field_ref2                matlab.ui.control.DropDown
        title_MaxVel              matlab.ui.control.Label
        num_MinCorr               matlab.ui.control.EditField
        thresh_vec2C_text         matlab.ui.control.Label
        num_MinVel                matlab.ui.control.EditField
        num_MaxVel                matlab.ui.control.EditField
        ref_fix2                  matlab.ui.control.EditField
        get_ref_fix2              matlab.ui.control.CheckBox
        thresh_vel2_text          matlab.ui.control.Label
        Patch2                    matlab.ui.container.Panel
        MaxDiff_patch2_title      matlab.ui.control.Label
        FieldSmooth_patch2_title  matlab.ui.control.Label
        num_SubDomainSize_patch2  matlab.ui.control.EditField
        num_MaxDiff_patch2        matlab.ui.control.EditField
        num_FieldSmooth_patch2    matlab.ui.control.EditField
        subdomain_patch2_title    matlab.ui.control.Label
        Civ3                      matlab.ui.container.Panel
        CheckDeformation_civ3     matlab.ui.control.CheckBox
        text103                   matlab.ui.control.Label
        text104                   matlab.ui.control.Label
        text108                   matlab.ui.control.Label
        num_CorrBoxSize_civ3_1    matlab.ui.control.EditField
        num_CorrBoxSize_civ3_2    matlab.ui.control.EditField
        num_SearchRange_civ3_1    matlab.ui.control.EditField
        num_SearchRange_civ3_2    matlab.ui.control.EditField
        text105                   matlab.ui.control.Label
        num_CorrSmooth_civ3       matlab.ui.control.EditField
        text106                   matlab.ui.control.Label
        num_Dx_civ3               matlab.ui.control.EditField
        text107                   matlab.ui.control.Label
        num_Dy_civ3               matlab.ui.control.EditField
        Fix3                      matlab.ui.container.Panel
        num_MinCorr_civ3          matlab.ui.control.EditField
        text110                   matlab.ui.control.Label
        field_ref_civ3            matlab.ui.control.DropDown
        text111                   matlab.ui.control.Label
        num_MinVel_civ3           matlab.ui.control.EditField
        num_MaxVel_civ3           matlab.ui.control.EditField
        ref_fix_civ3              matlab.ui.control.EditField
        get_ref_fix_civ3          matlab.ui.control.CheckBox
        text109                   matlab.ui.control.Label
        Patch3                    matlab.ui.container.Panel
        text115                   matlab.ui.control.Label
        num_FieldSmooth_patch3    matlab.ui.control.EditField
        text116                   matlab.ui.control.Label
        num_SubDomainSize_patch3  matlab.ui.control.EditField
        num_MaxDiff_patch3        matlab.ui.control.EditField
        text112                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        % function CheckStereo_Callback(app, hObject, eventdata, handles)
        %     %------------------------------------------------------------------------
        %     % --- TO ABANDON Executes on button press in test_stereo1.
        % 
        %     %------------------------------------------------------------------------
        %     hparent=get(hObject,'parent');
        %     parent_tag=get(hparent,'Tag');
        %     hchildren=get(hparent,'children');
        %     handle_txtbox=findobj(hchildren,'tag','txt_Mask');
        %     if isequal(get(hObject,'Value'),0)
        %         set(handles.num_SubDomainSize,'Visible','on')
        %         set(handles.num_FieldSmooth,'Visible','on')
        %     else
        %         set(handles.num_SubDomainSize,'Visible','off')
        %         set(handles.num_FieldSmooth,'Visible','off')
        %     end
        % end
        
        
        % function SearchRange_Callback(app, hObject, eventdata, handles)
        %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %     % Callbacks in the uipanel Civ1
        %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %     %------------------------------------------------------------------------
        %     % --- Executes on button press in SearchRange: determine the search range num_SearchRange_1,num_SearchRange_2
        % 
        %     %------------------------------------------------------------------------
        %     %determine pair numbers
        %     if strcmp(get(handles.num_UMin,'Visible'),'off')
        %         set(handles.u_title,'Visible','on')
        %         set(handles.v_title,'Visible','on')
        %         set(handles.num_UMin,'Visible','on')
        %         set(handles.num_UMax,'Visible','on')
        %         set(handles.num_VMin,'Visible','on')
        %         set(handles.num_VMax,'Visible','on')
        %         %set(handles.CoordUnit,'Visible','on')
        %         %set(handles.TimeUnit,'Visible','on')
        %         %set(handles.slash_title,'Visible','on')
        %         set(handles.min_title,'Visible','on')
        %         set(handles.max_title,'Visible','on')
        %         set(handles.unit_title,'Visible','on')
        %     else
        %         get_search_range(app, hObject, eventdata, handles)
        %     end
        % end
        
%         function civ_input_CloseRequestFcn(app, hObject, eventdata, handles)
%             % --- Executes when user attempts to close get_field.
%             
%             if isequal(get(handles.get_field, 'waitstatus'), 'waiting')
%                 % The GUI is still in UIWAIT, us UIRESUME
%                 uiresume(handles.civ_input_fig);
%             else
%                 % The GUI is no longer waiting, just close it
%                 delete(handles.civ_input_fig);
%             end
%         end
        
        function closeview_field(app, gcbo, eventdata)
            %------------------------------------------------------------------------
            %----function introduced for the correlation window figure, activated by deleting this window
            
            %------------------------------------------------------------------------
            hview_field=findobj(allchild(0),'tag','view_field');% look for view_field
            if ~isempty(hview_field)
                delete(hview_field)
            end
        end


        %------------------------------------------------------------------------
        % Introduce input parameters from structure ActionInput
        function fill_civ_input(app,ActionInput)

            fill_app(ActionInput,app)% fill the elements of the current GUI civ_input with the input parameters

            options={'Civ1','Fix1','Patch1','Civ2','Fix2','Patch2','Civ3','Fix3','Patch3'};
            for ilist=1:6
                if app.(['Check' options{ilist}]).Value==true
                    app.(options{ilist}).Visible='on';
                else
                    app.(options{ilist}).Visible='off';
                end
            end
            %  CheckMask_Callback(app, [])
            CheckThreshold_Callback(app,[])
            CheckRescale_Callback(app,[])
            
        end
        
        
%         function fill_civ_input(app, Data, handles)
%             %------------------------------------------------------------------------
%             % --- fill stereo_input with the parameters retrieved from an input Civ file
%             %------------------------------------------------------------------------
%             
%             
%             %% Civ param
%             % lists of parameters to enter
%             ListParamNum={'CorrBoxSize','SearchBoxSize','SearchBoxShift','Dx','Dy','Dz','MinIma','MaxIma'};% list of numerical values (to transform in strings)
%             ListParamValue={'CorrSmooth','CheckGrid','CheckMask','CheckThreshold'};
%             ListParamString={'Grid','Mask'};
%             % CorrSmooth ??
%             option={'Civ1','Civ2'};
%             for ichoice=1:2
%                 if isfield(Data,[option{ichoice} '_CorrBoxSize'])
%                     fill_panel(app, Data,handles,option{ichoice},ListParamNum,ListParamValue,ListParamString)
%                 end
%             end
            
%             %% Fix param
%             option={'Fix1','Fix2'};
%             for ichoice=1:2
%                 if isfield(Data,[option{ichoice} '_CheckFmin2'])
%                     ListParamNum={'MinVel','MaxVel','MinCorr'};% list of numerical values (to transform in strings)
%                     ListParamValue={'CheckFmin2','CheckF3','CheckF4'};
%                     ListParamString={'ref_fix_1'};
%                     fill_panel(app, Data,handles,option{ichoice},ListParamNum,ListParamValue,ListParamString)
%                 end
%             end
%             
%             %% Patch param
%             option={'Patch1','Patch2'};
%             for ichoice=1:2
%                 if isfield(Data,[option{ichoice} '_FieldSmooth'])
%                     ListParamNum={'FieldSmooth','MaxDiff','SubDomainSize'};% list of numerical values (to transform in strings)
%                     ListParamValue={};
%                     ListParamString={};
%                     fill_panel(app, Data,handles,option{ichoice},ListParamNum,ListParamValue,ListParamString)
%                 end
%             end
%         end
        
%         function fill_panel(app, Data, handles, panel, ListParamNum, ListParamValue, ListParamString)
%             %------------------------------------------------------------------------
%             % --- fill a panel of stereo_input with the parameters retrieved from an input Civ file
%             %------------------------------------------------------------------------
%             
%             children=get(handles.(panel),'children');%handles of the children of the input GUI with handle 'GUI_handle'
%             handles_panel=[];
%             for ichild=1:numel(children)
%                 if ~isempty(get(children(ichild),'tag'))
%                     handles_panel.(get(children(ichild),'tag'))=children(ichild);
%                 end
%             end
%             for ilist=1:numel(ListParamNum)
%                 ParamName=ListParamNum{ilist};
%                 CivParamName=[panel '_' ParamName];
%                 if isfield(Data,CivParamName)
%                     for icoord=1:numel(Data.(CivParamName))
%                         if numel(Data.(CivParamName))>1
%                             Tag=['num_' ParamName '_' num2str(icoord)];
%                         else
%                             Tag=['num_' ParamName];
%                         end
%                         if isfield(handles_panel,Tag)
%                             set(handles_panel.(Tag),'String',num2str(Data.(CivParamName)(icoord)))
%                             set(handles_panel.(Tag),'Visible','on')
%                         end
%                     end
%                 end
%             end
%             for ilist=1:numel(ListParamValue)
%                 ParamName=ListParamValue{ilist};
%                 CivParamName=[panel '_' ParamName];
%                 if strcmp(ParamName,'CorrSmooth')
%                     ParamName=['num_' ParamName];
%                 end
%                 if isfield(Data,CivParamName)
%                     if isfield(handles_panel,ParamName)
%                         set(handles_panel.(ParamName),'Value',Data.(CivParamName))
%                     end
%                 end
%             end
%             for ilist=1:numel(ListParamString)
%                 ParamName=ListParamString{ilist};
%                 CivParamName=[panel '_' ParamName];
%                 if isfield(Data,CivParamName)
%                     if isfield(handles_panel,ParamName)
%                         set(handles_panel.(ParamName),'String',Data.(CivParamName))
%                     end
%                 end
%             end
%         end
                    
        
        function [ind1, ind2, mode] = find_pair_indices(app, str_civ, i_series, j_series, MinIndex_i, MaxIndex_i, MinIndex_j, MaxIndex_j)
            %------------------------------------------------------------------------
            % --- determine the list of index pairs of processing file
            
            %------------------------------------------------------------------------
            ind1='';
            ind2='';
            r=regexp(str_civ,'^\D(?<ind>[i|j])=( -| )(?<num1>\d+)\|(?<num2>\d+)','names');
            if ~isempty(r)
                mode=['D' r.ind];
                ind1=stra2num(r.num1);
                ind2=stra2num(r.num2);
            else
                mode='burst';
                r=regexp(str_civ,'^j= (?<num1>[a-z])-(?<num2>[a-z])','names');
                if ~isempty(r)
                    NomTypeNc='_1ab';
                else
                    r=regexp(str_civ,'^j= (?<num1>[A-Z])-(?<num2>[A-Z])','names');
                    if ~isempty(r)
                        NomTypeNc='_1AB';
                    else
                        r=regexp(str_civ,'^j= (?<num1>\d+)-(?<num2>\d+)','names');
                        if ~isempty(r)
                            NomTypeNc='_1_1-2';
                        end
                    end
                end
                if isempty(r)
                    display('wrong pair mode input option')
                else
                ind1=stra2num(r.num1);
                ind2=stra2num(r.num2);
                end
            end
        end
        
        function [nbslice, flag_grid] = get_grid(app, filebase, handles)
            %------------------------------------------------------------------------
            % --- function called to look for grid files
            
            %------------------------------------------------------------------------
            flag_grid=0;%default
            nbslice=1;
            [Path,Name]=fileparts(filebase);
            currentdir=pwd;
            cd(Path);%move in the dir of the root name filebase
            gridfiles=dir([Name '_*grid_*.grid']);%look for grid files
            cd(currentdir);%come back to the current working directory
            if ~isempty(gridfiles)
                flag_grid=1;
                gridname=gridfiles(1).name;% take the first grid file in the list
                [Path2,Name,ext]=fileparts(gridname);
                Namedouble=double(Name);
                val=(48>Namedouble)|(Namedouble>57);% select the non-numerical characters
                ind_grid=findstr('grid',Name);
                i=ind_grid-1;
                while val(i)==0 && i>0
                    i=i-1;
                end
                nbslice=str2double(Name(i+1:ind_grid-1));
                if ~isnan(nbslice) && Name(i)=='_'
                    flag_grid=1;
                else
                    msgbox_uvmat('ERROR',['bad grid file ' Name ext ' found in ' Path2])
                    return
                    nbslice=1;
                end
            end
        end
        
        function [nbslice, flag_mask] = get_mask(app,filebase)
            %------------------------------------------------------------------------
            % --- function called to look for mask files
            
            %------------------------------------------------------------------------
            %detect mask files, images with appropriate file base
            %[filebase '_' xx 'mask'], xx=nbslice
            %flag_mask=1 indicates detection
            
            flag_mask=0;%default
            nbslice=1;
            
            % subdir=get(handles.Civ1_ImageB,'String');
            [Path,Name]=fileparts(filebase);
            if ~isfolder(Path)
                msgbox_uvmat('ERROR','no path for input files')
                return
            end
            % currentdir=pwd;
            % cd(Path);%move in the dir of the root name filebase
            maskfiles=dir(fullfile(Path,[Name '_*mask_*.png']));%look for mask files
            % cd(currentdir);%come back to the current working directory
            if ~isempty(maskfiles)
                %     msgbox_uvmat('ERROR','no mask available, to create it use Tools/Make mask in the upper menu bar of uvmat')
                % else
                flag_mask=1;
                maskname=maskfiles(1).name;% take the first mask file in the list
                [Path2,Name,ext]=fileparts(maskname);
                Namedouble=double(Name);
                val=(48>Namedouble)|(Namedouble>57);% select the non-numerical characters
                ind_mask=findstr('mask',Name);
                i=ind_mask-1;
                while val(i)==0 && i>0
                    i=i-1;
                end
                nbslice=str2double(Name(i+1:ind_mask-1));
                if ~isnan(nbslice) && Name(i)=='_'
                    flag_mask=1;
                else
                    msgbox_uvmat('ERROR',['bad mask file ' Name ext ' found in ' Path2])
                    return
                    nbslice=1;
                end
            end
        end
        
        
        function get_search_range(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            % ---  determine the search range num_SearchRange_1,num_SearchRange_2 and shift
            
            %------------------------------------------------------------------------
            param_civ1=read_GUI(handles.Civ1);
            umin=param_civ1.UMin;
            umax=param_civ1.UMax;
            vmin=param_civ1.VMin;
            vmax=param_civ1.VMax;
            %switch min_title and max_title in case of error
            if umax<=umin
                umin_old=umin;
                umin=umax;
                umax=umin_old;
                set(handles.num_UMin,'String', num2str(umin))
                set(handles.num_UMax,'String', num2str(umax))
            end
            if vmax<=vmin
                vmin_old=vmin;
                vmin=vmax;
                vmax=vmin_old;
                set(handles.num_VMin,'String', num2str(vmin))
                set(handles.num_VMax,'String', num2str(vmax))
            end
            if ~(isempty(umin)||isempty(umax)||isempty(vmin)||isempty(vmax))
                shiftx=round((umin+umax)/2);
                shifty=round((vmin+vmax)/2);
                isx=(umax+2-shiftx)*2+param_civ1.CorrBoxSize(1);
                isx=2*ceil(isx/2)+1;
                isy=(vmax+2-shifty)*2+param_civ1.CorrBoxSize(2);
                isy=2*ceil(isy/2)+1;
                set(handles.num_SearchBoxShift_1,'String',num2str(shiftx));
                set(handles.num_SearchBoxShift_2,'String',num2str(shifty));
                set(handles.num_SearchRange_1,'String',num2str(isx));
                set(handles.num_SearchRange_2,'String',num2str(isy));
            end
        end
        
        function keyboard_callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            % --- Executes on key press with selection of a uicontrol
            %------------------------------------------------------------------------
            ListExclude={'CheckCiv1','CheckFix1','CheckPatch1','CheckCiv2','CheckFix2','CheckPatch2','ref_i'};
            if isempty(find(strcmp(get(gco,'Tag'),ListExclude),1))% if the selected uicontrol is not in the Exclude list
                set(handles.ConfigSource,'String','NEW')% indicate that the configuration is new
                set(handles.OK,'BackgroundColor',[1 0 1])%
                drawnow
            end
        end
        
        function str = num2stra(app, num, nom_type)
            %------------------------------------------------------------------------
            % --- transform numbers to letters
            
            %------------------------------------------------------------------------
            if isempty(nom_type)
                str='';
            elseif strcmp(nom_type(end),'a')
                str=char(96+num);
            elseif strcmp(nom_type(end),'A')
                str=char(96+num);
            elseif isempty(nom_type(2:end))%a single index
                str='';
            else
                str=num2str(num);
            end
        end
        
        function ref_fix1_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            
            %------------------------------------------------------------------------
            set(handles.num_MinVel,'Value',1);
            set(handles.field_ref1,'Value',1)
            set(handles.field_ref1,'String',{' '})
            set(handles.ref_fix1,'UserData',[]);
            set(handles.ref_fix1,'String','');
            set(handles.thresh_vel1,'String','0');
        end
        
        function ref_fix2_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            
            %------------------------------------------------------------------------
            set(handles.num_MinVel,'Value',1);
            set(handles.field_ref2,'Value',1)
            set(handles.field_ref2,'String',{' '})
            set(handles.ref_fix2,'UserData',[]);
            set(handles.ref_fix2,'String','');
            set(handles.num_MinVel,'String','0');
        end
        
        function ref_i_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            
            %------------------------------------------------------------------------
            mode_list=get(handles.ListPairMode,'String');
            mode_value=get(handles.ListPairMode,'Value');
            mode=mode_list{mode_value};
            errormsg=find_netcpair_civ(app, handles,1);% update the menu of pairs depending on the available netcdf files
            if isequal(mode,'series(Di)') || ...% we do patch2 only
                    (get(handles.CheckCiv2,'Value')==0 && get(handles.CheckCiv1,'Value')==0 && get(handles.CheckFix1,'Value')==0 && get(handles.CheckPatch1,'Value')==0)
                errormsg=find_netcpair_civ(app,  handles,2);
            end
            if isempty(errormsg)
                set(handles.ref_i,'BackgroundColor',[1 1 1])
                set(handles.ref_j,'BackgroundColor',[1 1 1])
            else
                msgbox_uvmat('ERROR',errormsg)
            end
        end
        
        function ref_i_KeyPressFcn(app, hObject, eventdata, handles)
            set(hObject,'BackgroundColor',[1 0 1])
        end
        
        function update_CivOptions(app, handles, ind_input)
            % --- activated by any checkbox controling the selection of Civ1,Fix1,Patch1,Civ2,Fix2,Patch2
            
            %------------------------------------------------------------------------
            % if opening>0
            %     set(handles.CheckCiv2,'UserData',opening)% store the info on the current status of the civ processing
            % % end
            checkbox=zeros(1,9);
            checkbox(1)=get(handles.CheckCiv1,'Value');
            checkbox(2)=get(handles.CheckFix1,'Value');
            checkbox(3)=get(handles.CheckPatch1,'Value');
            checkbox(4)=get(handles.CheckCiv2,'Value');
            checkbox(5)=get(handles.CheckFix2,'Value');
            checkbox(6)=get(handles.CheckPatch2,'Value');
            checkbox(7)=get(handles.CheckCiv3,'Value');
            checkbox(8)=get(handles.CheckFix3,'Value');
            checkbox(9)=get(handles.CheckPatch3,'Value');
            
          
            %% set the visibility of the different panels
            options={'Civ1','Fix1','Patch1','Civ2','Fix2','Patch2','Civ3','Patch3','Patch3'};
            for ilist=1:min(ind_input,length(options))
            %     if checkbox(ilist)
                    set(handles.(options{ilist}),'Visible','on')
            %     else
            %         set(handles.(options{ilist}),'Visible','off')
            %     end
            end
        end
        
        function update_frame(app, CivStage)
            %------------------------------------------------------------------------
            options={'Civ1','Fix1','Patch1','Civ2','Fix2','Patch2','Civ3','Fix3','Patch3'};
            check_on=app.(['Check' options{CivStage}]).Value;
            
            if check_on % activate frames
                status=false;
                for index=1:CivStage
                    if app.(['Check' options{index}]).Value
                        status=true;
                    end
                    if status
                        app.(options{index}).Visible='on';
                        app.(['Check' options{index}]).Value=true;
                        % set(handles.(options{index}),'Visible','on')
                        % set(handles.(['Check' options{index}]),'Value',1)
                    end
                end
            else
                for index=CivStage:numel(options)
                     app.(options{index}).Visible='off';
                        app.(['Check' options{index}]).Value=0;
                    % set(handles.(options{index}),'Visible','off')
                    % set(handles.(['Check' options{index}]),'Value',0)
                end
            end
        end
    end       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function stereo_input_OpeningFcn(app, Param)
                   % --- Executes just before stereo_input is made visible.
        %-----------------------------------------------------------------
                     
            % Ensure that the app appears on screen when run
            movegui(app.stereo_input_fig, 'onscreen');
            
            %------------------------------------------------------------------------
            % This function has no output args, see OutputFcn.
            
            %% General settings
            app.stereo_input_fig.UserData.InputTable=Param.InputTable;
            app.stereo_input_fig.UserData.SeriesData=Param.SeriesData;         
       
%             FileType='image';%fdefault
%             FileInfo=[];
            
          
            %% prepare the GUI with input parameters
            app.ConfigSource.Value='\default';
                                
            %% set the stereo_input options, depending on the input file content if a nc file has been opened
            ListOptions={'CheckCiv1', 'CheckFix1' 'CheckPatch1', 'CheckCiv2', 'CheckFix2', 'CheckPatch2', 'CheckCiv3', 'CheckFix3', 'CheckPatch3'};
            checkbox=zeros(size(ListOptions));%default
            checkrefresh=0;
            for index=1:numel(ListOptions)
                checkbox(index)=app.(ListOptions{index}).Value;
            end
            index_max=find(checkbox, 1, 'last' );
            if isempty(index_max),index_max=1;end
            for index=1:index_max
                app.(ListOptions{index}).Value=true;% select all operations starting from CIV1
            end
            
            %% introduce the stored Civ parameters  if available (from previous input or ImportConfig in series)
            if ~checkrefresh && isfield(Param,'ActionInput')&& strcmp(Param.ActionInput.Program,Param.Action.ActionName)% the program fits with the stored data
                fill_civ_input(app,Param.ActionInput)%fill the GUI with the parameters retrieved from the input Param
            end
                 
            %%  wait for OK to close          
          uiwait(app.stereo_input_fig);
        end

        % Value changed function: CheckCiv1
        function CheckCiv1_Callback(app, event)
        update_frame(app,1)
        end

        % Value changed function: CheckCiv2
        function CheckCiv2_Callback(app, event)
        update_frame(app,4)
        end

        % Value changed function: CheckCiv3
        function CheckCiv3_Callback(app, event)
        update_frame(app,7)
        end

        % Value changed function: CheckDeformation, CheckDeformation_civ3
        function CheckDeformation_Callback(app, event)
                   % --- Executes on button press in CheckDeformation.
        %-----------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.ConfigSource,'String','NEW')
            set(handles.OK,'BackgroundColor',[1 0 1])
        end

        % Value changed function: CheckFix1
        function CheckFix1_Callback(app, event)
        update_frame(app,2)
        end

        % Value changed function: CheckFix2
        function CheckFix2_Callback(app, event)
        update_frame(app, 5)
        end

        % Value changed function: CheckFix3
        function CheckFix3_Callback(app, event)
        update_frame(app, 8)
        end

        % Callback function
        function CheckGrid_Callback(app, event)
               
             
        end

        % Value changed function: CheckMask
        function CheckMask_Callback(app, event)
 
            CheckMask=false;
            if app.CheckMask.Value
                InputTable=app.stereo_input_fig.UserData.InputTable;

                [nbslice, flag_mask]=get_mask(app,InputTable{1,1});% look for a mask with appropriate name
                if isequal(flag_mask,1)
                    filemask=[num2str(nbslice) 'mask'];
                    CheckMask=true;
                else % browse for a mask
                    filemask= uigetfile_uvmat('pick a mask image file:',InputTable{1,1},'image');
                    app.CheckMask.UserData=filemask;%store for future use
                    if ~isempty(filemask)
                        CheckMask=true;
                    end
                end
            end
            if CheckMask
              app.Mask_A.Visible='on';
              app.Mask_B.Visible='on';
                app.Mask_A.Value=filemask;
                app.Mask_B.Value=filemask;
            else
                app.Mask_A.Visible='off';
                app.Mask_B.Visible='off';
            end
            app.CheckMask.Value=CheckMask;
           
            app.ConfigSource.Value='NEW';
            app.ConfigSource.BackgroundColor=[1 0 1];
        end

        % Value changed function: CheckPatch1
        function CheckPatch1_Callback(app, event)
        update_frame(app,3)
        end

        % Value changed function: CheckPatch2
        function CheckPatch2_Callback(app, event)
        update_frame(app,6)
        end

        % Value changed function: CheckPatch3
        function CheckPatch3_Callback(app, event)
        update_frame(app,9)
        end

        % Value changed function: CheckRescale
        function CheckRescale_Callback(app, event)

            status='off';
            if app.CheckRescale.Value% if chck box has been selected
                status='on';
            end
            app.num_Maxtanh.Visible=status;
            app.ConfigSource.Value='NEW';

            % set(handles.OK,'BackgroundColor',[1 0 1])
        end

        % Value changed function: CheckThreshold
        function CheckThreshold_Callback(app, event)
          
            if app.CheckThreshold.Value
                visible_str='on';
            else
                visible_str='off';
            end
            app.num_MinIma.Visible=visible_str;
            app.num_MaxIma.Visible=visible_str;
            app.Threshold_title.Visible=visible_str;
 
            app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
        end

        % Button pushed function: ImportParam
        function ImportParam_Callback(app, event)
            % --- Import processing parameters from a previous processing
            %------------------------------------------------------------------
            InputTable=app.stereo_input_fig.UserData.InputTable;% read the input file(s) table exported from the GUI series as a starting path to search the input xml file
            oldfile=InputTable{1,1};
            if isempty(oldfile)
                % use a file name stored in prefdir
                dir_perso=prefdir;
                profil_perso=fullfile(dir_perso,'uvmat_perso.mat');
                if exist(profil_perso,'file')
                    h=load (profil_perso);
                    if isfield(h,'RootPath') && ischar(h.RootPath)
                        oldfile=h.RootPath;
                    end
                end
            end
            filexml=uigetfile_uvmat('pick a xml parameter file for civ',oldfile,'.xml');% get the xml file containing processing parameters
            %proceed only if a file has been introduced by the browser
            if ~isempty(filexml)
                Param=xml2struct(filexml);% read the input xml file as a Matlab structure

                % Param.Action.RUN=0; %desactivate the input RUN=1
                if ~isfield(Param,'InputTable')||~isfield(Param,'IndexRange')
                    msgbox_uvmat('ERROR','invalid config file: open a file in a folder ''/0_XML''')
                    return
                end
                check_input=0;
                if isfield(Param,'ActionInput')
                    if isfield(Param.ActionInput,'Program')&& strcmp(Param.ActionInput.Program,'stereo_civ')
                       % ActionInput=update_input(app,Param.ActionInput);% translate from old conventions if needed
                        fill_civ_input(app,Param.ActionInput); % fill the GUI with input parameters
                        app.ConfigSource.Value=filexml;% indicate the source file in box app.ConfigSource
                        check_input=true;%

                        %                         fill_GUI(Param.ActionInput,handles.civ_input)% fill the elements of the GUI series with the input parameters
                        %                         set(handles.ConfigSource,'String',filexml)
                        %                         check_input=1;
                        % update_CivOptions(app, handles,0)
                    end
                end
                if ~check_input
                    msgbox_uvmat('ERROR','invalid config file (not for civ_series')
                    return
                end
            end
        end

        % Callback function
        function MenuHelp_Callback(app, event)

        end

        % Button pushed function: OK
        function OK_Callback(app, event)

            uiresume(app.stereo_input_fig);
        end

        % Button pushed function: SetDefaultParam
        function SetDefaultParam_Callback(app, event)
        % --- introduce default parameters
        %-----------------------------------------------------------------                   
            Param.ConfigSource='\default';
            
            %% Civ1 parameters
            Param.Civ1.CorrBoxSize=[101 101];
            Param.Civ1.SearchBoxSize=[100 100];
            Param.Civ1.SearchBoxShift=[0 0];
            Param.Civ1.CorrSmooth=1;
            Param.Civ1.Dx=20;
            Param.Civ1.Dy=20;
            Param.Civ1.CheckMask=0;
            Param.Civ1.CheckThreshold=0;
            Param.Civ1.TestCiv1=0;
            
            %% Fix1 parameters
            Param.Fix1.MinCorr=0.2000;
            
            %% Patch1 parameters
            Param.Patch1.FieldSmooth=1000;
            Param.Patch1.MaxDiff=3;
            Param.Patch1.SubDomainSize=1000;
            Param.Patch1.TestPatch1=0;
            
            %% Civ2 parameters
            Param.Civ2.CorrBoxSize=[101 101];
            Param.Civ2.SearchBoxSize=[10 10];
            Param.Civ2.CorrSmooth=1;
            Param.Civ2.Dx=20;
            Param.Civ2.Dy=20;
            Param.Civ2.CheckMask=0;
            Param.Civ2.CheckThreshold=0;
            Param.Civ2.TestCiv2=0;
            
            %% Fix2 parameters
            Param.Fix2.MinCorr=0.2000;
            
            %% Patch2 parameters
            Param.Patch2.FieldSmooth=1000;
            Param.Patch2.MaxDiff=2;
            Param.Patch2.SubDomainSize=1000;
            
            fill_app(Param,app)% fill the elements of the GUI series with the input parameters
        end

        % Value changed function: TestCiv1
        function TestCiv1_Callback(app, event)
            %  NOT IMPLEMENTED (Executes on button press in TestCiv1: prepare the image correlation function
            %     activated by mouse motion)
        %-----------------------------------------------------------------
if app.TestCiv1.Value
    app.TestCiv1.BackgroundColor=[1 1 0];% paint TestCiv1 button to yellow to confirm civ launch
    app.CheckFix1.Value=0;% desactivate next step
    update_frame(app,2)

    % get info from the GUI 'series'
    hseries=findobj(allchild(0),'Tag','series');
    Param=read_GUI(hseries);
    disp('TEST MODE NOT IMPLEMENTED')

 else
        app.TestCiv1.BackgroundColor=[1 0 0];% paint button to red
end
        end

        % Value changed function: get_ref_fix1
        function get_ref_fix1_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in get_ref_fix1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            filebase=get(handles.RootPath,'String');
            [FileName, PathName, filterindex] = uigetfile( ...
                {'*.nc', ' (*.nc)';
                '*.nc',  'netcdf files '; ...
                '*.*', 'All Files (*.*)'}, ...
                'Pick a file',filebase);
            
            fileinput=[PathName FileName];
            sizf=size(fileinput);
            if (~ischar(fileinput)||~isequal(sizf(1),1)),return;end %stop if fileinput not a character string
            %[Path,File,field_count,str2,str_a,str_b,ref.ext,ref.nom_type,ref.subdir]=name2display(fileinput);
            [Path,ref.subdir,File,ref.num1,ref.num2,ref.num_a,ref.num_b,ref.ext,ref.nom_type]=fileparts_uvmat(fileinput);
            ref.filebase=fullfile(Path,File);
            % ref.num_a=stra2num(str_a);
            % ref.num_b=stra2num(str_b);
            % ref.num1=str2double(field_count);
            % ref.num2=str2double(str2);
            browse=[];%initialisation
            if ~isequal(ref.ext,'.nc')
                msgbox_uvmat('ERROR','the reference file must be in netcdf format (*.nc)')
                return
            end
            set(handles.ref_fix1,'String',[fullfile(ref.subdir,File) '....nc']);
            set(handles.ref_fix1,'UserData',ref)
            menu_field{1}='civ1';
            Data=nc2struct(fileinput,[]);
            if isfield(Data,'patch') && isequal(Data.patch,1)
                menu_field{2}='filter1';
            end
            if isfield(Data,'civ2') && isequal(Data.civ2,1)
                menu_field{3}='civ2';
            end
            if isfield(Data,'patch2') && isequal(Data.patch2,1)
                menu_field{4}='filter2';
            end
            set(handles.field_ref1,'String',menu_field);
            set(handles.field_ref1,'Value',length(menu_field));
            set(handles.num_MinVel,'Value',2);
            set(handles.num_MinVel,'String','1');%default threshold
            set(handles.ref_fix1,'Enable','on')
        end

        % Value changed function: get_ref_fix2, get_ref_fix_civ3
        function get_ref_fix2_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in get_ref_fix2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            if isequal(get(handles.get_ref_fix2,'Value'),1)
                filebase=get(handles.RootPath,'String');
                [FileName, PathName, filterindex] = uigetfile( ...
                    {'*.nc', ' (*.nc)';
                    '*.nc',  'netcdf files '; ...
                    '*.*', 'All Files (*.*)'}, ...
                    'Pick a file',filebase);
                fileinput=[PathName FileName];
                sizf=size(fileinput);
                if (~ischar(fileinput)||~isequal(sizf(1),1)),return;end %stop if fileinput not a character string
                %[Path,File,field_count,str2,str_a,str_b,ref.ext,ref.nom_type,ref.subdir]=name2display(fileinput);
                [Path,ref.subdir,File,ref.num1,ref.num2,ref.num_a,ref.num_b,ref.ext,ref.nom_type]=fileparts_uvmat(fileinput);
                ref.filebase=fullfile(Path,File);
                %     ref.num_a=stra2num(str_a);
                %     ref.num_b=stra2num(str_b);
                %     ref.num1=str2num(field_count);
                %     ref.num2=str2num(str2);
                browse=[];%initialisation
                if ~isequal(ref.ext,'.nc')
                    msgbox_uvmat('ERROR','the reference file must be in netcdf format (*.nc)')
                    return
                end
                set(handles.ref_fix2,'String',[fullfile(ref.subdir,File) '....nc']);
                set(handles.ref_fix2,'UserData',ref)
                menu_field{1}='civ1';
                Data=nc2struct(fileinput,[]);
                if isfield(Data,'patch') && isequal(Data.patch,1)
                    menu_field{2}='filter1';
                end
                if isfield(Data,'civ2') && isequal(Data.civ2,1)
                    menu_field{3}='civ2';
                end
                if isfield(Data,'patch2') && isequal(Data.patch2,1)
                    menu_field{4}='filter2';
                end
                set(handles.field_ref2,'String',menu_field);
                set(handles.field_ref2,'Value',length(menu_field));
                set(handles.num_MinVel,'Value',2);
                set(handles.num_MinVel,'String','1');%default threshold
                set(handles.ref_fix2,'Enable','on')
                set(handles.ref_fix2,'Visible','on')
                set(handles.field_ref2,'Visible','on')
            else
                set(handles.ref_fix2,'Visible','off')
                set(handles.field_ref2,'Visible','off')
            end
        end

        % Value changed function: num_CorrSmooth_civ1, 
        % num_CorrSmooth_civ2, num_CorrSmooth_civ3
        function num_CorrSmooth_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on selection in menu CorrSmooth.
            
            app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];
        end

        % Value changed function: CheckBackground
        function CheckBackgroundValueChanged(app, event)
            testbackground=0;
            if app.CheckBackground.Value% if the checkbox is activated
                BackgroundGuess=app.Background_A.Value; % use the previous input
                InputTable=app.stereo_input_fig.UserData.InputTable;
                if isempty(BackgroundGuess)
               BackgroundGuess=InputTable{1,1};
                end
                % browse for a background
                BackgroundGuess= uigetfile_uvmat('pick a background image file:',BackgroundGuess,'.png');
                if ~isempty(BackgroundGuess)
                    [FilePath,FileName,FileExt]=fileparts(BackgroundGuess);
                    [RootPath,SubDir,RootFile,i1_series,~,j1_series,~,NomType]=find_file_series(FilePath,[FileName FileExt]);
                    if ~isempty(i1_series) % look for the appropriate nomenclature
                        Min_i=find( i1_series(1,2,:), 1 );
                        r=regexp(NomType,'^_(?<i1>\d+)1$','names');
                        if ~isempty(r)
                            nb_zero=numel(r.i1);% nbre of zeros
                            if nb_zero>0 && Min_i>10^nb_zero
                                NomType='_1';
                            end
                        end
                    end
                    if strcmp(NomType,'_1')|| strcmp(NomType,'_1_1')% indexing found for background image
                        [~,ref_i]=find(squeeze(i1_series(1,:,:)));% the index i stands for the i index of the image to process
                        if isscalar(ref_i)
                            msgbox_uvmat('ERROR','do not put any index  _1,_2,... for a single background image name');
                        else
                            diff_ref_i=diff(ref_i,1);
                            if isequal (diff_ref_i,diff_ref_i(1)*ones(size(diff_ref_i)))
                                app.num_BkgndPeriod.Value=num2str(diff_ref_i(1));
                                app.num_BkgndPeriod.Visible='on';
                            end
                        end
                        if strcmp(NomType,'_1_1')% period background at different levels (multilevel of volume scan)
                            NbSlice=j1_series(1,2,end);
                            app.num_NbSlice.Value=num2str(NbSlice);% slice deptermination detected
                            app.num_NbSlice.Visible='on';
                        end
                    elseif ~strcmp(NomType,'*')% other kind of file indexing
                        msgbox_uvmat('ERROR','multilevel background images must be labeled with a single index as _1,_2,...');
                        return
                    end
                    testbackground=1;
                end
            end
            if testbackground
                app.Background_A.Visible='on';
                app.Background_B.Visible='on';
                [~,SubDirRoot,SubDirExt]=fileparts(SubDir);% keep the root name before extension
                if strcmp(RootPath,InputTable{1,1})&& strcmp(SubDirRoot,InputTable{1,2})
                    app.Background_A.Value=fullfile(SubDirExt,RootFile);% background can be adapted to each file series                    
                else
                app.Background_A.Value=fullfile(RootPath,SubDir,RootFile);
                end
                app.CheckBackground.Value=true;
                if strcmp(NomType,'_1')
                    app.num_BkgndPeriod.Visible='on';
                end
                app.Background_B.Value=app.Background_A.Value; %reproduce the input by default, to further edit by hand
            else
                app.CheckBackground.Value=0;
                app.Background_A.Visible='off';
                app.Background_B.Visible='off';
                app.num_BkgndPeriod.Visible='off';
            end
            app.ConfigSource.Value='NEW';
            app.OK.BackgroundColor=[1 0 1];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.stereo_input_fig = uifigure('Visible', 'off');
            app.stereo_input_fig.Name = 'stereo_input';
            app.stereo_input_fig.Tag = 'civ_input';
            app.Patch3 = uipanel(app.stereo_input_fig);
            app.Patch3.Visible = 'off';
            app.Patch3.Tag = 'Patch3';
            app.text112 = uilabel(app.Patch3);
            app.text112.Tag = 'text112';
            app.text112.Text = 'subdomain size';
            app.num_MaxDiff_patch3 = uieditfield(app.Patch3, 'text');
            app.num_MaxDiff_patch3.Tag = 'num_MaxDiff';
            app.num_MaxDiff_patch3.Tooltip = '''num_MaxDiff'': vectors are flaged as false if the difference with filtered value exceeds this value (in px)';
            app.num_MaxDiff_patch3.Value = '1.5';
            app.num_SubDomainSize_patch3 = uieditfield(app.Patch3, 'text');
            app.num_SubDomainSize_patch3.Tag = 'num_SubDomainSize';
            app.num_SubDomainSize_patch3.Tooltip = '''num_SubDomainSize'': size of the subdomains used for interpolation (nb of vectors)';
            app.num_SubDomainSize_patch3.Value = '1000';
            app.text116 = uilabel(app.Patch3);
            app.text116.Tag = 'text116';
            app.text116.Text = 'MaxDiff';
            app.num_FieldSmooth_patch3 = uieditfield(app.Patch3, 'text');
            app.num_FieldSmooth_patch3.Tag = 'num_FieldSmooth_2';
            app.num_FieldSmooth_patch3.Tooltip = '''num_FieldSmooth'': smoothing parameter (large values increase smoothing)';
            app.num_FieldSmooth_patch3.Value = '500';
            app.text115 = uilabel(app.Patch3);
            app.text115.Tag = 'text115';
            app.text115.Text = 'FieldSmooth';
            app.Fix3 = uipanel(app.stereo_input_fig);
            app.Fix3.Visible = 'off';
            app.Fix3.Tag = 'Fix3';
            app.text109 = uilabel(app.Fix3);
            app.text109.Tag = 'text109';
            app.text109.Text = '| vel | <';
            app.get_ref_fix_civ3 = uicheckbox(app.Fix3);
            app.get_ref_fix_civ3.ValueChangedFcn = createCallbackFcn(app, @get_ref_fix2_Callback, true);
            app.get_ref_fix_civ3.Tag = 'get_ref_fix2_1';
            app.get_ref_fix_civ3.Visible = 'off';
            app.get_ref_fix_civ3.Tooltip = '''get_ref_fix2'': compare the velocity field to a reference field';
            app.get_ref_fix_civ3.Text = 'GET_REF';
            app.ref_fix_civ3 = uieditfield(app.Fix3, 'text');
            app.ref_fix_civ3.Tag = 'ref_fix2_1';
            app.ref_fix_civ3.Visible = 'off';
            app.ref_fix_civ3.Tooltip = '''ref_fix2'': display the reference file name for fix';
            app.num_MaxVel_civ3 = uieditfield(app.Fix3, 'text');
            app.num_MaxVel_civ3.Tag = 'num_MaxVel_2';
            app.num_MaxVel_civ3.Tooltip = '''num_MaxVel'': remove vel vectors  larger  than threshold';
            app.num_MinVel_civ3 = uieditfield(app.Fix3, 'text');
            app.num_MinVel_civ3.Tag = 'num_MinVel_2';
            app.num_MinVel_civ3.Tooltip = '''num_MinVel'': removes vectors whose modulus is smaller than the threshold (in picxel displacement)';
            app.text111 = uilabel(app.Fix3);
            app.text111.Tag = 'text111';
            app.text111.Text = '| vel |>';
            app.field_ref_civ3 = uidropdown(app.Fix3);
            app.field_ref_civ3.Items = {};
            app.field_ref_civ3.Tag = 'field_ref2_1';
            app.field_ref_civ3.Visible = 'off';
            app.field_ref_civ3.Tooltip = '''field_ref2'':field used for reference (civ1,filter1...)';
            app.field_ref_civ3.Value = {};
            app.text110 = uilabel(app.Fix3);
            app.text110.Tag = 'text110';
            app.text110.Text = 'vec_C <';
            app.num_MinCorr_civ3 = uieditfield(app.Fix3, 'text');
            app.num_MinCorr_civ3.Tag = 'num_MinCorr_2';
            app.num_MinCorr_civ3.Tooltip = '''num_MinCorr'': removes vectors with image correlation value smaller than the threshold';
            app.num_MinCorr_civ3.Value = '0.2';
            app.Civ3 = uipanel(app.stereo_input_fig);
            app.Civ3.Visible = 'off';
            app.Civ3.Tag = 'Civ3';
            app.num_Dy_civ3 = uieditfield(app.Civ3, 'text');
            app.num_Dy_civ3.Tag = 'num_Dy_2';
            app.num_Dy_civ3.Tooltip = 'num_Dy'': mesh along y (in pixel) for the grid of measureme';
            app.num_Dy_civ3.Value = '20';
            app.text107 = uilabel(app.Civ3);
            app.text107.Tag = 'text107';
            app.text107.Tooltip = '''title_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.text107.Text = 'Dy';
            app.num_Dx_civ3 = uieditfield(app.Civ3, 'text');
            app.num_Dx_civ3.Tag = 'num_Dx_2';
            app.num_Dx_civ3.Tooltip = '''num_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.num_Dx_civ3.Value = '20';
            app.text106 = uilabel(app.Civ3);
            app.text106.Tag = 'text106';
            app.text106.Tooltip = '''title_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.text106.Text = 'auto-grid Dx';
            app.num_CorrSmooth_civ3 = uieditfield(app.Civ3, 'text');
            app.num_CorrSmooth_civ3.ValueChangedFcn = createCallbackFcn(app, @num_CorrSmooth_Callback, true);
            app.num_CorrSmooth_civ3.Tag = 'num_CorrSmooth_3';
            app.num_CorrSmooth_civ3.Tooltip = '''num_CorrSmooth'': smoothing parameter for image correlations ';
            app.num_CorrSmooth_civ3.Value = '1';
            app.text105 = uilabel(app.Civ3);
            app.text105.Tag = 'text105';
            app.text105.Tooltip = '''rho_civ2_title'': smoothing parameter for image correlations ';
            app.text105.Text = 'CorrSmooth';
            app.num_SearchRange_civ3_2 = uieditfield(app.Civ3, 'text');
            app.num_SearchRange_civ3_2.Tag = 'num_SearchBoxSize_2';
            app.num_SearchRange_civ3_2.Tooltip = '''num_SearchRange_2'': search range (in pixels) along the y direction';
            app.num_SearchRange_civ3_2.Value = '5';
            app.num_SearchRange_civ3_1 = uieditfield(app.Civ3, 'text');
            app.num_SearchRange_civ3_1.Tag = 'num_SearchRange_1_2';
            app.num_SearchRange_civ3_1.Tooltip = '''num_SearchRange_1'':search range (in pixels) along the x direction';
            app.num_SearchRange_civ3_1.Value = '5';
            app.num_CorrBoxSize_civ3_2 = uieditfield(app.Civ3, 'text');
            app.num_CorrBoxSize_civ3_2.Tag = 'num_CorrBoxSize_2_2';
            app.num_CorrBoxSize_civ3_2.Tooltip = '''num_CorrBoxSize_2'':size of the correlation box (in pixels) along the y direction';
            app.num_CorrBoxSize_civ3_2.Value = '101';
            app.num_CorrBoxSize_civ3_1 = uieditfield(app.Civ3, 'text');
            app.num_CorrBoxSize_civ3_1.Tag = 'num_CorrBoxSize_1_2';
            app.num_CorrBoxSize_civ3_1.Tooltip = '''num_CorrBoxSize_1'':size of the correlation box (in pixels) along the x direction';
            app.num_CorrBoxSize_civ3_1.Value = '101';
            app.text108 = uilabel(app.Civ3);
            app.text108.Tag = 'text108';
            app.text108.Tooltip = '''is_title'':search range around the previous estimate (in pixels) in each direction';
            app.text108.Text = 'Range x, y';
            app.text104 = uilabel(app.Civ3);
            app.text104.Tag = 'text104';
            app.text104.Text = 'CorrBox x, y';
            app.text103 = uilabel(app.Civ3);
            app.text103.Tag = 'text103';
            app.text103.Text = 'correlation';
            app.CheckDeformation_civ3 = uicheckbox(app.Civ3);
            app.CheckDeformation_civ3.ValueChangedFcn = createCallbackFcn(app, @CheckDeformation_Callback, true);
            app.CheckDeformation_civ3.Tag = 'CheckDeformation_1';
            app.CheckDeformation_civ3.Tooltip = '''CheckDeformation'': introduces local image deformation by shear and rotation  in the correlation search';
            app.CheckDeformation_civ3.Text = 'deformation';
            app.Patch2 = uipanel(app.stereo_input_fig);
            app.Patch2.Visible = 'off';
            app.Patch2.Tag = 'Patch2';
            app.subdomain_patch2_title = uilabel(app.Patch2);
            app.subdomain_patch2_title.Tag = 'subdomain_text2';
            app.subdomain_patch2_title.Text = 'subdomain size';
            app.num_FieldSmooth_patch2 = uieditfield(app.Patch2, 'text');
            app.num_FieldSmooth_patch2.Tag = 'num_FieldSmooth';
            app.num_FieldSmooth_patch2.Tooltip = '''num_FieldSmooth'': smoothing parameter (large values increase smoothing)';
            app.num_FieldSmooth_patch2.Value = '1000';
            app.num_MaxDiff_patch2 = uieditfield(app.Patch2, 'text');
            app.num_MaxDiff_patch2.Tag = 'num_MaxDiff';
            app.num_MaxDiff_patch2.Tooltip = '''num_MaxDiff'': vectors are flaged as false if the difference with filtered value exceeds this value (in px)';
            app.num_MaxDiff_patch2.Value = '1.5';
            app.num_SubDomainSize_patch2 = uieditfield(app.Patch2, 'text');
            app.num_SubDomainSize_patch2.Tag = 'num_SubDomainSize';
            app.num_SubDomainSize_patch2.Tooltip = '''num_SubDomainSize'': size of the subdomains used for interpolation (nb of vectors)';
            app.num_SubDomainSize_patch2.Value = '1000';
            app.FieldSmooth_patch2_title = uilabel(app.Patch2);
            app.FieldSmooth_patch2_title.Tag = 'FieldSmooth_txt';
            app.FieldSmooth_patch2_title.Text = 'FieldSmooth';
            app.MaxDiff_patch2_title = uilabel(app.Patch2);
            app.MaxDiff_patch2_title.Tag = 'title_MaxDiff';
            app.MaxDiff_patch2_title.Text = 'MaxDiff';
            app.Fix2 = uipanel(app.stereo_input_fig);
            app.Fix2.Visible = 'off';
            app.Fix2.Tag = 'Fix2';
            app.thresh_vel2_text = uilabel(app.Fix2);
            app.thresh_vel2_text.Tag = 'thresh_vel2_text';
            app.thresh_vel2_text.Text = '| vel | <';
            app.get_ref_fix2 = uicheckbox(app.Fix2);
            app.get_ref_fix2.ValueChangedFcn = createCallbackFcn(app, @get_ref_fix2_Callback, true);
            app.get_ref_fix2.Tag = 'get_ref_fix2';
            app.get_ref_fix2.Visible = 'off';
            app.get_ref_fix2.Tooltip = '''get_ref_fix2'': compare the velocity field to a reference field';
            app.get_ref_fix2.Text = 'GET_REF';
            app.ref_fix2 = uieditfield(app.Fix2, 'text');
            app.ref_fix2.Tag = 'ref_fix2';
            app.ref_fix2.Visible = 'off';
            app.ref_fix2.Tooltip = '''ref_fix2'': display the reference file name for fix';
            app.num_MaxVel = uieditfield(app.Fix2, 'text');
            app.num_MaxVel.Tag = 'num_MaxVel';
            app.num_MaxVel.Tooltip = '''num_MaxVel'': remove vel vectors  larger  than threshold';
            app.num_MinVel = uieditfield(app.Fix2, 'text');
            app.num_MinVel.Tag = 'num_MinVel';
            app.num_MinVel.Tooltip = '''num_MinVel'': removes vectors whose modulus is smaller than the threshold (in picxel displacement)';
            app.thresh_vec2C_text = uilabel(app.Fix2);
            app.thresh_vec2C_text.Tag = 'thresh_vec2C_text';
            app.thresh_vec2C_text.Text = 'vec_C <';
            app.num_MinCorr = uieditfield(app.Fix2, 'text');
            app.num_MinCorr.Tag = 'num_MinCorr';
            app.num_MinCorr.Tooltip = '''num_MinCorr'': removes vectors with image correlation value smaller than the threshold';
            app.num_MinCorr.Value = '0.2';
            app.title_MaxVel = uilabel(app.Fix2);
            app.title_MaxVel.Tag = 'title_MaxVel';
            app.title_MaxVel.Text = '| vel |>';
            app.field_ref2 = uidropdown(app.Fix2);
            app.field_ref2.Items = {};
            app.field_ref2.Tag = 'field_ref2';
            app.field_ref2.Visible = 'off';
            app.field_ref2.Tooltip = '''field_ref2'':field used for reference (civ1,filter1...)';
            app.field_ref2.Value = {};
            app.Civ2 = uipanel(app.stereo_input_fig);
            app.Civ2.Visible = 'off';
            app.Civ2.Tag = 'Civ2';
            app.parameters_text = uilabel(app.Civ2);
            app.parameters_text.Tag = 'parameters_text';
            app.parameters_text.Text = 'correlation';
            app.num_Dy_civ2 = uieditfield(app.Civ2, 'text');
            app.num_Dy_civ2.Tag = 'num_Dy';
            app.num_Dy_civ2.Tooltip = 'num_Dy'': mesh along y (in pixel) for the grid of measureme';
            app.num_Dy_civ2.Value = '20';
            app.num_Dx_civ2 = uieditfield(app.Civ2, 'text');
            app.num_Dx_civ2.Tag = 'num_Dx';
            app.num_Dx_civ2.Tooltip = '''num_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.num_Dx_civ2.Value = '20';
            app.num_CorrSmooth_civ2 = uieditfield(app.Civ2, 'text');
            app.num_CorrSmooth_civ2.ValueChangedFcn = createCallbackFcn(app, @num_CorrSmooth_Callback, true);
            app.num_CorrSmooth_civ2.Tag = 'num_CorrSmooth';
            app.num_CorrSmooth_civ2.Tooltip = '''num_CorrSmooth'': smoothing parameter for image correlations ';
            app.num_CorrSmooth_civ2.Value = '1';
            app.num_SearchRange_civ2_2 = uieditfield(app.Civ2, 'text');
            app.num_SearchRange_civ2_2.Tag = 'num_SearchRange_2';
            app.num_SearchRange_civ2_2.Tooltip = '''num_SearchRange_2'': search range (in pixels) along the y direction';
            app.num_SearchRange_civ2_2.Value = '10';
            app.num_SearchRange_civ2_1 = uieditfield(app.Civ2, 'text');
            app.num_SearchRange_civ2_1.Tag = 'num_SearchRange_1';
            app.num_SearchRange_civ2_1.Tooltip = '''num_SearchRange_1'':earch  range(in pixels) along the x direction';
            app.num_SearchRange_civ2_1.Value = '10';
            app.num_CorrBoxSize_civ2_2 = uieditfield(app.Civ2, 'text');
            app.num_CorrBoxSize_civ2_2.Tag = 'num_CorrBoxSize_2';
            app.num_CorrBoxSize_civ2_2.Tooltip = '''num_CorrBoxSize_2'':size of the correlation box (in pixels) along the y direction';
            app.num_CorrBoxSize_civ2_2.Value = '101';
            app.num_CorrBoxSize_civ2_1 = uieditfield(app.Civ2, 'text');
            app.num_CorrBoxSize_civ2_1.Tag = 'num_CorrBoxSize_1';
            app.num_CorrBoxSize_civ2_1.Tooltip = '''num_CorrBoxSize_1'':size of the correlation box (in pixels) along the x direction';
            app.num_CorrBoxSize_civ2_1.Value = '101';
            app.ibx_civ2_text = uilabel(app.Civ2);
            app.ibx_civ2_text.Tag = 'ibx_civ2_text';
            app.ibx_civ2_text.Text = 'CorrBox x, y';
            app.CheckDeformation = uicheckbox(app.Civ2);
            app.CheckDeformation.ValueChangedFcn = createCallbackFcn(app, @CheckDeformation_Callback, true);
            app.CheckDeformation.Tag = 'CheckDeformation';
            app.CheckDeformation.Tooltip = '''CheckDeformation'': introduces local image deformation by shear and rotation  in the correlation search';
            app.CheckDeformation.Text = 'deformation';
            app.rho_civ2_title = uilabel(app.Civ2);
            app.rho_civ2_title.Tag = 'rho_civ2_title';
            app.rho_civ2_title.Tooltip = '''rho_civ2_title'': smoothing parameter for image correlations ';
            app.rho_civ2_title.Text = 'CorrSmooth';
            app.title_Dx = uilabel(app.Civ2);
            app.title_Dx.Tag = 'title_Dx';
            app.title_Dx.Tooltip = '''title_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.title_Dx.Text = 'auto-grid Dx';
            app.title_Dy = uilabel(app.Civ2);
            app.title_Dy.Tag = 'title_Dy';
            app.title_Dy.Tooltip = '''title_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.title_Dy.Text = 'Dy';
            app.text94 = uilabel(app.Civ2);
            app.text94.Tag = 'text94';
            app.text94.Tooltip = '''is_title'':search range (in pixels) along each direction';
            app.text94.Text = 'Range x, y';
            app.Patch1 = uipanel(app.stereo_input_fig);
            app.Patch1.Visible = 'off';
            app.Patch1.Tag = 'Patch1';
            app.num_FieldSmooth_patch1 = uieditfield(app.Patch1, 'text');
            app.num_FieldSmooth_patch1.Tag = 'num_FieldSmooth';
            app.num_FieldSmooth_patch1.Tooltip = '''num_FieldSmooth'': smoothing parameter (large values increase smoothing)';
            app.num_FieldSmooth_patch1.Value = '1000';
            app.num_MaxDiff_patch1 = uieditfield(app.Patch1, 'text');
            app.num_MaxDiff_patch1.Tag = 'num_MaxDiff';
            app.num_MaxDiff_patch1.Tooltip = '''num_MaxDiff'': vectors are flaged as false if the difference with filtered value exceeds this value (in px)';
            app.num_MaxDiff_patch1.Value = '3';
            app.num_SubDomainSize_patch1 = uieditfield(app.Patch1, 'text');
            app.num_SubDomainSize_patch1.Tag = 'num_SubDomainSize';
            app.num_SubDomainSize_patch1.Tooltip = '''num_SubDomainSize'': size of the subdomains used for interpolation (nb of vectors)';
            app.num_SubDomainSize_patch1.Value = '1000';
            app.text72 = uilabel(app.Patch1);
            app.text72.Tag = 'text72';
            app.text72.Text = 'subdomain size';
            app.text75 = uilabel(app.Patch1);
            app.text75.Tag = 'text75';
            app.text75.Text = 'FieldSmooth';
            app.title_MaxDiff_patch1 = uilabel(app.Patch1);
            app.title_MaxDiff_patch1.Tag = 'title_MaxDiff_1';
            app.title_MaxDiff_patch1.Text = 'MaxDiff';
            app.Fix1 = uipanel(app.stereo_input_fig);
            app.Fix1.Visible = 'off';
            app.Fix1.Tag = 'Fix1';
            app.TitleMinVel = uilabel(app.Fix1);
            app.TitleMinVel.Tag = 'TitleMinVel';
            app.TitleMinVel.Text = {'| vel | <'; ''};
            app.get_ref_fix1 = uicheckbox(app.Fix1);
            app.get_ref_fix1.ValueChangedFcn = createCallbackFcn(app, @get_ref_fix1_Callback, true);
            app.get_ref_fix1.Tag = 'get_ref_fix1';
            app.get_ref_fix1.Visible = 'off';
            app.get_ref_fix1.Tooltip = '''get_ref_fix1'': compares the velocity field to a reference';
            app.get_ref_fix1.Text = 'GET_REF';
            app.ref_fix1 = uieditfield(app.Fix1, 'text');
            app.ref_fix1.Tag = 'ref_fix1';
            app.ref_fix1.Visible = 'off';
            app.ref_fix1.Tooltip = '''ref_fix1'': display the reference file name for fix';
            app.num_MaxVel_fix1 = uieditfield(app.Fix1, 'text');
            app.num_MaxVel_fix1.Tag = 'num_MaxVel_1';
            app.num_MaxVel_fix1.Tooltip = '''num_MaxVel'': remove vel vectors  larger  than this  threshold (in pixel displacement)';
            app.num_MinVel_fix1 = uieditfield(app.Fix1, 'text');
            app.num_MinVel_fix1.Tag = 'num_MinVel_1';
            app.num_MinVel_fix1.Tooltip = '''num_MinVel'': removes vectors whose modulus is smaller than the threshold (in picxel displacement)';
            app.thresh_vecC_title = uilabel(app.Fix1);
            app.thresh_vecC_title.Tag = 'thresh_vecC_title';
            app.thresh_vecC_title.Tooltip = '''thresh_vecC_title'': removes vectors with image correlation value smaller than the threshold';
            app.thresh_vecC_title.Text = 'vec_C <';
            app.num_MinCorr_fix1 = uieditfield(app.Fix1, 'text');
            app.num_MinCorr_fix1.Tag = 'num_MinCorr_1';
            app.num_MinCorr_fix1.Tooltip = '''num_MinCorr'': removes vectors with image correlation value smaller than the threshold';
            app.num_MinCorr_fix1.Value = '0.2';
            app.title_MaxVel_fix1 = uilabel(app.Fix1);
            app.title_MaxVel_fix1.Tag = 'title_MaxVel_1';
            app.title_MaxVel_fix1.Text = '| vel | >';
            app.field_ref1 = uidropdown(app.Fix1);
            app.field_ref1.Items = {};
            app.field_ref1.Tag = 'field_ref1';
            app.field_ref1.Visible = 'off';
            app.field_ref1.Tooltip = '''field_ref1'':field used for reference (civ1,filter1...)';
            app.field_ref1.Value = {};
            app.Civ1 = uipanel(app.stereo_input_fig);
            app.Civ1.Visible = 'off';
            app.Civ1.Tag = 'Civ1';
            app.TestCiv1 = uibutton(app.Civ1, 'state');
            app.TestCiv1.ValueChangedFcn = createCallbackFcn(app, @TestCiv1_Callback, true);
            app.TestCiv1.Tag = 'TestCiv1';
            app.TestCiv1.Visible = 'off';
            app.TestCiv1.Tooltip = '''TestCiv1'' show the image correlation';
            app.TestCiv1.Text = 'TEST';
            app.num_Dy_civ1 = uieditfield(app.Civ1, 'text');
            app.num_Dy_civ1.Tag = 'num_Dy';
            app.num_Dy_civ1.Tooltip = '''num_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.num_Dy_civ1.Value = '20';
            app.title_Dy_civ1 = uilabel(app.Civ1);
            app.title_Dy_civ1.Tag = 'title_Dy_1';
            app.title_Dy_civ1.Tooltip = '''title_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.title_Dy_civ1.Text = 'Dy';
            app.num_Dx_civ1 = uieditfield(app.Civ1, 'text');
            app.num_Dx_civ1.Tag = 'num_Dx';
            app.num_Dx_civ1.Tooltip = '''Num_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.num_Dx_civ1.Value = '20';
            app.title_Dx_civ1 = uilabel(app.Civ1);
            app.title_Dx_civ1.Tag = 'title_Dx_1';
            app.title_Dx_civ1.Tooltip = '''title_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.title_Dx_civ1.Text = 'auto-grid Dx';
            app.num_CorrSmooth_civ1 = uieditfield(app.Civ1, 'text');
            app.num_CorrSmooth_civ1.ValueChangedFcn = createCallbackFcn(app, @num_CorrSmooth_Callback, true);
            app.num_CorrSmooth_civ1.Tag = 'num_CorrSmooth';
            app.num_CorrSmooth_civ1.Tooltip = '''num_CorrSmooth_1'': smoothing parameter for image correlations ''';
            app.num_CorrSmooth_civ1.Value = '1';
            app.num_SearchBoxShift_2 = uieditfield(app.Civ1, 'text');
            app.num_SearchBoxShift_2.Tag = 'num_SearchBoxShift_2';
            app.num_SearchBoxShift_2.Tooltip = '''num_SearchBoxShift_2'': systematic shift (in pixels) of the search box (take into account a mean flow)';
            app.num_SearchBoxShift_2.Value = '0';
            app.num_SearchBoxShift_1 = uieditfield(app.Civ1, 'text');
            app.num_SearchBoxShift_1.Tag = 'num_SearchBoxShift_1';
            app.num_SearchBoxShift_1.Tooltip = '''num_SearchBoxShift_1'': systematic shift (in pixels) of the search box (take into account a mean flow)';
            app.num_SearchBoxShift_1.Value = '0';
            app.num_SearchRange_civ1_2 = uieditfield(app.Civ1, 'text');
            app.num_SearchRange_civ1_2.Tag = 'num_SearchRange_2';
            app.num_SearchRange_civ1_2.Tooltip = '''num_SearchRange_2'': search range (in pixels) along the y direction';
            app.num_SearchRange_civ1_2.Value = '100';
            app.num_SearchRange_civ1_1 = uieditfield(app.Civ1, 'text');
            app.num_SearchRange_civ1_1.Tag = 'num_SearchRange_1';
            app.num_SearchRange_civ1_1.Tooltip = '''num_SearchRange_1'':search range (in pixels) along the x direction';
            app.num_SearchRange_civ1_1.Value = '100';
            app.num_CorrBoxSize_civ1_2 = uieditfield(app.Civ1, 'text');
            app.num_CorrBoxSize_civ1_2.Tag = 'num_CorrBoxSize_2';
            app.num_CorrBoxSize_civ1_2.Tooltip = '''num_CorrBoxSize_2'':size of the correlation box (in pixels) along the y direction';
            app.num_CorrBoxSize_civ1_2.Value = '101';
            app.num_CorrBoxSize_civ1_1 = uieditfield(app.Civ1, 'text');
            app.num_CorrBoxSize_civ1_1.Tag = 'num_CorrBoxSize_1';
            app.num_CorrBoxSize_civ1_1.Tooltip = '''num_CorrBoxSize_1'':size of the correlation box (in pixels) along the x direction';
            app.num_CorrBoxSize_civ1_1.Value = '101';
            app.parameters = uilabel(app.Civ1);
            app.parameters.Tag = 'parameters';
            app.parameters.Text = 'correlation';
            app.ib_title = uilabel(app.Civ1);
            app.ib_title.Tag = 'ib_title';
            app.ib_title.Tooltip = '''ib_title'':size of the correlation box (in pixels) along each direction';
            app.ib_title.Text = 'CorrBox x, y';
            app.is_title = uilabel(app.Civ1);
            app.is_title.Tag = 'is_title';
            app.is_title.Tooltip = '''is_title'':size of the search box (in pixels) along each direction';
            app.is_title.Text = 'Range x,y';
            app.shift_title = uilabel(app.Civ1);
            app.shift_title.Tag = 'shift_title';
            app.shift_title.Tooltip = '''shift_title'': systematic shift (in pixels) for search box (take into account a mean flow)';
            app.shift_title.Text = 'Shift x, y';
            app.rho_title = uilabel(app.Civ1);
            app.rho_title.Tag = 'rho_title';
            app.rho_title.Tooltip = '''rho_title'': smoothing parameter for image correlations ';
            app.rho_title.Text = 'CorrSmooth';
            app.OK = uibutton(app.stereo_input_fig, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OK_Callback, true);
            app.OK.Tag = 'OK';
            app.OK.Tooltip = '''OK'': send parameters to the GUI series';
            app.OK.Text = 'OK';
            app.CheckPatch3 = uicheckbox(app.stereo_input_fig);
            app.CheckPatch3.ValueChangedFcn = createCallbackFcn(app, @CheckPatch3_Callback, true);
            app.CheckPatch3.Tag = 'CheckPatch3';
            app.CheckPatch3.Tooltip = '''CheckPatch3'': interpolates and smooths the velocity field';
            app.CheckPatch3.Text = 'PATCH3';
            app.CheckFix3 = uicheckbox(app.stereo_input_fig);
            app.CheckFix3.ValueChangedFcn = createCallbackFcn(app, @CheckFix3_Callback, true);
            app.CheckFix3.Tag = 'CheckFix3';
            app.CheckFix3.Tooltip = '''CheckFix3'': flags false vectors with selected criteria';
            app.CheckFix3.Text = 'FIX3';
            app.CheckCiv3 = uicheckbox(app.stereo_input_fig);
            app.CheckCiv3.ValueChangedFcn = createCallbackFcn(app, @CheckCiv3_Callback, true);
            app.CheckCiv3.Tag = 'CheckCiv3';
            app.CheckCiv3.Tooltip = '''CheckCiv3'': select first PIV processing';
            app.CheckCiv3.Text = 'CIV3';
            app.CheckPatch2 = uicheckbox(app.stereo_input_fig);
            app.CheckPatch2.ValueChangedFcn = createCallbackFcn(app, @CheckPatch2_Callback, true);
            app.CheckPatch2.Tag = 'CheckPatch2';
            app.CheckPatch2.Tooltip = '''CheckPatch2'': interpolates and smooths the civ2 velocity field';
            app.CheckPatch2.Text = 'PATCH2';
            app.CheckFix2 = uicheckbox(app.stereo_input_fig);
            app.CheckFix2.ValueChangedFcn = createCallbackFcn(app, @CheckFix2_Callback, true);
            app.CheckFix2.Tag = 'CheckFix2';
            app.CheckFix2.Tooltip = '''CheckFix2'': removes (flag)  false civ2 vectors with selected criteria';
            app.CheckFix2.Text = 'FIX2';
            app.CheckCiv2 = uicheckbox(app.stereo_input_fig);
            app.CheckCiv2.ValueChangedFcn = createCallbackFcn(app, @CheckCiv2_Callback, true);
            app.CheckCiv2.Tag = 'CheckCiv2';
            app.CheckCiv2.Tooltip = '''CheckCiv2''; iterative PIV processing using the prior estimate from civ1 and patch1';
            app.CheckCiv2.Text = 'CIV2';
            app.CheckPatch1 = uicheckbox(app.stereo_input_fig);
            app.CheckPatch1.ValueChangedFcn = createCallbackFcn(app, @CheckPatch1_Callback, true);
            app.CheckPatch1.Tag = 'CheckPatch1';
            app.CheckPatch1.Tooltip = '''CheckPatch1'': interpolates and smooths the velocity field';
            app.CheckPatch1.Text = 'PATCH1';
            app.CheckFix1 = uicheckbox(app.stereo_input_fig);
            app.CheckFix1.ValueChangedFcn = createCallbackFcn(app, @CheckFix1_Callback, true);
            app.CheckFix1.Tag = 'CheckFix1';
            app.CheckFix1.Tooltip = '''CheckFix1'': flags false vectors with selected criteria';
            app.CheckFix1.Text = 'FIX1';
            app.CheckCiv1 = uicheckbox(app.stereo_input_fig);
            app.CheckCiv1.ValueChangedFcn = createCallbackFcn(app, @CheckCiv1_Callback, true);
            app.CheckCiv1.Tag = 'CheckCiv1';
            app.CheckCiv1.Tooltip = '''CheckCiv1'': select first PIV processing';
            app.CheckCiv1.Text = 'CIV1';
            app.CheckCiv1.Value = true;
            app.num_resolution = uieditfield(app.stereo_input_fig, 'text');
            app.num_resolution.Tag = 'num_resolution';
            app.num_resolution.Tooltip = '''num_resolution'': factor for the size of the images projected in the phys coordinates, > 1 to increase the resolution';
            app.num_resolution.Value = '1';
            app.resolution_factor_title = uilabel(app.stereo_input_fig);
            app.resolution_factor_title.Tag = 'text117';
            app.resolution_factor_title.Text = 'resolution factor';
            app.CheckZField = uicheckbox(app.stereo_input_fig);
            app.CheckZField.Tag = 'CheckZField';
            app.CheckZField.Tooltip = '''CheckZField: calculate the z shift field of the illumination surface''';
            app.CheckZField.Text = 'Z field';
            app.CheckTest = uicheckbox(app.stereo_input_fig);
            app.CheckTest.Tag = 'CheckTest';
            app.CheckTest.Tooltip = '''CheckLSM'' save only physics data to recude output weight';
            app.CheckTest.Text = 'Test mode';
            app.CheckMask = uicheckbox(app.stereo_input_fig);
            app.CheckMask.ValueChangedFcn = createCallbackFcn(app, @CheckMask_Callback, true);
            app.CheckMask.Tag = 'CheckMask';
            app.CheckMask.Tooltip = '''CheckMask'': use mask file for civ, or creates it if it does not exist''';
            app.CheckMask.Text = 'Masks A & B';
            app.Mask_A = uieditfield(app.stereo_input_fig, 'text');
            app.Mask_A.Tag = 'Mask_A';
            app.Mask_A.Visible = 'off';
            app.Mask_A.Tooltip = '''Mask_A'': display a selected mask series for images A''';
            app.Mask_B = uieditfield(app.stereo_input_fig, 'text');
            app.Mask_B.Tag = 'Mask_B';
            app.Mask_B.Visible = 'off';
            app.Mask_B.Tooltip = '''Mask_B: display a selected mask series for images B''';
            app.num_NbSlice = uieditfield(app.stereo_input_fig, 'text');
            app.num_NbSlice.Tag = 'num_NbSlice';
            app.num_NbSlice.Visible = 'off';
            app.num_NbSlice.Tooltip = '''num_NbSlice'': number of slices for the mask';
            app.Background_A = uieditfield(app.stereo_input_fig, 'text');
            app.Background_A.Tag = 'Background_A';
            app.Background_A.Visible = 'off';
            app.Background_A.Tooltip = '''Background'': display background image filename';
            app.Background_B = uieditfield(app.stereo_input_fig, 'text');
            app.Background_B.Tag = 'Background_B';
            app.Background_B.Visible = 'off';
            app.Background_B.Tooltip = '''Background'': display background image filename';
            app.num_BkgndPeriod = uieditfield(app.stereo_input_fig, 'text');
            app.num_BkgndPeriod.Tag = 'num_BkgndPeriod';
            app.num_BkgndPeriod.Visible = 'off';
            app.num_BkgndPeriod.Tooltip = '''num_BkgndPeriod'': index i period for background determination';
            app.num_Maxtanh = uieditfield(app.stereo_input_fig, 'text');
            app.num_Maxtanh.Tag = 'num_Maxtanh';
            app.num_Maxtanh.Visible = 'off';
            app.num_Maxtanh.Tooltip = '''num_Maxtanh'': threshold for the tanh image intensity rescaling';
            app.threshold_title = uilabel(app.stereo_input_fig);
            app.threshold_title.Tag = 'text118';
            app.threshold_title.Visible = 'off';
            app.threshold_title.Text = 'threshold';
            app.CheckRescale = uicheckbox(app.stereo_input_fig);
            app.CheckRescale.ValueChangedFcn = createCallbackFcn(app, @CheckRescale_Callback, true);
            app.CheckRescale.Tag = 'CheckRescale';
            app.CheckRescale.Tooltip = '''CheckRescale'': rescale the image luminosity I by threshold*tanh(I/threshold)';
            app.CheckRescale.Text = 'Rescale tanh';
            app.num_MaxIma = uieditfield(app.stereo_input_fig, 'text');
            app.num_MaxIma.Tag = 'num_MaxIma';
            app.num_MaxIma.Visible = 'off';
            app.num_MaxIma.Tooltip = '''num_MaxIma'': higher luminosity threshold for PIV processing';
            app.CheckThreshold = uicheckbox(app.stereo_input_fig);
            app.CheckThreshold.ValueChangedFcn = createCallbackFcn(app, @CheckThreshold_Callback, true);
            app.CheckThreshold.Tag = 'CheckThreshold';
            app.CheckThreshold.Tooltip = '''CheckThreshold'': set thresholds on image intensity';
            app.CheckThreshold.Text = 'Threshold';
            app.num_MinIma = uieditfield(app.stereo_input_fig, 'text');
            app.num_MinIma.Tag = 'num_MinIma';
            app.num_MinIma.Visible = 'off';
            app.num_MinIma.Tooltip = '''num_MinIma'': lower luminosity threshold for PIV processing';
            app.num_MinIma.Value = '0';
            app.Threshold_title = uilabel(app.stereo_input_fig);
            app.Threshold_title.Tag = 'title_Threshold';
            app.Threshold_title.Visible = 'off';
            app.Threshold_title.Text = '< image <';
            app.CheckBackground = uicheckbox(app.stereo_input_fig);
            app.CheckBackground.ValueChangedFcn = createCallbackFcn(app, @CheckBackgroundValueChanged, true);
            app.CheckBackground.Tag = 'CheckBackground';
            app.CheckBackground.Tooltip = '''CheckBackground: subtract background images''';
            app.CheckBackground.Text = 'Background A & B';
            app.ConfigSource = uieditfield(app.stereo_input_fig, 'text');
            app.ConfigSource.Tag = 'ConfigSource';
            app.ConfigSource.Enable = 'off';
            app.ConfigSource.Tooltip = '''ConfigSource'': source of the current parameters';
            app.ImportParam = uibutton(app.stereo_input_fig, 'push');
            app.ImportParam.ButtonPushedFcn = createCallbackFcn(app, @ImportParam_Callback, true);
            app.ImportParam.Tag = 'ImportParam';
            app.ImportParam.Tooltip = '''ImportParam'' browse   xml file (in a folder 0_XML) to import civ parameters ';
            app.ImportParam.Text = 'IMPORT';
            app.SetDefaultParam = uibutton(app.stereo_input_fig, 'push');
            app.SetDefaultParam.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultParam_Callback, true);
            app.SetDefaultParam.Tag = 'SetDefaultParam';
            app.SetDefaultParam.Tooltip = '''SetDefaultParam'': press to revert to default civ parameters';
            app.SetDefaultParam.Text = 'DEFAULT';
            app.source_config_title = uilabel(app.stereo_input_fig);
            app.source_config_title.Tag = 'text93';
            app.source_config_title.Text = 'source config:';
            % Show the figure after all components are created
            app.stereo_input_fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stereo_input(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.stereo_input_fig)

                % Execute the startup function
                runStartupFcn(app, @(app)stereo_input_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.stereo_input_fig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.stereo_input_fig)
        end
    end
end